classdef Retrofit_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        RetrofitApp1Label               matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        BuildingTab                     matlab.ui.container.Tab
        GeneralinformationPanel         matlab.ui.container.Panel
        TimezoneUTCEditFieldLabel       matlab.ui.control.Label
        TimezoneUTCEditField            matlab.ui.control.NumericEditField
        LongitudeEditFieldLabel         matlab.ui.control.Label
        LongitudeEditField              matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        NumberofoccupantsEditFieldLabel  matlab.ui.control.Label
        NumberofoccupantsEditField      matlab.ui.control.NumericEditField
        NumberofheatedfloorsEditFieldLabel  matlab.ui.control.Label
        NumberofheatedfloorsEditField   matlab.ui.control.NumericEditField
        FloorheightmEditFieldLabel      matlab.ui.control.Label
        FloorheightmEditField           matlab.ui.control.NumericEditField
        UndergrounddepthofcellarfloormEditFieldLabel  matlab.ui.control.Label
        UndergrounddepthofcellarfloormEditField  matlab.ui.control.NumericEditField
        OvergroundheightofcellarfloormEditFieldLabel  matlab.ui.control.Label
        OvergroundheightofcellarfloormEditField  matlab.ui.control.NumericEditField
        EnergyreferenceareaERAm2EditFieldLabel  matlab.ui.control.Label
        EnergyreferenceareaERAm2EditField  matlab.ui.control.NumericEditField
        BuildingperimetermEditFieldLabel  matlab.ui.control.Label
        BuildingperimetermEditField     matlab.ui.control.NumericEditField
        BuildingclassButtonGroup        matlab.ui.container.ButtonGroup
        VerylightButton                 matlab.ui.control.RadioButton
        LightButton                     matlab.ui.control.RadioButton
        MediumButton                    matlab.ui.control.RadioButton
        HeavyButton                     matlab.ui.control.RadioButton
        VeryheavyButton                 matlab.ui.control.RadioButton
        Buildingenvelopesurfacesm2Panel  matlab.ui.container.Panel
        RoofEditField_2Label            matlab.ui.control.Label
        RoofEditField_2                 matlab.ui.control.NumericEditField
        ExternalwallsEditField_2Label   matlab.ui.control.Label
        ExternalwallsEditField_2        matlab.ui.control.NumericEditField
        WindowsEditField_2Label         matlab.ui.control.Label
        WindowsEditField_2              matlab.ui.control.NumericEditField
        GroundfloorEditField_2Label     matlab.ui.control.Label
        GroundfloorEditField_2          matlab.ui.control.NumericEditField
        UValuesWm2KPanel                matlab.ui.container.Panel
        RoofEditFieldLabel              matlab.ui.control.Label
        RoofEditField                   matlab.ui.control.NumericEditField
        ExternalwallsEditFieldLabel     matlab.ui.control.Label
        ExternalwallsEditField          matlab.ui.control.NumericEditField
        WindowsEditFieldLabel           matlab.ui.control.Label
        WindowsEditField                matlab.ui.control.NumericEditField
        GroundfloorEditFieldLabel       matlab.ui.control.Label
        GroundfloorEditField            matlab.ui.control.NumericEditField
        SurfaceresistancesincludedCheckBox  matlab.ui.control.CheckBox
        TotalenergytransmittanceofwindowsPanel  matlab.ui.container.Panel
        GValueEditFieldLabel            matlab.ui.control.Label
        GValueEditField                 matlab.ui.control.NumericEditField
        OrientationofexternalwallsandwindowsPanel  matlab.ui.container.Panel
        SouthEditFieldLabel             matlab.ui.control.Label
        SouthEditField                  matlab.ui.control.NumericEditField
        EastEditFieldLabel              matlab.ui.control.Label
        EastEditField                   matlab.ui.control.NumericEditField
        NorthEditFieldLabel             matlab.ui.control.Label
        NorthEditField                  matlab.ui.control.NumericEditField
        WestEditFieldLabel              matlab.ui.control.Label
        WestEditField                   matlab.ui.control.NumericEditField
        AzimuthangleLabel               matlab.ui.control.Label
        SouthEditField_2                matlab.ui.control.NumericEditField
        EastEditField_2                 matlab.ui.control.NumericEditField
        NorthEditField_2                matlab.ui.control.NumericEditField
        WestEditField_2                 matlab.ui.control.NumericEditField
        Externalwallsm2Label            matlab.ui.control.Label
        SouthEditField_3                matlab.ui.control.NumericEditField
        EastEditField_3                 matlab.ui.control.NumericEditField
        NorthEditField_3                matlab.ui.control.NumericEditField
        WestEditField_3                 matlab.ui.control.NumericEditField
        Windowsm2Label                  matlab.ui.control.Label
        AnnotationtoazimuthangleTextAreaLabel  matlab.ui.control.Label
        AnnotationtoazimuthangleTextArea  matlab.ui.control.TextArea
        CompleteLampLabel               matlab.ui.control.Label
        CompleteLamp                    matlab.ui.control.Lamp
        ImportbuildingdataButton        matlab.ui.control.Button
        HeatingsystemTab                matlab.ui.container.Tab
        RoomsetpointtemperatureCEditFieldLabel  matlab.ui.control.Label
        RoomsetpointtemperatureCEditField  matlab.ui.control.NumericEditField
        OutdoordesigntemperatureCEditFieldLabel  matlab.ui.control.Label
        OutdoordesigntemperatureCEditField  matlab.ui.control.NumericEditField
        HeatinglimitCEditFieldLabel     matlab.ui.control.Label
        HeatinglimitCEditField          matlab.ui.control.NumericEditField
        correspondstotheoutsidetemperatureonthedesigndayLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        TypeofheatdissipationDropDownLabel  matlab.ui.control.Label
        TypeofheatdissipationDropDown   matlab.ui.control.DropDown
        TemperaturespreadCEditFieldLabel  matlab.ui.control.Label
        TemperaturespreadCEditField     matlab.ui.control.NumericEditField
        betweensupplyandreturntemperatureoffutureheatingsystemLabel  matlab.ui.control.Label
        TemperatureofDHWCEditFieldLabel  matlab.ui.control.Label
        TemperatureofDHWCEditField      matlab.ui.control.NumericEditField
        DatainputTab                    matlab.ui.container.Tab
        TrainingperiodPanel             matlab.ui.container.Panel
        StartdateLabel                  matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        EnddateDatePicker               matlab.ui.control.DatePicker
        TestperiodPanel                 matlab.ui.container.Panel
        StartdateDatePicker_2Label      matlab.ui.control.Label
        StartdateDatePicker_2           matlab.ui.control.DatePicker
        EnddateDatePicker_2Label        matlab.ui.control.Label
        EnddateDatePicker_2             matlab.ui.control.DatePicker
        InputfilesxlsxPanel             matlab.ui.container.Panel
        SolarirradianceEditFieldLabel   matlab.ui.control.Label
        SolarirradianceEditField        matlab.ui.control.EditField
        WSNmeasurementdataEditFieldLabel  matlab.ui.control.Label
        WSNmeasurementdataEditField     matlab.ui.control.EditField
        UITable                         matlab.ui.control.Table
        FormatLabel                     matlab.ui.control.Label
        VariablesTextAreaLabel          matlab.ui.control.Label
        VariablesTextArea               matlab.ui.control.TextArea
        UITable_2                       matlab.ui.control.Table
        FormatLabel_2                   matlab.ui.control.Label
        VariablesTextArea_2Label        matlab.ui.control.Label
        VariablesTextArea_2             matlab.ui.control.TextArea
        CalibratelightgreyboxmodelButton  matlab.ui.control.Button
        CompleteLamp_2Label             matlab.ui.control.Label
        CompleteLamp_2                  matlab.ui.control.Lamp
        RCmodelTab                      matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        ModelvalidationTab              matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        OverviewTab                     matlab.ui.container.Tab
        BuildingcharacteristicsLabel    matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        TrainingperiodLabel             matlab.ui.control.Label
        UITable3_2                      matlab.ui.control.Table
        TestperiodLabel                 matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        IndoortemperatureTab            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        HeatingdemandTab                matlab.ui.container.Tab
        TrainingperiodPanel_2           matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        TestperiodPanel_2               matlab.ui.container.Panel
        UIAxes2_3                       matlab.ui.control.UIAxes
        ModelparametersTab              matlab.ui.container.Tab
        UITable4                        matlab.ui.control.Table
        HeattransfercoefficientsWKLabel  matlab.ui.control.Label
        UITable4_2                      matlab.ui.control.Table
        CapacitiesJLabel                matlab.ui.control.Label
        UITable4_3                      matlab.ui.control.Table
        SurfaceheattransfercoefficientsWKLabel  matlab.ui.control.Label
        RetrofitmeasuresTab             matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        FinancialparametersTab          matlab.ui.container.Tab
        RetrofitmeasuresonbuildingenvelopePanel  matlab.ui.container.Panel
        RoofinsulationLabel             matlab.ui.control.Label
        ExternalwallinsulationLabel     matlab.ui.control.Label
        WindowreplacementLabel          matlab.ui.control.Label
        CellarceilinginsulationLabel    matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        UValueWm2KLabel                 matlab.ui.control.Label
        EditField_5                     matlab.ui.control.NumericEditField
        GValueLabel                     matlab.ui.control.Label
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        LifetimeaLabel                  matlab.ui.control.Label
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        InvestmentcostCHFm2Label        matlab.ui.control.Label
        HeatingsystemsPanel             matlab.ui.container.Panel
        OilboilerLabel                  matlab.ui.control.Label
        GeothermalheatpumpLabel         matlab.ui.control.Label
        AirtowaterheatpumpLabel         matlab.ui.control.Label
        DistrictheatingLabel            matlab.ui.control.Label
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        LifetimeaLabel_3                matlab.ui.control.Label
        PelletboilerLabel               matlab.ui.control.Label
        EditField_27                    matlab.ui.control.NumericEditField
        InvestmentcostPanel             matlab.ui.container.Panel
        CHFLabel                        matlab.ui.control.Label
        CHFEditField                    matlab.ui.control.NumericEditField
        CHFEditField_2Label             matlab.ui.control.Label
        CHFEditField_2                  matlab.ui.control.NumericEditField
        CHFEditField_3Label             matlab.ui.control.Label
        CHFEditField_3                  matlab.ui.control.NumericEditField
        CHFEditField_4Label             matlab.ui.control.Label
        CHFEditField_4                  matlab.ui.control.NumericEditField
        CHFEditField_5Label             matlab.ui.control.Label
        CHFEditField_5                  matlab.ui.control.NumericEditField
        CHFkWEditFieldLabel             matlab.ui.control.Label
        CHFkWEditField                  matlab.ui.control.NumericEditField
        CHFkWEditField_2Label           matlab.ui.control.Label
        CHFkWEditField_2                matlab.ui.control.NumericEditField
        CHFkWEditField_3Label           matlab.ui.control.Label
        CHFkWEditField_3                matlab.ui.control.NumericEditField
        CHFkWEditField_4Label           matlab.ui.control.Label
        CHFkWEditField_4                matlab.ui.control.NumericEditField
        CHFkWEditField_5Label           matlab.ui.control.Label
        CHFkWEditField_5                matlab.ui.control.NumericEditField
        OperationalandmaintenancecostPanel  matlab.ui.container.Panel
        CHFaEditFieldLabel              matlab.ui.control.Label
        CHFaEditField                   matlab.ui.control.NumericEditField
        CHFaEditField_2Label            matlab.ui.control.Label
        CHFaEditField_2                 matlab.ui.control.NumericEditField
        CHFaEditField_3Label            matlab.ui.control.Label
        CHFaEditField_3                 matlab.ui.control.NumericEditField
        CHFaEditField_4Label            matlab.ui.control.Label
        CHFaEditField_4                 matlab.ui.control.NumericEditField
        CHFaEditField_5Label            matlab.ui.control.Label
        CHFaEditField_5                 matlab.ui.control.NumericEditField
        CHFakWLabel                     matlab.ui.control.Label
        CHFakWEditField                 matlab.ui.control.NumericEditField
        CHFakWEditField_2Label          matlab.ui.control.Label
        CHFakWEditField_2               matlab.ui.control.NumericEditField
        CHFakWEditField_3Label          matlab.ui.control.Label
        CHFakWEditField_3               matlab.ui.control.NumericEditField
        CHFakWEditField_4Label          matlab.ui.control.Label
        CHFakWEditField_4               matlab.ui.control.NumericEditField
        CHFakWEditField_5Label          matlab.ui.control.Label
        CHFakWEditField_5               matlab.ui.control.NumericEditField
        MaintenancemeasuresonbuildingenvelopePanel  matlab.ui.container.Panel
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_30                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        LifetimeaLabel_4                matlab.ui.control.Label
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        InvestmentcostCHFm2Label_2      matlab.ui.control.Label
        CleaningandrepaintingthefacadeLabel  matlab.ui.control.Label
        GrindingputtyingandrepaintingtheundersideoftheroofLabel  matlab.ui.control.Label
        ScaffoldingLabel                matlab.ui.control.Label
        RepaintingthewindowsLabel       matlab.ui.control.Label
        EcologicalparametersTab         matlab.ui.container.Tab
        CreateretrofitmatrixButton      matlab.ui.control.Button
        CompleteLamp_5Label             matlab.ui.control.Label
        CompleteLamp_5                  matlab.ui.control.Lamp
        RetrofitmeasuresonbuildingenvelopePanel_3  matlab.ui.container.Panel
        RoofinsulationLabel_2           matlab.ui.control.Label
        ExternalwallinsulationLabel_2   matlab.ui.control.Label
        WindowreplacementLabel_2        matlab.ui.control.Label
        CellarceilinginsulationLabel_2  matlab.ui.control.Label
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        GHGemissionskgCO2eqm2Label      matlab.ui.control.Label
        EditField_45                    matlab.ui.control.NumericEditField
        EditField_46                    matlab.ui.control.NumericEditField
        EditField_47                    matlab.ui.control.NumericEditField
        EditField_48                    matlab.ui.control.NumericEditField
        PENRconsumptionkWhm2Label       matlab.ui.control.Label
        HeatingsystemsPanel_2           matlab.ui.container.Panel
        OilboilerLabel_2                matlab.ui.control.Label
        GeothermalheatpumpLabel_2       matlab.ui.control.Label
        AirtowaterheatpumpLabel_2       matlab.ui.control.Label
        DistrictheatingLabel_2          matlab.ui.control.Label
        EditField_49                    matlab.ui.control.NumericEditField
        EditField_50                    matlab.ui.control.NumericEditField
        EditField_51                    matlab.ui.control.NumericEditField
        EditField_52                    matlab.ui.control.NumericEditField
        GHGemissionskgCO2eqcomponentLabel  matlab.ui.control.Label
        EditField_53                    matlab.ui.control.NumericEditField
        EditField_54                    matlab.ui.control.NumericEditField
        EditField_55                    matlab.ui.control.NumericEditField
        EditField_56                    matlab.ui.control.NumericEditField
        PENRconsumptionkWhcomponentLabel  matlab.ui.control.Label
        PelletboilerLabel_2             matlab.ui.control.Label
        EditField_57                    matlab.ui.control.NumericEditField
        EditField_58                    matlab.ui.control.NumericEditField
        EditField_60                    matlab.ui.control.NumericEditField
        GHGemissionskgCO2eqmprobelengthLabel  matlab.ui.control.Label
        EditField_64                    matlab.ui.control.NumericEditField
        PENRconsumptionkWhmprobelengthLabel  matlab.ui.control.Label
        SimulationsTab                  matlab.ui.container.Tab
        BuildingenergysimulationsPanel  matlab.ui.container.Panel
        InputfilesxlsxwithhourlydataforawholeyearPanel  matlab.ui.container.Panel
        SolarirradianceEditField_2Label  matlab.ui.control.Label
        SolarirradianceEditField_2      matlab.ui.control.EditField
        ExternaltemperatureEditFieldLabel  matlab.ui.control.Label
        ExternaltemperatureEditField    matlab.ui.control.EditField
        UITable_3                       matlab.ui.control.Table
        FormatLabel_3                   matlab.ui.control.Label
        VariablesTextArea_3Label        matlab.ui.control.Label
        VariablesTextArea_3             matlab.ui.control.TextArea
        UITable_4                       matlab.ui.control.Table
        FormatLabel_4                   matlab.ui.control.Label
        VariablesTextArea_4Label        matlab.ui.control.Label
        VariablesTextArea_4             matlab.ui.control.TextArea
        StartsimulationButton           matlab.ui.control.Button
        CompleteLamp_4Label             matlab.ui.control.Label
        CompleteLamp_4                  matlab.ui.control.Lamp
        HeatingsystemsimulationsPanel   matlab.ui.container.Panel
        EfficienciesPanel               matlab.ui.container.Panel
        DHWstorageLabel                 matlab.ui.control.Label
        DHWstorageEditField             matlab.ui.control.NumericEditField
        OilboilerEditFieldLabel         matlab.ui.control.Label
        OilboilerEditField              matlab.ui.control.NumericEditField
        DistrictheatingheattransferstationEditFieldLabel  matlab.ui.control.Label
        DistrictheatingheattransferstationEditField  matlab.ui.control.NumericEditField
        PelletboilerEditFieldLabel      matlab.ui.control.Label
        PelletboilerEditField           matlab.ui.control.NumericEditField
        HeatpumpsTextAreaLabel          matlab.ui.control.Label
        HeatpumpsTextArea               matlab.ui.control.TextArea
        StartsimulationButton_2         matlab.ui.control.Button
        CompleteLamp_6Label             matlab.ui.control.Label
        CompleteLamp_6                  matlab.ui.control.Lamp
        TechnicalfeasibilityPanel       matlab.ui.container.Panel
        MaxsupplytemperaturewithgeothermalheatpumpCEditFieldLabel  matlab.ui.control.Label
        MaxsupplytemperaturewithgeothermalheatpumpCEditField  matlab.ui.control.NumericEditField
        MaxsupplytemperaturewithairtowaterheatpumpCEditFieldLabel  matlab.ui.control.Label
        MaxsupplytemperaturewithairtowaterheatpumpCEditField  matlab.ui.control.NumericEditField
        TextArea                        matlab.ui.control.TextArea
        ImpressumTab                    matlab.ui.container.Tab
        ProgrammerTextAreaLabel         matlab.ui.control.Label
        ProgrammerTextArea              matlab.ui.control.TextArea
        EmailTextAreaLabel              matlab.ui.control.Label
        EmailTextArea                   matlab.ui.control.TextArea
        TitleTextAreaLabel              matlab.ui.control.Label
        TitleTextArea                   matlab.ui.control.TextArea
        VersionTextAreaLabel            matlab.ui.control.Label
        VersionTextArea                 matlab.ui.control.TextArea
        DateTextAreaLabel               matlab.ui.control.Label
        DateTextArea                    matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % Building information
        TZ;                                                     % Time zone (UTC)
        longitude; latitude;                                    % [°]
        n_oc;                                                   % Number of occupants [-]
        n_floor;                                                % Number of floors [-]
        h_floor;                                                % Floor height
        per;                                                    % Building perimeter [m]
        z_gr; h_gr;                                             % Depth of cellar floor in the ground [m] and height above ground [m]
        areas = zeros(1,4);                                     % Areas of roof, ext. walls, windows, ground floor [m2]
        ERA;                                                    % Energy reference area [m2]
        or_walls = zeros(1,4);                                  % Orientation of ext. walls [°] (S=0, E=90, N=180/-180, W=-90)
        A_walls = zeros(1,4);                                   % Areas of ext. walls according to orientation [m2]
        A_win = zeros(1,4);                                     % Areas of windows according to orientation [m2]
        U = zeros(1,4);                                         % U-values of building envelope components [W/(m2*K)]
        sur_R;                                                  % Surface resistances included in U-values (according to ISO 6946)? (1=yes, 0=no)
        g_win;                                                  % G-value of windows [-]
        bui_class;                                              % Building class: 1=very light, 2=light, 3=medium, 4=heavy, 5=very heavy
        
        % Heating system information
        T_i_set; T_e_design;                                    % Indoor set temperature [°C] and outdoor design temperature [°C] to calculate (maximum) heating load
        heating_limit;                                          % Heating limit temperature [°C] (if T_e > heating_limit then no heating)
        n;                                                      % Radiator exponent: 1.1 (floor heating) - 1.3 (radiators) (Albers, 2018)
        delta_T;                                                % Temperature difference between supply and return for new heating system
        T_dhw;
        
        % Other parameters
        alpha_roof = 0.6; alpha_wall = 0.6;                         % Solar absorption coefficients (default values according to ISO 52016, table B.15)
        d_cf = 0.3; d_cw = 0.3; hc_c = 1.8;                         % Thicknesses of cellar floor and wall [m] (default values/assumptions) and thermal conductivity for concrete [W/(m*K)] (ISO 10456, table 3)
        F_sky_hor = 1; F_sky_ver = 0.5;                             % View factors to the sky [-] (ISO 52016, table B.18)
        h_ri = 5.13; h_re = 4.14;                                   % Radiative heat transfer coefficients [W/(m2*K)] (ISO 52016, table 25)
        h_ce = 20.0; h_ci_roof = 5.0; h_ci_wall = 2.5;              % Convective heat transfer coefficients [W/(m2*K)] (ISO 52016, table 25)
        h_ci_win = 2.5; h_ci_im = 2.5; h_ci_gf = 0.7;
        R_si_u = 0.10; R_si_h = 0.13; R_si_d = 0.17;                % Conventional surface resistances (u=upwards, h=horizontal, d=downwards) [m2*K/W] (ISO 6946, table 7)
        R_se = 0.04;
        d_sl = 0.5; hc_sl = 2.0; c_v_sl = 2000000;                  % Thickness [m], thermal conductivity [W/(m*K)] and heat capacity per volume [J/(m3*K)] of soil layer (ISO 13770, table 7)
        T_avg_sl = 10; dT_sl = 10;                                  % Average soil temperature and amplitude of surface temperature fluctuation [°C] (Zürcher and Frank, 2018)
        dens_air = 1.23; c_p_air = 1008/3600;                       % Density [kg/m3] and specific heat [Wh/(kg*K)] of air (ISO 10456, table 3)
        dens_water = 0.99; c_p_water = 4190;                        % Density [kg/l] and specific heat [J/(kg*K)] of water
        c_p_steel = 500;                                            % Specific heat [J/(kg*K)] of steel (ISO 10456)
        dens_cement = 1800; c_p_cement = 1000;                      % Density [kg/m3] and specific heat [J/(kg*K)] of cement
        dT_sky = 11;                                                % Difference between apparent sky temp. and air temp. [K] (ISO 52016, table B.19)
        f_el = 0.7;                                                 % Electricity reduction factor [-] (default value according to SIA 380/1)
        bui_C = [50000,75000,110000,175000,250000];                 % Specific heat capacities [J/(m2*K)] depending on bui_class (ISO 52016, table B.14)

        % Preallocation
        input_file;
        solar_input;
        A_im;
        R_sl; R_cf; R_cw;
        d_cf_w; d_cw_w;
        U_cf; U_cw;
        R_fl; R_gf;
        H_se_roof; H_se_wall; H_se_win; H_se_gf;
        H_ci_roof; H_ci_wall; H_ci_win; H_ci_im; H_ci_gf;
        H_ri_roof; H_ri_wall; H_ri_im; H_ri_gf;
        H_roof; H_wall; H_win; H1_gf; H2_gf;
        H_se_env; H_ci_env; H_tot_env; H_env;
        
        % Dates of training period
        year_tr = 2019;                                                 % Year of input data (2019 or 'reference')
        month_0_tr = 2;
        day_0_tr = 33;                                                  % First day of input data (1-365)
        n_days_tr = 21;                                                 % Time period of training data (in days)
        h_0_tr = (33-1)*24+1;                                           % First hour of input data
        time_0_tr;
        time_1_tr;
        time_tr;
        time_tr_d;
        
        % Dates of test period
        year_test = 2019;                                               % Year of input data (2019 or 'reference')
        month_0_test = 2;
        day_0_test = 54;                                                % First day of input data (1-365)
        n_days_test = 21;                                               % Time period of training data (in days)
        h_0_test = (54-1)*24+1;                                         % First hour of input data
        time_0_test;
        time_1_test;
        time_test;
        time_test_d;
        
        % Results
        T_i_reference = zeros(24,1);
        K_rad; H_tot; hload_max; hload_design;
        res_tr; corr;
        T_i_tr; T_i_lsim_tr;
        T_i; T_i_lsim;
        P_h; P_h_sim_tr;
        P_h_test; P_h_sim_test;
        P_h_d; P_h_day_tr;
        P_h_d_test; P_h_day_test;
        
        % Model parameters
        H_par = zeros(3,1);         % H_h, H_est, H_im
        C_par = zeros(5,1);         % C_env, C_gf, C_h, C_im, C_i
        
        % Retrofit matrix
        Uroof; Uwall; Uwin; Ugf; Gval;
        U_retrofit = zeros(16,4); Gwin = zeros(16,1);
        invest_single_BE = zeros(5,1); invest_single_MM = zeros(5,1); invest_HS = zeros(5,2);
        OM_HS = zeros(5,2);
        lifetime_BE = zeros(5,1); lifetime_MM = zeros(5,1); lifetime_HS = zeros(5,1);
        GHG_be = zeros(5,1); GHG_hs = zeros(5,1); GHG_probe;
        PENR_be = zeros(5,1); PENR_hs = zeros(5,1); PENR_probe;
        
        % Yearly building energy simulation
        sim_U; sim_input_file; sim_solar_input;
        time_sim; time_sim_day;
        sim_H_tot = zeros(16,1);
        sim_hload_max = zeros(16,1);
        sim_Q_h_year = zeros(16,1);
        sim_P_h_hour = zeros(8760,16);
        sim_T_supply_average = zeros(16,1);
        sim_T_supply_hour = zeros(8760,16);
        
        % Heating system simulation
        Q_dhw;
        eff_dhw; eff_oil; eff_dh; eff_pellet;
        T_supply_limit_air; T_supply_limit_geo;
        feasibility_air = zeros(16,1); feasibility_geo = zeros(16,1);
        E_final = zeros(16,5);
        length_probe = zeros(16,1);
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.TZ = app.TimezoneUTCEditField.Value;                                                   
        app.longitude = app.LongitudeEditField.Value; app.latitude = app.LatitudeEditField.Value;                          
        app.n_oc = app.NumberofoccupantsEditField.Value;                                                 
        app.n_floor = app.NumberofheatedfloorsEditField.Value;                                               
        app.h_floor = app.FloorheightmEditField.Value;                                            
        app.per = app.BuildingperimetermEditField.Value;                                                   
        app.z_gr = app.UndergrounddepthofcellarfloormEditField.Value; app.h_gr = app.OvergroundheightofcellarfloormEditField.Value;                                   
        app.areas(1) = app.RoofEditField_2.Value;
        app.areas(2) = app.ExternalwallsEditField_2.Value;
        app.areas(3) = app.WindowsEditField_2.Value;
        app.areas(4) = app.GroundfloorEditField_2.Value;
        app.ERA = app.EnergyreferenceareaERAm2EditField.Value;                                                
        app.or_walls(1) = app.SouthEditField.Value;
        app.or_walls(2) = app.EastEditField.Value;
        app.or_walls(3) = app.NorthEditField.Value;
        app.or_walls(4) = app.WestEditField.Value;
        app.A_walls(1) = app.SouthEditField_2.Value;
        app.A_walls(2) = app.EastEditField_2.Value;
        app.A_walls(3) = app.NorthEditField_2.Value;
        app.A_walls(4) = app.WestEditField_2.Value;
        app.A_win(1) = app.SouthEditField_3.Value;
        app.A_win(2) = app.EastEditField_3.Value;
        app.A_win(3) = app.NorthEditField_3.Value;
        app.A_win(4) = app.WestEditField_3.Value;
        app.U(1) = app.RoofEditField.Value;
        app.U(2) = app.ExternalwallsEditField.Value;
        app.U(3) = app.WindowsEditField.Value;
        app.U(4) = app.GroundfloorEditField.Value;
        value = app.SurfaceresistancesincludedCheckBox.Value;
            if value == 1
                app.sur_R = 1;
            else
                app.sur_R = 0;
            end
        app.g_win = app.GValueEditField.Value;
            if app.VerylightButton.Value==true
                app.bui_class = 1;
            elseif app.LightButton.Value==true
                app.bui_class = 2;
            elseif app.MediumButton.Value==true
                app.bui_class = 3;
            elseif app.HeavyButton.Value==true
                app.bui_class = 4;
            elseif app.VeryheavyButton.Value==true
                app.bui_class = 5;
            end
        
        app.T_i_set = app.RoomsetpointtemperatureCEditField.Value;
        app.T_e_design = app.OutdoordesigntemperatureCEditField.Value;
        app.heating_limit = app.HeatinglimitCEditField.Value;
        value_2 = app.TypeofheatdissipationDropDown.Value;
            if strcmp(value_2,'Floor heating')
                app.n = 1.1;
            elseif strcmp(value_2,'Floor heating and radiators')
                app.n = 1.2;
            elseif strcmp(value_2,'Radiators')
                app.n = 1.3;
            elseif strcmp(value_2,'Convectors')
                app.n = 1.4;
            end
        app.delta_T = app.TemperaturespreadCEditField.Value;      
        
        % Data input
        app.input_file = app.WSNmeasurementdataEditField.Value;
        app.solar_input = app.SolarirradianceEditField.Value;
        app.time_0_tr = app.StartdateDatePicker.Value;
        app.time_1_tr = app.EnddateDatePicker.Value+hours(23);
        app.time_tr = app.time_0_tr:hours(1):app.time_1_tr;
        app.time_tr_d = app.time_0_tr:days(1):app.time_1_tr;
        app.time_0_test = app.StartdateDatePicker_2.Value;
        app.time_1_test = app.EnddateDatePicker_2.Value+hours(23);
        app.time_test = app.time_0_test:hours(1):app.time_1_test;
        app.time_test_d = app.time_0_test:days(1):app.time_1_test;
        
        % Retrofit matrix
        app.Uroof = app.EditField.Value;
        app.Uwall = app.EditField_2.Value;
        app.Uwin = app.EditField_3.Value;
        app.Ugf = app.EditField_4.Value;
        app.Gval = app.EditField_5.Value;
        app.invest_single_BE(1) = app.EditField_10.Value;
        app.invest_single_BE(2) = app.EditField_11.Value;
        app.invest_single_BE(3) = app.EditField_12.Value;
        app.invest_single_BE(4) = app.EditField_13.Value;
        app.invest_single_MM(1) = app.EditField_32.Value;
        app.invest_single_MM(2) = app.EditField_33.Value;
        app.invest_single_MM(3) = app.EditField_34.Value;
        app.invest_single_MM(4) = app.EditField_35.Value;
        app.invest_HS(1,1) = app.CHFEditField.Value;
        app.invest_HS(2,1) = app.CHFEditField_2.Value;
        app.invest_HS(3,1) = app.CHFEditField_3.Value;
        app.invest_HS(4,1) = app.CHFEditField_4.Value;
        app.invest_HS(5,1) = app.CHFEditField_5.Value;
        app.invest_HS(1,2) = app.CHFkWEditField.Value;
        app.invest_HS(2,2) = app.CHFkWEditField_2.Value;
        app.invest_HS(3,2) = app.CHFkWEditField_3.Value;
        app.invest_HS(4,2) = app.CHFkWEditField_4.Value;
        app.invest_HS(5,2) = app.CHFkWEditField_5.Value;
        app.OM_HS(1,1) = app.CHFaEditField.Value;
        app.OM_HS(2,1) = app.CHFaEditField_2.Value;
        app.OM_HS(3,1) = app.CHFaEditField_3.Value;
        app.OM_HS(4,1) = app.CHFaEditField_4.Value;
        app.OM_HS(5,1) = app.CHFaEditField_5.Value;
        app.OM_HS(1,2) = app.CHFakWEditField.Value;
        app.OM_HS(2,2) = app.CHFakWEditField_2.Value;
        app.OM_HS(3,2) = app.CHFakWEditField_3.Value;
        app.OM_HS(4,2) = app.CHFakWEditField_4.Value;
        app.OM_HS(5,2) = app.CHFakWEditField_5.Value;
        app.lifetime_BE(1) = app.EditField_6.Value;
        app.lifetime_BE(2) = app.EditField_7.Value;
        app.lifetime_BE(3) = app.EditField_8.Value;
        app.lifetime_BE(4) = app.EditField_9.Value;
        app.lifetime_MM(1) = app.EditField_28.Value;
        app.lifetime_MM(2) = app.EditField_29.Value;
        app.lifetime_MM(3) = app.EditField_30.Value;
        app.lifetime_MM(4) = app.EditField_31.Value;
        app.lifetime_HS(1) = app.EditField_14.Value;
        app.lifetime_HS(2) = app.EditField_15.Value;
        app.lifetime_HS(3) = app.EditField_16.Value;
        app.lifetime_HS(4) = app.EditField_17.Value;
        app.lifetime_HS(5) = app.EditField_27.Value;
        app.GHG_be(1) = app.EditField_36.Value;
        app.GHG_be(2) = app.EditField_37.Value;
        app.GHG_be(3) = app.EditField_38.Value;
        app.GHG_be(4) = app.EditField_39.Value;
        app.PENR_be(1) = app.EditField_45.Value;
        app.PENR_be(2) = app.EditField_46.Value;
        app.PENR_be(3) = app.EditField_47.Value;
        app.PENR_be(4) = app.EditField_48.Value;
        app.GHG_hs(1) = app.EditField_49.Value;
        app.GHG_hs(2) = app.EditField_50.Value;
        app.GHG_hs(3) = app.EditField_51.Value;
        app.GHG_hs(4) = app.EditField_52.Value;
        app.GHG_hs(5) = app.EditField_57.Value;
        app.PENR_hs(1) = app.EditField_53.Value;
        app.PENR_hs(2) = app.EditField_54.Value;
        app.PENR_hs(3) = app.EditField_55.Value;
        app.PENR_hs(4) = app.EditField_56.Value;
        app.PENR_hs(5) = app.EditField_58.Value;
        app.GHG_probe = app.EditField_60.Value;
        app.PENR_probe = app.EditField_64.Value;
        
        % Yearly building energy simulation
        app.sim_input_file = app.ExternaltemperatureEditField.Value;
        app.sim_solar_input = app.SolarirradianceEditField_2.Value;
        
        % Heating system simulation
        app.T_dhw = app.TemperatureofDHWCEditField.Value;
        app.eff_dhw = app.DHWstorageEditField.Value;
        app.eff_oil = app.OilboilerEditField.Value;
        app.eff_dh = app.DistrictheatingheattransferstationEditField.Value;
        app.eff_pellet = app.PelletboilerEditField.Value;
        app.T_supply_limit_geo = app.MaxsupplytemperaturewithgeothermalheatpumpCEditField.Value;
        app.T_supply_limit_air = app.MaxsupplytemperaturewithairtowaterheatpumpCEditField.Value;
        
        % RC-network image
        imshow('rcmodel.png','Parent',app.UIAxes4)
        end

        % Value changed function: TimezoneUTCEditField
        function TimezoneUTCEditFieldValueChanged(app, event)
            app.TZ = app.TimezoneUTCEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: LongitudeEditField
        function LongitudeEditFieldValueChanged(app, event)
            app.longitude = app.LongitudeEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: LatitudeEditField
        function LatitudeEditFieldValueChanged(app, event)
            app.latitude = app.LatitudeEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: NumberofoccupantsEditField
        function NumberofoccupantsEditFieldValueChanged(app, event)
            app.n_oc = app.NumberofoccupantsEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: NumberofheatedfloorsEditField
        function NumberofheatedfloorsEditFieldValueChanged(app, event)
            app.n_floor = app.NumberofheatedfloorsEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: FloorheightmEditField
        function FloorheightmEditFieldValueChanged(app, event)
            app.h_floor = app.FloorheightmEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: 
        % UndergrounddepthofcellarfloormEditField
        function UndergrounddepthofcellarfloormEditFieldValueChanged(app, event)
            app.z_gr = app.UndergrounddepthofcellarfloormEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: 
        % OvergroundheightofcellarfloormEditField
        function OvergroundheightofcellarfloormEditFieldValueChanged(app, event)
            app.h_gr = app.OvergroundheightofcellarfloormEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: EnergyreferenceareaERAm2EditField
        function EnergyreferenceareaERAm2EditFieldValueChanged(app, event)
            app.ERA = app.EnergyreferenceareaERAm2EditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: BuildingperimetermEditField
        function BuildingperimetermEditFieldValueChanged(app, event)
            app.per = app.BuildingperimetermEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Selection changed function: BuildingclassButtonGroup
        function BuildingclassButtonGroupSelectionChanged(app, event)
            selectedButton = app.BuildingclassButtonGroup.SelectedObject.Text;
            if strcmp(selectedButton,'Very light')
                app.bui_class = 1;
            elseif strcmp(selectedButton,'Light')
                app.bui_class = 2;
            elseif strcmp(selectedButton,'Medium')
                app.bui_class = 3;
            elseif strcmp(selectedButton,'Heavy')
                app.bui_class = 4;
            elseif strcmp(selectedButton,'Very heavy')
                app.bui_class = 5;
            end
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: RoofEditField_2
        function RoofEditField_2ValueChanged(app, event)
            app.areas(1) = app.RoofEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: ExternalwallsEditField_2
        function ExternalwallsEditField_2ValueChanged(app, event)
            app.areas(2) = app.ExternalwallsEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: WindowsEditField_2
        function WindowsEditField_2ValueChanged(app, event)
            app.areas(3) = app.WindowsEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: GroundfloorEditField_2
        function GroundfloorEditField_2ValueChanged(app, event)
            app.areas(4) = app.GroundfloorEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: RoofEditField
        function RoofEditFieldValueChanged(app, event)
            app.U(1) = app.RoofEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: ExternalwallsEditField
        function ExternalwallsEditFieldValueChanged(app, event)
            app.U(2) = app.ExternalwallsEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: WindowsEditField
        function WindowsEditFieldValueChanged(app, event)
            app.U(3) = app.WindowsEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: GroundfloorEditField
        function GroundfloorEditFieldValueChanged(app, event)
            app.U(4) = app.GroundfloorEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: SurfaceresistancesincludedCheckBox
        function SurfaceresistancesincludedCheckBoxValueChanged(app, event)
            value = app.SurfaceresistancesincludedCheckBox.Value;
            if value == 1
                app.sur_R = 1;
            else
                app.sur_R = 0;
            end
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: GValueEditField
        function GValueEditFieldValueChanged(app, event)
            app.g_win = app.GValueEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: SouthEditField
        function SouthEditFieldValueChanged(app, event)
            app.or_walls(1) = app.SouthEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: EastEditField
        function EastEditFieldValueChanged(app, event)
            app.or_walls(2) = app.EastEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: NorthEditField
        function NorthEditFieldValueChanged(app, event)
            app.or_walls(3) = app.NorthEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: WestEditField
        function WestEditFieldValueChanged(app, event)
            app.or_walls(4) = app.WestEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: SouthEditField_2
        function SouthEditField_2ValueChanged(app, event)
            app.A_walls(1) = app.SouthEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: EastEditField_2
        function EastEditField_2ValueChanged(app, event)
            app.A_walls(2) = app.EastEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: NorthEditField_2
        function NorthEditField_2ValueChanged(app, event)
            app.A_walls(3) = app.NorthEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: WestEditField_2
        function WestEditField_2ValueChanged(app, event)
            app.A_walls(4) = app.WestEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: SouthEditField_3
        function SouthEditField_3ValueChanged(app, event)
            app.A_win(1) = app.SouthEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: EastEditField_3
        function EastEditField_3ValueChanged(app, event)
            app.A_win(2) = app.EastEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: NorthEditField_3
        function NorthEditField_3ValueChanged(app, event)
            app.A_win(3) = app.NorthEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: WestEditField_3
        function WestEditField_3ValueChanged(app, event)
            app.A_win(4) = app.WestEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: RoomsetpointtemperatureCEditField
        function RoomsetpointtemperatureCEditFieldValueChanged(app, event)
            app.T_i_set = app.RoomsetpointtemperatureCEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: OutdoordesigntemperatureCEditField
        function OutdoordesigntemperatureCEditFieldValueChanged(app, event)
            app.T_e_design = app.OutdoordesigntemperatureCEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: HeatinglimitCEditField
        function HeatinglimitCEditFieldValueChanged(app, event)
            app.heating_limit = app.HeatinglimitCEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: TypeofheatdissipationDropDown
        function TypeofheatdissipationDropDownValueChanged(app, event)
            value = app.TypeofheatdissipationDropDown.Value;
            if strcmp(value,'Floor heating')
                app.n = 1.1;
            elseif strcmp(value,'Floor heating and radiators')
                app.n = 1.2;
            elseif strcmp(value,'Radiators')
                app.n = 1.3;
            elseif strcmp(value,'Convectors')
                app.n = 1.4;
            end
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: TemperaturespreadCEditField
        function TemperaturespreadCEditFieldValueChanged(app, event)
            app.delta_T = app.TemperaturespreadCEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: WSNmeasurementdataEditField
        function WSNmeasurementdataEditFieldValueChanged(app, event)
            app.input_file = app.WSNmeasurementdataEditField.Value;
            app.CompleteLamp_2.Color = 'r';
        end

        % Value changed function: SolarirradianceEditField
        function SolarirradianceEditFieldValueChanged(app, event)
            app.solar_input = app.SolarirradianceEditField.Value;
            app.CompleteLamp_2.Color = 'r';
        end

        % Button pushed function: ImportbuildingdataButton
        function ImportbuildingdataButtonPushed(app, event)
            % Auxiliary values/calculations
            app.A_im = app.areas(4)*(3.5*app.n_floor-2);                            % Area of internal mass calculated according to (Lundström et al. 2019)
            app.R_sl = app.d_sl/app.hc_sl;                                          % Resistance of soil layer [m2*K/W] (ISO 13370)
            app.R_cf = app.d_cf/app.hc_c;                                           % Resistance of cellar floor [m2*K/W] (ISO 13370)
            app.R_cw = app.d_cw/app.hc_c;                                           % Resistance of cellar wall [m2*K/W] (ISO 13370)
            app.d_cf_w = app.d_cw+app.hc_sl*(app.R_si_d+app.R_cf+app.R_se);                     % Weighted thickness of cellar floor [m] (ISO 13370)
            app.d_cw_w = app.hc_sl*(app.R_si_h+app.R_cw+app.R_se);                          % Weighted thickness of cellar wall [m] (ISO 13370)
            app.U_cf = 2*app.hc_sl/(pi*app.areas(4)/(0.5*app.per)+app.d_cf_w+0.5*app.z_gr)...   % Calculated U-value of cellar floor [W/(m2*K)] (ISO 13370)
                *log(1+pi*app.areas(4)/(0.5*app.per)/(app.d_cf_w+0.5*app.z_gr));
            app.U_cw = 2*app.hc_sl/(pi*app.z_gr)*(1+0.5*app.d_cf_w/(app.d_cf_w+app.z_gr))...    % Calculated U-value of cellar wall [W/(m2*K)] (ISO 13370)
                *log(1+app.z_gr/app.d_cw_w);
            if app.sur_R==1
                app.R_fl = 1/app.U(4)-app.R_si_d-app.R_se;
            else
                app.R_fl = 1/app.U(4);
            end
            app.R_gf = app.R_fl+app.areas(4)/(app.areas(4)*app.U_cf+app.z_gr*app.per*app.U_cw+app.h_gr...   % Calculated resistance of complete ground floor (incl. cellar) [m2*K/W] (ISO 13370)
                *app.per*app.hc_c/app.d_cw+app.c_p_air*app.dens_air*0.3*app.areas(4)*app.h_floor);
            
            % Exterior surface heat transfer coefficients
            app.H_se_roof = app.areas(1)*(app.h_ce+app.F_sky_hor*app.h_re);
            app.H_se_wall = app.areas(2)*(app.h_ce+app.F_sky_ver*app.h_re);
            app.H_se_win = app.areas(3)*(app.h_ce+app.F_sky_ver*app.h_re);
            app.H_se_gf = app.areas(4)*1/(app.R_gf-app.R_si_d-app.R_cf-app.R_sl);
            
            % Interior surface heat transfer coefficients (convective and radiative)
            app.H_ci_roof = app.areas(1)*app.h_ci_roof; app.H_ri_roof = app.areas(1)*app.h_ri;
            app.H_ci_wall = app.areas(2)*app.h_ci_wall; app.H_ri_wall = app.areas(2)*app.h_ri;
            app.H_ci_win = app.areas(3)*app.h_ci_win;
            app.H_ci_im = app.A_im*app.h_ci_im; app.H_ri_im = app.A_im*app.h_ri;
            app.H_ci_gf = app.areas(4)*app.h_ci_gf; app.H_ri_gf = app.areas(4)*app.h_ri;
            
            % Heat transfer coefficients of building envelope elements
            if app.sur_R==1
                app.H_roof = app.areas(1)/(1/app.U(1)-app.R_si_u-app.R_se);
                app.H_wall = app.areas(2)/(1/app.U(2)-app.R_si_h-app.R_se);
                app.H_win = app.areas(3)/(1/app.U(3)-app.R_si_h-app.R_se);
                
            else
                app.H_roof = app.areas(1)*app.U(1);
                app.H_wall = app.areas(2)*app.U(2);
                app.H_win = app.areas(3)*app.U(3);
            end
            
            app.H1_gf = app.areas(4)/(app.R_gf/2+app.R_sl);
            app.H2_gf = 2*app.areas(4)/app.R_gf;
            
            % Lumped parameter calculation for simplified model
            app.H_se_env = app.H_se_roof+app.H_se_wall;
            app.H_ci_env = app.H_ci_roof+app.H_ci_wall;
            app.H_tot_env = 1/(1/app.H_se_roof+1/app.H_roof+1/app.H_ci_roof)+1/(1/app.H_se_wall+1/app.H_wall+1/app.H_ci_wall);
            app.H_env = 1/(1/app.H_tot_env-1/app.H_se_env-1/app.H_ci_env);
            
            % DHW demand [kWh/a]
            app.Q_dhw = 365*app.n_oc*40*(app.T_dhw-10)*4.190*0.99/3600;
            
            app.CompleteLamp.Color = 'g';
        end

        % Button pushed function: CalibratelightgreyboxmodelButton
        function CalibratelightgreyboxmodelButtonPushed(app, event)
            %% MODEL CALIBRATION AND TRAINING PERIOD
            [I_tot_hor, I_tot_ver] = solar_calc(app.solar_input,app.day_0_tr,...      % Calculating total horizontal and vertical irradiance [W/m2]
                app.n_days_tr,app.longitude,app.latitude,app.TZ,app.or_walls);
            
            if app.day_0_tr<212                                                % For calculating the ground temperature
                h_ground = app.h_0_tr+24*(31+30+31+30+31);
            else
                h_ground = app.h_0_tr-24*(31+28+31+30+31+30+31);
            end
            
            range_start = int2str(app.day_0_tr*24-22);
            range_end = int2str((app.day_0_tr+app.n_days_tr-1)*24+1);
            app.T_i_tr = xlsread(app.input_file,1,['C',range_start,':C',range_end]);
            T_e = xlsread(app.input_file,1,['D',range_start,':D',range_end]);
            T_supply = xlsread(app.input_file,1,['G',range_start,':G',range_end]);
            T_return = xlsread(app.input_file,1,['F',range_start,':F',range_end]);
            flow = xlsread(app.input_file,1,['E',range_start,':E',range_end]);
            
            % Preallocation
            T_i_sum = zeros(24,app.n_days_tr*24);
            app.P_h = zeros(app.n_days_tr*24,1);
            P_re_roof = zeros(app.n_days_tr*24,1);
            P_re_wall = zeros(app.n_days_tr*24,1);
            P_re = zeros(app.n_days_tr*24,1);
            P_sol_win = zeros(app.n_days_tr*24,1);
            T_gr = zeros(app.n_days_tr*24,1);
            
            h_count = 1;
            for i=1:(app.n_days_tr*24)
                % Sum of indoor temperatures at each hour of day
                T_i_sum(h_count,i) = app.T_i_tr(i);
                h_count = h_count+1;
                if h_count==25
                    h_count = 1;
                end
                % Heat input of heating system
                app.P_h(i) = flow(i)*app.dens_water*app.c_p_water*(T_supply(i)-T_return(i));
                % Net radiant heat gains on exterior surfaces
                P_re_roof(i) = app.areas(1)*(app.alpha_roof*I_tot_hor(i)-app.F_sky_hor*app.h_re*app.dT_sky);
                I_tot_ver_w = 1/app.areas(2)*(app.A_walls(1)*I_tot_ver(i,1)+app.A_walls(2)*...
                    I_tot_ver(i,2)+app.A_walls(3)*I_tot_ver(i,3)+app.A_walls(4)*I_tot_ver(i,4));
                P_re_wall(i) = app.areas(2)*(app.alpha_wall*I_tot_ver_w-app.F_sky_ver*app.h_re*app.dT_sky);
                P_re(i) = P_re_roof(i)+P_re_wall(i);
                % Solar heat gains through windows
                I_tot_win_w = 1/app.areas(3)*(app.A_win(1)*I_tot_ver(i,1)+app.A_win(2)*...
                    I_tot_ver(i,2)+app.A_win(3)*I_tot_ver(i,3)+app.A_win(4)*I_tot_ver(i,4));
                P_sol_win(i) = app.areas(3)*app.g_win*I_tot_win_w;
                % Ground temperature (2m depth)
                T_gr(i) = app.T_avg_sl+app.dT_sl*(exp((-1)*app.z_gr*sqrt(pi/(app.hc_sl/app.c_v_sl*3600*8760)))...
                    *cos(2*pi/8760*h_ground-app.z_gr*sqrt(pi/(app.hc_sl/app.c_v_sl*3600*8760))));
                h_ground = h_ground+1;
            end
            
            u = [T_e,P_re,T_gr,P_sol_win,app.P_h];
            y = app.T_i_tr;
            
            % Calculation of indoor temperature profile
            for i=1:24
                app.T_i_reference(i) = sum(T_i_sum(i,:))/app.n_days_tr;
            end
            
            % Calculation of radiator constant K_rad [W/°C^n] (Jangsten et al., 2017)
            h_count = 1;
            for i=2:(app.n_days_tr*24)
                if app.P_h(i)<=1.1*app.P_h(i-1) && app.P_h(i)>=0.9*app.P_h(i-1) && flow(i)>=0.5*max(flow)       % Only consider time periods when heating is not turned on/off and high flow rates
                    K_rad_i(h_count) = app.P_h(i)/((T_supply(i)-T_return(i))/log((T_supply(i)-app.T_i_tr(i))/(T_return(i)-app.T_i_tr(i))))^app.n;
                    h_count = h_count+1;
                end
            end
            app.K_rad = mean(K_rad_i);
            
            % Parameter estimation
            Ts = 3600;                                                  % Sample time [s]
            data = iddata(y,u,Ts,'InterSample',{'foh';'foh';'foh';'foh';'foh'});                    % Create an iddata object for time-series data
            
            % Set initial values for parameters
            H_1_env = 2*app.H_env; H_2_env = H_1_env;
            H_1_gf = app.H1_gf; H_2_gf = app.H2_gf;
            
            H_est = 0;
            H_h = app.n_floor*6*4*app.h_ci_im;                                 % Assumption: 6 radiators with 4m2 heating area per floor
            H_im = app.A_im*app.h_ci_im;
            C_env = (app.areas(1)+app.areas(2))*app.bui_C(app.bui_class);
            C_gf = app.areas(4)*app.bui_C(app.bui_class);
            C_h = app.c_p_steel*40*app.n_floor*6;                              % Assumption: 6 radiators of 40kg steel per floor
            C_im = app.A_im*app.bui_C(app.bui_class);
            C_i = app.ERA*app.h_floor*app.dens_air*app.c_p_air*3600;
            
            % Create linear grey-box model with identifiable parameters
            parameters = {'H_se_env',app.H_se_env; 'H_se_win',app.H_se_win; 'H_se_gf',app.H_se_gf; 'H_ci_env',app.H_ci_env; 'H_ci_win',app.H_ci_win;...
                'H_ci_gf',app.H_ci_gf; 'H_1_env',H_1_env; 'H_2_env',H_2_env; 'H_win',app.H_win; 'H_1_gf',H_1_gf; 'H_2_gf',H_2_gf;...
                'H_h',H_h; 'H_est',H_est; 'H_im',H_im; 'C_env',C_env; 'C_gf',C_gf; 'C_h',C_h; 'C_im',C_im; 'C_i',C_i};
            fcn_type = 'c';
            sys_init = idgrey('par_to_SS',parameters,fcn_type,{},0);
            
            % Set parameter constraints
            sys_init.Structure.Parameters(1).Free = false;
            sys_init.Structure.Parameters(2).Free = false;
            sys_init.Structure.Parameters(3).Free = false;
            sys_init.Structure.Parameters(4).Free = false;
            sys_init.Structure.Parameters(5).Free = false;
            sys_init.Structure.Parameters(6).Free = false;
            sys_init.Structure.Parameters(7).Free = false;
            sys_init.Structure.Parameters(8).Free = false;
            sys_init.Structure.Parameters(9).Free = false;
            sys_init.Structure.Parameters(10).Free = false;
            sys_init.Structure.Parameters(11).Free = false;
            sys_init.Structure.Parameters(12).Free = false;
            sys_init.Structure.Parameters(13).Minimum = 0;
            sys_init.Structure.Parameters(13).Maximum = 300;
            sys_init.Structure.Parameters(14).Free = false;
            sys_init.Structure.Parameters(15).Minimum = (app.areas(1)+app.areas(2))*app.bui_C(1);
            sys_init.Structure.Parameters(15).Maximum = (app.areas(1)+app.areas(2))*app.bui_C(5);
            sys_init.Structure.Parameters(16).Minimum = app.areas(4)*app.bui_C(1);
            sys_init.Structure.Parameters(16).Maximum = app.areas(4)*app.bui_C(5);
            sys_init.Structure.Parameters(17).Minimum = 0;
            sys_init.Structure.Parameters(17).Maximum = 10*app.c_p_steel*40*app.n_floor*6;
            sys_init.Structure.Parameters(18).Minimum = app.A_im*app.bui_C(1);
            sys_init.Structure.Parameters(18).Maximum = app.A_im*app.bui_C(5);
            sys_init.Structure.Parameters(19).Minimum = app.ERA*app.h_floor*app.dens_air*app.c_p_air*3600;
            sys_init.Structure.Parameters(19).Maximum = 10*app.ERA*app.h_floor*app.dens_air*app.c_p_air*3600;
            
            % Set initial states
            x0 = [8;10;50;22;22];
            
            % Set options for linear grey-box estimation (greyest)
            opt = greyestOptions;
            opt.Focus = 'simulation';
            opt.EnforceStability = true;
            opt.Display = 'on';
            opt.InitialState = x0;
            opt.DisturbanceModel = 'estimate';
            opt.SearchMethod = 'auto';
            opt.SearchOptions.MaxIterations = 50;
            
            % Estimate parameters
            sys_est = greyest(data, sys_init, opt);
            
            % Initial parameters and estimated parameters
            est_par = zeros(8,4);
            
            est_par(1,1) = H_h;
            est_par(2,1) = H_est;
            est_par(3,1) = H_im;
            est_par(4,1) = C_env;
            est_par(5,1) = C_gf;
            est_par(6,1) = C_h;
            est_par(7,1) = C_im;
            est_par(8,1) = C_i;
            
            est_par(1,2) = sys_est.Structure.Parameters(12).Value;
            est_par(2,2) = sys_est.Structure.Parameters(13).Value;
            est_par(3,2) = sys_est.Structure.Parameters(14).Value;
            est_par(4,2) = sys_est.Structure.Parameters(15).Value;
            est_par(5,2) = sys_est.Structure.Parameters(16).Value;
            est_par(6,2) = sys_est.Structure.Parameters(17).Value;
            est_par(7,2) = sys_est.Structure.Parameters(18).Value;
            est_par(8,2) = sys_est.Structure.Parameters(19).Value;
            
            app.H_par(1) = sys_est.Structure.Parameters(12).Value;
            app.H_par(2) = sys_est.Structure.Parameters(13).Value;
            app.H_par(3) = sys_est.Structure.Parameters(14).Value;
            app.C_par(1) = sys_est.Structure.Parameters(15).Value;
            app.C_par(2) = sys_est.Structure.Parameters(16).Value;
            app.C_par(3) = sys_est.Structure.Parameters(17).Value;
            app.C_par(4) = sys_est.Structure.Parameters(18).Value;
            app.C_par(5) = sys_est.Structure.Parameters(19).Value;
            
            % Calculate total heat transfer coefficient (HTC)
            app.H_tot = 1/(1/app.H_ci_env+1/H_1_env+1/H_2_env+1/app.H_se_env)+1/(1/app.H_ci_win+1/app.H_win+1/app.H_se_win)+est_par(2,2)+1/(1/app.H_ci_gf+1/H_1_gf+1/H_2_gf+1/app.H_se_gf)*(app.T_i_set-app.T_avg_sl)/(app.T_i_set-app.T_e_design);
            app.hload_design = app.H_tot*(app.T_i_set-app.T_e_design)/1000;
            % Simulation (of internal temperature): Training period
            T = zeros(app.n_days_tr*24,1);
            for j=1:(app.n_days_tr*24)
                T(j) = j*3600;
            end
            x0_training = x0;
            
            [app.T_i_lsim_tr,~,x_lsim_tr] = lsim(sys_est,u,T,x0_training,'foh');
            
            % Model validation: Training period
            app.res_tr = app.T_i_tr-app.T_i_lsim_tr;        % Residuals
            app.corr = corrcov(getcov(sys_est));    % Correlation matrix of estimated parameters (coefficients < |0.98|)    
            
            % Simulation of heating load: Training period
            [A1,B1,C1,D1] = par_to_SS_hload(app.H_se_env,app.H_se_win,app.H_se_gf,app.H_ci_env,app.H_ci_win,app.H_ci_gf,H_1_env,H_2_env,app.H_win,H_1_gf,H_2_gf,est_par(2,2),est_par(3,2),est_par(4,2),est_par(5,2),est_par(7,2),est_par(8,2));
            sys_hload_1 = ss(A1,B1,C1,D1);
            [A2,B2,C2,D2] = par_to_SS_hload_2(app.H_se_env,app.H_se_win,app.H_se_gf,app.H_ci_env,app.H_ci_win,app.H_ci_gf,H_1_env,H_2_env,app.H_win,H_1_gf,H_2_gf,est_par(2,2),est_par(3,2),est_par(4,2),est_par(5,2),est_par(7,2),est_par(8,2));
            sys_hload_2 = ss(A2,B2,C2,D2);
            x0_hload = [x0_training(1);x0_training(2);x0_training(4);app.T_i_tr(1)];
            hload_min = zeros(60,1); hload_min(1) = est_par(1,2)*(x_lsim_tr(1,3)-app.T_i_tr(1));
            app.hload_max = app.H_tot*(app.T_i_set-app.T_e_design)+app.Q_dhw*1000/8760;
            T_i_hload_min = zeros(60,1); T_i_hload_min(1) = app.T_i_tr(1);
            T_i_hload = zeros(app.n_days_tr*24,1); T_i_hload(1) = app.T_i_tr(1);
            T_h_hload_min = zeros(60,1); T_h_hload_min(1) = x0_training(3);
            T_h_hload_hour = zeros(app.n_days_tr*24,1); T_h_hload_hour(1) = x0_training(3);
            P_h_sim_min = zeros(60,1); P_h_sim_min(1) = app.P_h(1);
            app.P_h_sim_tr = zeros(app.n_days_tr*24,1); app.P_h_sim_tr(1) = app.P_h(1);
            
            for j=2:(app.n_days_tr*24)
                for i=2:60
                    u_hload = [T_e(j-1)+(i-1)/60*(T_e(j)-T_e(j-1)),P_re(j-1)+(i-1)/60*(P_re(j)-P_re(j-1)),T_gr(j-1)+(i-1)/60*(T_gr(j)-T_gr(j-1)),P_sol_win(j-1)+(i-1)/60*(P_sol_win(j)-P_sol_win(j-1));T_e(j-1)+i/60*(T_e(j)-T_e(j-1)),P_re(j-1)+i/60*(P_re(j)-P_re(j-1)),T_gr(j-1)+i/60*(T_gr(j)-T_gr(j-1)),P_sol_win(j-1)+i/60*(P_sol_win(j)-P_sol_win(j-1))];
                    [T_i_low,~,~] = lsim(sys_hload_1,u_hload,[0;60],x0_hload,'foh');
                    hload_min(i) = est_par(8,2)*((app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1)))-T_i_low(2))/60;
                    
                    A_hload = [1+60*est_par(1,2)/est_par(6,2), (-1)*60/est_par(6,2); est_par(1,2), 0];
                    b_hload = [60*est_par(1,2)/est_par(6,2)*(app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1)))+T_h_hload_min(i-1); hload_min(i)+est_par(1,2)*(app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1)))];
                    X = linsolve(A_hload,b_hload);
                    
                    P_h_sim_min(i) = X(2);
                    T_h_hload_min(i) = X(1);
                    
                    if (T_e(j-1)+(i-1)/60*(T_e(j)-T_e(j-1)))>app.heating_limit
                        P_h_sim_min(i) = 0;
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    elseif P_h_sim_min(i)<0
                        P_h_sim_min(i) = 0;
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    elseif P_h_sim_min(i)>app.hload_max
                        P_h_sim_min(i) = app.hload_max;
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    else
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    end
                    
                    hload_min(i) = est_par(1,2)*(T_h_hload_min(i)-(app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1))));
                    
                    u_hload_2 = [T_e(j-1)+(i-1)/60*(T_e(j)-T_e(j-1)),P_re(j-1)+(i-1)/60*(P_re(j)-P_re(j-1)),T_gr(j-1)+(i-1)/60*(T_gr(j)-T_gr(j-1)),P_sol_win(j-1)+(i-1)/60*(P_sol_win(j)-P_sol_win(j-1)),hload_min(i-1);T_e(j-1)+i/60*(T_e(j)-T_e(j-1)),P_re(j-1)+i/60*(P_re(j)-P_re(j-1)),T_gr(j-1)+i/60*(T_gr(j)-T_gr(j-1)),P_sol_win(j-1)+i/60*(P_sol_win(j)-P_sol_win(j-1)),hload_min(i)];
                    [~,~,x_hload_2] = lsim(sys_hload_2,u_hload_2,[0;60],x0_hload,'foh');
                    x0_hload = [x_hload_2(2,1);x_hload_2(2,2);x_hload_2(2,3);app.T_i_tr(j-1)+i/60*(app.T_i_tr(j)-app.T_i_tr(j-1))];
                    T_i_hload_min(i) = x_hload_2(2,4);
                end
                T_h_hload_hour(j) = sum(T_h_hload_min)/60;
                app.P_h_sim_tr(j) = sum(P_h_sim_min)/60;
                hload_min(1) = hload_min(60);
                P_h_sim_min(1) = P_h_sim_min(60);
                T_h_hload_min(1) = T_h_hload_min(60);
                T_i_hload(j) = T_i_hload_min(60);
            end
            
            % Daily heat demand [kWh]
            app.P_h_day_tr = zeros(app.n_days_tr,1); app.P_h_d = zeros(app.n_days_tr,1);
            for j=1:app.n_days_tr
                % Simulated daily heating demand
                app.P_h_day_tr(j) = sum(app.P_h_sim_tr((j-1)*24+1:j*24))/1000;
                % Measured daily heating demand
                app.P_h_d(j) = sum(app.P_h((j-1)*24+1:j*24))/1000;
            end
            
            %% TEST PERIOD
            [I_tot_hor_test, I_tot_ver_test] = solar_calc(app.solar_input,app.day_0_test,...      % Calculating total horizontal and vertical irradiance [W/m2]
                app.n_days_test,app.longitude,app.latitude,app.TZ,app.or_walls);
            
            if app.day_0_test<212                                                % For calculating the ground temperature
                h_ground = app.h_0_test+24*(31+30+31+30+31);
            else
                h_ground = app.h_0_test-24*(31+28+31+30+31+30+31);
            end
            
            range_start = int2str(app.day_0_test*24-22);
            range_end = int2str((app.day_0_test+app.n_days_test-1)*24+1);
            app.T_i = xlsread(app.input_file,1,['C',range_start,':C',range_end]);
            T_e_test = xlsread(app.input_file,1,['D',range_start,':D',range_end]);
            T_supply_test = xlsread(app.input_file,1,['G',range_start,':G',range_end]);
            T_return_test = xlsread(app.input_file,1,['F',range_start,':F',range_end]);
            flow_test = xlsread(app.input_file,1,['E',range_start,':E',range_end]);
            
            % Preallocation
            app.P_h_test = zeros(app.n_days_test*24,1);
            P_re_roof = zeros(app.n_days_test*24,1);
            P_re_wall = zeros(app.n_days_test*24,1);
            P_re_test = zeros(app.n_days_test*24,1);
            P_sol_win_test = zeros(app.n_days_test*24,1);
            T_gr_test = zeros(app.n_days_test*24,1);
            
            for i=1:(app.n_days_test*24)
                % Heat input of heating system
                app.P_h_test(i) = flow_test(i)*app.dens_water*app.c_p_water*(T_supply_test(i)-T_return_test(i));
                % Net radiant heat gains on exterior surfaces
                P_re_roof(i) = app.areas(1)*(app.alpha_roof*I_tot_hor_test(i)-app.F_sky_hor*app.h_re*app.dT_sky);
                I_tot_ver_w = 1/app.areas(2)*(app.A_walls(1)*I_tot_ver_test(i,1)+app.A_walls(2)*...
                    I_tot_ver_test(i,2)+app.A_walls(3)*I_tot_ver_test(i,3)+app.A_walls(4)*I_tot_ver_test(i,4));
                P_re_wall(i) = app.areas(2)*(app.alpha_wall*I_tot_ver_w-app.F_sky_ver*app.h_re*app.dT_sky);
                P_re_test(i) = P_re_roof(i)+P_re_wall(i);
                % Solar heat gains through windows
                I_tot_win_w = 1/app.areas(3)*(app.A_win(1)*I_tot_ver_test(i,1)+app.A_win(2)*...
                    I_tot_ver_test(i,2)+app.A_win(3)*I_tot_ver_test(i,3)+app.A_win(4)*I_tot_ver_test(i,4));
                P_sol_win_test(i) = app.areas(3)*app.g_win*I_tot_win_w;
                % Ground temperature (2m depth)
                T_gr_test(i) = app.T_avg_sl+app.dT_sl*(exp((-1)*app.z_gr*sqrt(pi/(app.hc_sl/app.c_v_sl*3600*8760)))...
                    *cos(2*pi/8760*h_ground-app.z_gr*sqrt(pi/(app.hc_sl/app.c_v_sl*3600*8760))));
                h_ground = h_ground+1;
            end
            
            u_test = [T_e_test,P_re_test,T_gr_test,P_sol_win_test,app.P_h_test];
            
            % Simulation (of internal temperature): Test period
            T_test = zeros(app.n_days_test*24,1);
            for j=1:(app.n_days_test*24)
                T_test(j) = j*3600;
            end
            x0_test = x0_training;
            
            [app.T_i_lsim,~,x_lsim] = lsim(sys_est,u_test,T_test,x0_test,'foh');
            
            % Simulation of heating load: Test period
            x0_hload = [x0_training(1);x0_training(2);x0_training(4);app.T_i(1)];
            hload_min = zeros(60,1); hload_min(1) = est_par(1,2)*(x_lsim(1,3)-app.T_i(1));
            T_i_hload_test = zeros(app.n_days_test*24,1); T_i_hload_test(1) = app.T_i(1);
            T_i_hload_min = zeros(60,1); T_i_hload_min(1) = app.T_i(1);
            T_h_hload_min = zeros(60,1); T_h_hload_min(1) = x0_test(3);
            T_h_hload_hour_test = zeros(app.n_days_test*24,1); T_h_hload_hour_test(1) = x0_training(3);
            P_h_sim_min = zeros(60,1); P_h_sim_min(1) = app.P_h_test(1);
            app.P_h_sim_test = zeros(app.n_days_test*24,1); app.P_h_sim_test(1) = app.P_h_test(1);
            
            for j=2:(app.n_days_test*24)
                for i=2:60
                    u_hload = [T_e_test(j-1)+(i-1)/60*(T_e_test(j)-T_e_test(j-1)),P_re_test(j-1)+(i-1)/60*(P_re_test(j)-P_re_test(j-1)),T_gr_test(j-1)+(i-1)/60*(T_gr_test(j)-T_gr_test(j-1)),P_sol_win_test(j-1)+(i-1)/60*(P_sol_win_test(j)-P_sol_win_test(j-1));T_e_test(j-1)+i/60*(T_e_test(j)-T_e_test(j-1)),P_re_test(j-1)+i/60*(P_re_test(j)-P_re_test(j-1)),T_gr_test(j-1)+i/60*(T_gr_test(j)-T_gr_test(j-1)),P_sol_win_test(j-1)+i/60*(P_sol_win_test(j)-P_sol_win_test(j-1))];
                    [T_i_low,~,~] = lsim(sys_hload_1,u_hload,[0;60],x0_hload,'foh');
                    hload_min(i) = est_par(8,2)*((app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1)))-T_i_low(2))/60;
                    
                    A_hload = [1+60*est_par(1,2)/est_par(6,2), (-1)*60/est_par(6,2); est_par(1,2), 0];
                    b_hload = [60*est_par(1,2)/est_par(6,2)*(app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1)))+T_h_hload_min(i-1); hload_min(i)+est_par(1,2)*(app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1)))];
                    X = linsolve(A_hload,b_hload);
                    
                    P_h_sim_min(i) = X(2);
                    T_h_hload_min(i) = X(1);
                    
                    if (T_e_test(j-1)+(i-1)/60*(T_e_test(j)-T_e_test(j-1)))>app.heating_limit
                        P_h_sim_min(i) = 0;
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    elseif P_h_sim_min(i)<0
                        P_h_sim_min(i) = 0;
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    elseif P_h_sim_min(i)>app.hload_max
                        P_h_sim_min(i) = app.hload_max;
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    else
                        T_h_hload_min(i) = (T_h_hload_min(i-1)+60*est_par(1,2)/est_par(6,2)*(app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1)))+60*P_h_sim_min(i)/est_par(6,2))/(1+60*est_par(1,2)/est_par(6,2));
                    end
                    
                    hload_min(i) = est_par(1,2)*(T_h_hload_min(i)-(app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1))));
                    
                    u_hload_2 = [T_e_test(j-1)+(i-1)/60*(T_e_test(j)-T_e_test(j-1)),P_re_test(j-1)+(i-1)/60*(P_re_test(j)-P_re_test(j-1)),T_gr_test(j-1)+(i-1)/60*(T_gr_test(j)-T_gr_test(j-1)),P_sol_win_test(j-1)+(i-1)/60*(P_sol_win_test(j)-P_sol_win_test(j-1)),hload_min(i-1);T_e_test(j-1)+i/60*(T_e_test(j)-T_e_test(j-1)),P_re_test(j-1)+i/60*(P_re_test(j)-P_re_test(j-1)),T_gr_test(j-1)+i/60*(T_gr_test(j)-T_gr_test(j-1)),P_sol_win_test(j-1)+i/60*(P_sol_win_test(j)-P_sol_win_test(j-1)),hload_min(i)];
                    [~,~,x_hload_2] = lsim(sys_hload_2,u_hload_2,[0;60],x0_hload,'foh');
                    x0_hload = [x_hload_2(2,1);x_hload_2(2,2);x_hload_2(2,3);app.T_i(j-1)+i/60*(app.T_i(j)-app.T_i(j-1))];
                    T_i_hload_min(i) = x_hload_2(2,4);
                end
                T_h_hload_hour_test(j) = sum(T_h_hload_min)/60;
                app.P_h_sim_test(j) = sum(P_h_sim_min)/60;
                hload_min(1) = hload_min(60);
                P_h_sim_min(1) = P_h_sim_min(60);
                T_h_hload_min(1) = T_h_hload_min(60);
                T_i_hload_test(j) = T_i_hload_min(60);
            end
            
            % Daily heating demand [kWh]
            app.P_h_day_test = zeros(app.n_days_test,1); app.P_h_d_test = zeros(app.n_days_test,1);
            for j=1:app.n_days_test
                % Simulated daily heating demand
                app.P_h_day_test(j) = sum(app.P_h_sim_test((j-1)*24+1:j*24))/1000;
                % Measured daily heating demand
                app.P_h_d_test(j) = sum(app.P_h_test((j-1)*24+1:j*24))/1000;
            end
           
            % UI: Model validation, Model parameters, Heat transfer coefficients
            app.UITable4.RowName = {'H_1_env';'H_2_env';'H_win';'H_1_gf';'H_2_gf';'H_est'};
            app.UITable4.ColumnFormat = {'numeric','char'};
            app.UITable4.Data = {H_1_env,'calculated';H_2_env,'calculated';app.H_win,'calculated';H_1_gf,'calculated';H_2_gf,'calculated';app.H_par(2),'estimated'};
            
            % UI: Model validation, Model parameters, Surface heat transfer coefficients
            app.UITable4_3.RowName = {'H_se_env';'H_se_win';'H_se_gf';'H_ci_env';'H_ci_win';'H_ci_gf';'H_h';'H_im'};
            app.UITable4_3.ColumnFormat = {'numeric','char'};
            app.UITable4_3.Data = {app.H_se_env,'calculated';app.H_se_win,'calculated';app.H_se_gf,'calculated';app.H_ci_env,'calculated';app.H_ci_win,'calculated';app.H_ci_gf,'calculated';app.H_par(1),'calculated';app.H_par(3),'calculated'};
            
            % UI: Model validation, Model parameters, Capacities
            app.UITable4_2.RowName = {'C_env';'C_gf';'C_h';'C_im';'C_i'};
            app.UITable4_2.ColumnFormat = {'numeric','char'};
            app.UITable4_2.Data = {app.C_par(1),'estimated';app.C_par(2),'estimated';app.C_par(3),'estimated';app.C_par(4),'estimated';app.C_par(5),'estimated'};
            
            % UI: Model validation, Overview, Building characteristics
            app.UITable2.ColumnName = {};
            app.UITable2.RowName = {'HLC';'Design heating load';'Radiator constant'};
            app.UITable2.ColumnFormat = {'numeric','char'};
            app.UITable2.Data = {app.H_tot,' W/K';app.hload_design,' kW';app.K_rad,' W/K^n'};
            
            % UI: Model validation, Overview, Training period
            mean_tr = [mean(app.T_i_tr),mean(app.T_i_lsim_tr),mean(app.P_h_d),mean(app.P_h_day_tr)];
            mape_tr = [0,100*sum(abs((app.T_i_tr-app.T_i_lsim_tr)./app.T_i_tr))/numel(app.T_i_tr),0,100*sum(abs((app.P_h_d-app.P_h_day_tr)./app.P_h_d))/numel(app.P_h_d)];
            mae_tr = [0,sum(abs(app.T_i_tr(:)-app.T_i_lsim_tr(:)))/numel(app.T_i_tr),0,sum(abs(app.P_h_d(:)-app.P_h_day_tr(:)))/numel(app.P_h_d)];
            app.UITable3.ColumnName = {'Measured T_i [°C]';'Simulated T_i [°C]';'Measured heating demand [kWh/day]';'Simulated heating demand [kWh/day]'};
            app.UITable3.RowName = {'Mean';'MAPE [%]';'MAE'};
            app.UITable3.Data = [mean_tr;mape_tr;mae_tr];
            
            % UI: Model validation, Overview, Test period
            mean_test = [mean(app.T_i),mean(app.T_i_lsim),mean(app.P_h_d_test),mean(app.P_h_day_test)];
            mape_test = [0,100*sum(abs((app.T_i-app.T_i_lsim)./app.T_i))/numel(app.T_i),0,100*sum(abs((app.P_h_d_test-app.P_h_day_test)./app.P_h_d_test))/numel(app.P_h_d_test)];
            mae_test = [0,sum(abs(app.T_i(:)-app.T_i_lsim(:)))/numel(app.T_i),0,sum(abs(app.P_h_d_test(:)-app.P_h_day_test(:)))/numel(app.P_h_d_test)];
            app.UITable3_2.ColumnName = {'Measured T_i [°C]';'Simulated T_i [°C]';'Measured heating demand [kWh/day]';'Simulated heating demand [kWh/day]'};
            app.UITable3_2.RowName = {'Mean';'MAPE [%]';'MAE'};
            app.UITable3_2.Data = [mean_test;mape_test;mae_test];
            
            % UI: Model validation, Indoor temperature, Training period
            plot(app.UIAxes,app.time_tr,app.T_i_tr,app.time_tr,app.T_i_lsim_tr)
            legend(app.UIAxes,'Measured','Simulated')
            
            % UI: Model validation, Indoor temperature, Test period
            plot(app.UIAxes_2,app.time_test,app.T_i,app.time_test,app.T_i_lsim)
            legend(app.UIAxes_2,'Measured','Simulated')
            
            % UI: Model validation, Heating demand, Training period
            plot(app.UIAxes3,app.time_tr_d,app.P_h_d,'-o',app.time_tr_d,app.P_h_day_tr,'-o')
            legend(app.UIAxes3,'Measured','Simulated')
            ylim(app.UIAxes3,[0 inf])
            
            % UI: Model validation, Heating demand, Test period
            plot(app.UIAxes2_3,app.time_test_d,app.P_h_d_test,'-o',app.time_test_d,app.P_h_day_test,'-o')
            legend(app.UIAxes2_3,'Measured','Simulated')
            ylim(app.UIAxes2_3,[0 inf])
            
            app.CompleteLamp_2.Color = 'g'; 
        end

        % Value changed function: StartdateDatePicker
        function StartdateDatePickerValueChanged(app, event)
            app.time_0_tr = app.StartdateDatePicker.Value;
            app.year_tr = year(app.time_0_tr);                                        % Year of input data (2019 or 'reference')
            app.month_0_tr = month(app.time_0_tr);
            app.day_0_tr = day(app.time_0_tr,'dayofyear');                            % First day of input data (1-365)
            app.n_days_tr = day(app.time_1_tr,'dayofyear')-app.day_0_tr+1;            % Time period of training data (in days)
            app.h_0_tr = (app.day_0_tr-1)*24+1;                                       % First hour of input data
            %time_0_tr = datetime(year,month_0,day_0_of_month_0,1,0,0);
            %time_1_tr = datetime(year,month_1,day_1_of_month_1,24,0,0);
            app.time_tr = app.time_0_tr:hours(1):app.time_1_tr;
            app.time_tr_d = app.time_0_tr:days(1):app.time_1_tr;
            app.CompleteLamp_2.Color = 'r';
        end

        % Value changed function: EnddateDatePicker
        function EnddateDatePickerValueChanged(app, event)
            app.time_1_tr = app.EnddateDatePicker.Value+hours(23);
            app.n_days_tr = day(app.time_1_tr,'dayofyear')-app.day_0_tr+1;            % Time period of training data (in days)
            app.time_tr = app.time_0_tr:hours(1):app.time_1_tr;
            app.time_tr_d = app.time_0_tr:days(1):app.time_1_tr;
            app.CompleteLamp_2.Color = 'r';
        end

        % Value changed function: StartdateDatePicker_2
        function StartdateDatePicker_2ValueChanged(app, event)
            app.time_0_test = app.StartdateDatePicker_2.Value;
            app.year_test = year(app.time_0_test);                                        % Year of input data (2019 or 'reference')
            app.month_0_test = month(app.time_0_test);
            app.day_0_test = day(app.time_0_test,'dayofyear');                            % First day of input data (1-365)
            app.n_days_test = day(app.time_1_test,'dayofyear')-app.day_0_test+1;            % Time period of training data (in days)
            app.h_0_test = (app.day_0_test-1)*24+1;                                       % First hour of input data
            %time_0_tr = datetime(year,month_0,day_0_of_month_0,1,0,0);
            %time_1_tr = datetime(year,month_1,day_1_of_month_1,24,0,0);
            app.time_test = app.time_0_test:hours(1):app.time_1_test;
            app.time_test_d = app.time_0_test:days(1):app.time_1_test;
            app.CompleteLamp_2.Color = 'r';
        end

        % Value changed function: EnddateDatePicker_2
        function EnddateDatePicker_2ValueChanged(app, event)
            app.time_1_test = app.EnddateDatePicker_2.Value+hours(23);
            app.n_days_test = day(app.time_1_test,'dayofyear')-app.day_0_test+1;            % Time period of training data (in days)
            app.time_test = app.time_0_test:hours(1):app.time_1_test;
            app.time_test_d = app.time_0_test:days(1):app.time_1_test;
            app.CompleteLamp_2.Color = 'r';
        end

        % Button pushed function: CreateretrofitmatrixButton
        function CreateretrofitmatrixButtonPushed(app, event)
            % [roof, wall, windows, ground floor]
            app.U_retrofit(1,:) = [0,0,0,0]; app.Gwin(1) = app.g_win;
            app.U_retrofit(2,:) = [app.Uroof,0,0,0]; app.Gwin(2) = app.g_win;
            app.U_retrofit(3,:) = [0,app.Uwall,0,0]; app.Gwin(3) = app.g_win;
            app.U_retrofit(4,:) = [0,0,app.Uwin,0]; app.Gwin(4) = app.Gval;
            app.U_retrofit(5,:) = [0,0,0,app.Ugf]; app.Gwin(5) = app.g_win;
            app.U_retrofit(6,:) = [app.Uroof,app.Uwall,0,0]; app.Gwin(6) = app.g_win;
            app.U_retrofit(7,:) = [app.Uroof,0,app.Uwin,0]; app.Gwin(7) = app.Gval;
            app.U_retrofit(8,:) = [app.Uroof,0,0,app.Ugf]; app.Gwin(8) = app.g_win;
            app.U_retrofit(9,:) = [0,app.Uwall,app.Uwin,0]; app.Gwin(9) = app.Gval;
            app.U_retrofit(10,:) = [0,app.Uwall,0,app.Ugf]; app.Gwin(10) = app.g_win;
            app.U_retrofit(11,:) = [0,0,app.Uwin,app.Ugf]; app.Gwin(11) = app.Gval;
            app.U_retrofit(12,:) = [app.Uroof,app.Uwall,app.Uwin,0]; app.Gwin(12) = app.Gval;
            app.U_retrofit(13,:) = [app.Uroof,app.Uwall,0,app.Ugf]; app.Gwin(13) = app.g_win;
            app.U_retrofit(14,:) = [app.Uroof,0,app.Uwin,app.Ugf]; app.Gwin(14) = app.Gval;
            app.U_retrofit(15,:) = [0,app.Uwall,app.Uwin,app.Ugf]; app.Gwin(15) = app.Gval;
            app.U_retrofit(16,:) = [app.Uroof,app.Uwall,app.Uwin,app.Ugf]; app.Gwin(16) = app.Gval;
            
            % Save variables for second app
            BE_lifetime = app.lifetime_BE;
            save('lifetime_BE.mat','BE_lifetime')
            MM_lifetime = app.lifetime_MM;
            save('lifetime_MM.mat','MM_lifetime')
            HS_lifetime = app.lifetime_HS;
            save('lifetime_HS.mat','HS_lifetime')
            
            BE_invest = zeros(5,1); MM_invest = zeros(5,1);
            for i=1:4
                BE_invest(i) = app.invest_single_BE(i)*app.areas(i);
                if i==4
                    MM_invest(i) = app.invest_single_MM(i)*app.areas(3);
                else
                    MM_invest(i) = app.invest_single_MM(i)*app.areas(2);
                end
            end
            save('invest_single_BE.mat','BE_invest')
            save('invest_single_MM.mat','MM_invest')
            
            HS_invest = app.invest_HS;
            save('invest_HS.mat','HS_invest')
            HS_om = app.OM_HS;
            save('OM_HS.mat','HS_om')
            
            BE_ghg = zeros(5,1); BE_penr = zeros(5,1);
            for i=1:4
                BE_ghg(i) = app.GHG_be(i)*app.areas(i);
                BE_penr(i) = app.PENR_be(i)*app.areas(i);
            end
            save('GHG_be.mat','BE_ghg')
            save('PENR_be.mat','BE_penr')
            
            HS_ghg = app.GHG_hs;
            save('GHG_hs.mat','HS_ghg')
            HS_penr = app.PENR_hs;
            save('PENR_hs.mat','HS_penr')
            PROBE_ghg = app.GHG_probe;
            save('GHG_probe.mat','PROBE_ghg')
            PROBE_penr = app.PENR_probe;
            save('PENR_probe.mat','PROBE_penr')
            
            app.CompleteLamp_5.Color = 'g';
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.Uroof = app.EditField.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.Uwall = app.EditField_2.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.Uwin = app.EditField_3.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.Ugf = app.EditField_4.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.Gval = app.EditField_5.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: ExternaltemperatureEditField
        function ExternaltemperatureEditFieldValueChanged(app, event)
            app.sim_input_file = app.ExternaltemperatureEditField.Value;
            app.CompleteLamp_4.Color = 'r';
        end

        % Value changed function: SolarirradianceEditField_2
        function SolarirradianceEditField_2ValueChanged(app, event)
            app.sim_solar_input = app.SolarirradianceEditField_2.Value;
            app.CompleteLamp_4.Color = 'r';
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            tic
            progress = waitbar(0,'Simulating');
            for rm=1:16
                % Parameter calculation (only R (i.e. H) parameters are affected by retrofit measures)
                app.sim_U = app.U;
                
                if app.U_retrofit(rm,4)>0
                    if app.sur_R==1
                        app.R_fl = 1/app.sim_U(4)-app.R_si_d-app.R_se+1/app.U_retrofit(rm,4);
                    else
                        app.R_fl = 1/app.sim_U(4)+1/app.U_retrofit(rm,4);
                    end
                else
                    if app.sur_R==1
                        app.R_fl = 1/app.sim_U(4)-app.R_si_d-app.R_se;
                    else
                        app.R_fl = 1/app.sim_U(4);
                    end
                end
                
                app.R_gf = app.R_fl+app.areas(4)/(app.areas(4)*app.U_cf+app.z_gr*app.per*app.U_cw+app.h_gr...          
                    *app.per*app.hc_c/app.d_cw+app.c_p_air*app.dens_air*0.3*app.areas(4)*app.h_floor);
                
                app.H_se_gf = app.areas(4)*1/(app.R_gf-app.R_si_d-app.R_cf-app.R_sl);
                
                if app.U_retrofit(rm,3)>0                  % Check whether windows were replaced
                    app.sim_U(3) = app.U_retrofit(rm,3);
                end
                if app.U_retrofit(rm,1)>0
                    app.sim_U(1) = 1/(1/app.sim_U(1)+1/app.U_retrofit(rm,1));
                end
                if app.U_retrofit(rm,2)>0
                    app.sim_U(2) = 1/(1/app.sim_U(2)+1/app.U_retrofit(rm,2));
                end
                if app.sur_R==1
                    app.H_roof = app.areas(1)/(1/app.sim_U(1)-app.R_si_u-app.R_se);
                    app.H_wall = app.areas(2)/(1/app.sim_U(2)-app.R_si_h-app.R_se);
                    app.H_win = app.areas(3)/(1/app.sim_U(3)-app.R_si_h-app.R_se);                    
                else
                    app.H_roof = app.areas(1)*app.sim_U(1);
                    app.H_wall = app.areas(2)*app.sim_U(2);
                    app.H_win = app.areas(3)*app.sim_U(3);
                end
                
                app.H1_gf = app.areas(4)/(app.R_gf/2+app.R_sl);
                app.H2_gf = 2*app.areas(4)/app.R_gf;
                
                app.H_tot_env = 1/(1/app.H_se_roof+1/app.H_roof+1/app.H_ci_roof)+1/(1/app.H_se_wall+1/app.H_wall+1/app.H_ci_wall);
                app.H_env = 1/(1/app.H_tot_env-1/app.H_se_env-1/app.H_ci_env);
                H_1_env = 2*app.H_env; H_2_env = H_1_env;
                H_1_gf = app.H1_gf; H_2_gf = app.H2_gf;
                
                % Calculation of building characteristics
                app.sim_H_tot(rm) = 1/(1/app.H_ci_env+1/H_1_env+1/H_2_env+1/app.H_se_env)+1/(1/app.H_ci_win+1/app.H_win+1/app.H_se_win)+app.H_par(2)+1/(1/app.H_ci_gf+1/H_1_gf+1/H_2_gf+1/app.H_se_gf)*(app.T_i_set-app.T_avg_sl)/(app.T_i_set-app.T_e_design);
                app.sim_hload_max(rm) = app.sim_H_tot(rm)*(app.T_i_set-app.T_e_design);
                
                % Read input from Reference year
                date_0 = '01-01-00';
                time_0_sim = datetime(date_0,'InputFormat','MM-dd-HH','Format','MMMM d, HH:mm');
                date_1 = '12-31-23';
                time_1_sim = datetime(date_1,'InputFormat','MM-dd-HH','Format','MMMM d, HH:mm');
                app.time_sim = time_0_sim:hours(1):time_1_sim;
                app.time_sim_day = time_0_sim:hours(24):time_1_sim;
                
                [I_tot_hor_sim, I_tot_ver_sim] = solar_calc(app.sim_solar_input,1,365,app.longitude,app.latitude,app.TZ,app.or_walls);     % Calculating total horizontal and vertical irradiance [W/m2]
                
                h_gr_sim = 1+24*(31+30+31+30+31);                           % Starting hour for calculation of ground temperature
                
                T_e_sim = xlsread(app.sim_input_file,1,'C2:C8761');
                
                P_re_roof_sim = zeros(8760,1);
                P_re_wall_sim = zeros(8760,1);
                P_re_sim = zeros(8760,1);
                P_sol_win_sim = zeros(8760,1);
                T_gr_sim = zeros(8760,1);
                T_i_sim = zeros(8760,1);
                time_of_day = 1;
                
                for i=1:8760
                    % Net radiant heat gains on exterior surfaces
                    P_re_roof_sim(i) = app.areas(1)*(app.alpha_roof*I_tot_hor_sim(i)-app.F_sky_hor*app.h_re*app.dT_sky);
                    I_tot_ver_w_sim = 1/app.areas(2)*(app.A_walls(1)*I_tot_ver_sim(i,1)+app.A_walls(2)*...
                        I_tot_ver_sim(i,2)+app.A_walls(3)*I_tot_ver_sim(i,3)+app.A_walls(4)*I_tot_ver_sim(i,4));
                    P_re_wall_sim(i) = app.areas(2)*(app.alpha_wall*I_tot_ver_w_sim-app.F_sky_ver*app.h_re*app.dT_sky);
                    P_re_sim(i) = P_re_roof_sim(i)+P_re_wall_sim(i);
                    % Solar heat gains through windows
                    I_tot_win_w_sim = 1/app.areas(3)*(app.A_win(1)*I_tot_ver_sim(i,1)+app.A_win(2)*...
                        I_tot_ver_sim(i,2)+app.A_win(3)*I_tot_ver_sim(i,3)+app.A_win(4)*I_tot_ver_sim(i,4));
                    P_sol_win_sim(i) = app.areas(3)*app.Gwin(rm)*I_tot_win_w_sim;
                    % Ground temperature (2m depth)
                    T_gr_sim(i) = app.T_avg_sl+app.dT_sl*(exp((-1)*app.z_gr*sqrt(pi/(app.hc_sl/app.c_v_sl*3600*8760)))...
                        *cos(2*pi/8760*h_gr_sim-app.z_gr*sqrt(pi/(app.hc_sl/app.c_v_sl*3600*8760))));
                    h_gr_sim = h_gr_sim+1;
                    % Indoor temperature
                    T_i_sim(i) = app.T_i_reference(time_of_day);
                    time_of_day = time_of_day+1;
                    if time_of_day == 25
                        time_of_day = 1;
                    end
                end
                
                % Preallocation and definition of initial values
                x0_sim = [8;10;22;T_i_sim(1)]; T_h_0 = 50;                          % Initial values of states (temperature nodes)
                hload_min = zeros(60,1); hload_min(1) = app.H_par(1)*(T_h_0-T_i_sim(1));
                T_i_hload_min = zeros(60,1); T_i_hload_min(1) = T_i_sim(1);
                T_h_hload_min = zeros(60,1); T_h_hload_min(1) = T_h_0;
                P_h_sim_min = zeros(60,1); P_h_sim_min(1) = app.sim_hload_max(rm)/2;
                app.sim_P_h_hour(1,rm) = app.sim_hload_max(rm)/2;
                
                %% Simulation of heating load
                [A1,B1,C1,D1] = par_to_SS_hload(app.H_se_env,app.H_se_win,app.H_se_gf,app.H_ci_env,app.H_ci_win,app.H_ci_gf,H_1_env,H_2_env,app.H_win,H_1_gf,H_2_gf,app.H_par(2),app.H_par(3),app.C_par(1),app.C_par(2),app.C_par(4),app.C_par(5));
                sys_hload_1 = ss(A1,B1,C1,D1);
                [A2,B2,C2,D2] = par_to_SS_hload_2(app.H_se_env,app.H_se_win,app.H_se_gf,app.H_ci_env,app.H_ci_win,app.H_ci_gf,H_1_env,H_2_env,app.H_win,H_1_gf,H_2_gf,app.H_par(2),app.H_par(3),app.C_par(1),app.C_par(2),app.C_par(4),app.C_par(5));
                sys_hload_2 = ss(A2,B2,C2,D2);
                
                for j=2:8760
                    for i=2:60
                        u_sim = [T_e_sim(j-1)+(i-1)/60*(T_e_sim(j)-T_e_sim(j-1)),P_re_sim(j-1)+(i-1)/60*(P_re_sim(j)-P_re_sim(j-1)),T_gr_sim(j-1)+(i-1)/60*(T_gr_sim(j)-T_gr_sim(j-1)),P_sol_win_sim(j-1)+(i-1)/60*(P_sol_win_sim(j)-P_sol_win_sim(j-1));T_e_sim(j-1)+i/60*(T_e_sim(j)-T_e_sim(j-1)),P_re_sim(j-1)+i/60*(P_re_sim(j)-P_re_sim(j-1)),T_gr_sim(j-1)+i/60*(T_gr_sim(j)-T_gr_sim(j-1)),P_sol_win_sim(j-1)+i/60*(P_sol_win_sim(j)-P_sol_win_sim(j-1))];
                        [T_i_low,~,~] = lsim(sys_hload_1,u_sim,[0;60],x0_sim,'foh');
                        hload_min(i) = app.C_par(5)*((T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1)))-T_i_low(2))/60;
                        
                        A_hload = [1+60*app.H_par(1)/app.C_par(3), (-1)*60/app.C_par(3); app.H_par(1), 0];
                        b_hload = [60*app.H_par(1)/app.C_par(3)*(T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1)))+T_h_hload_min(i-1); hload_min(i)+app.H_par(1)*(T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1)))];
                        X = linsolve(A_hload,b_hload);
                        
                        P_h_sim_min(i) = X(2);
                        T_h_hload_min(i) = X(1);
                        
                        if (T_e_sim(j-1)+(i-1)/60*(T_e_sim(j)-T_e_sim(j-1)))>app.heating_limit
                            P_h_sim_min(i) = 0;
                            T_h_hload_min(i) = (T_h_hload_min(i-1)+60*app.H_par(1)/app.C_par(3)*(T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1)))+60*P_h_sim_min(i)/app.C_par(3))/(1+60*app.H_par(1)/app.C_par(3));
                        elseif P_h_sim_min(i)<0
                            P_h_sim_min(i) = 0;
                            T_h_hload_min(i) = (T_h_hload_min(i-1)+60*app.H_par(1)/app.C_par(3)*(T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1)))+60*P_h_sim_min(i)/app.C_par(3))/(1+60*app.H_par(1)/app.C_par(3));
                        elseif P_h_sim_min(i)>app.sim_hload_max(rm)
                            P_h_sim_min(i) = app.sim_hload_max(rm);
                            T_h_hload_min(i) = (T_h_hload_min(i-1)+60*app.H_par(1)/app.C_par(3)*(T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1)))+60*P_h_sim_min(i)/app.C_par(3))/(1+60*app.H_par(1)/app.C_par(3));
                        else
                            T_h_hload_min(i) = (T_h_hload_min(i-1)+60*app.H_par(1)/app.C_par(3)*(T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1)))+60*P_h_sim_min(i)/app.C_par(3))/(1+60*app.H_par(1)/app.C_par(3));
                        end
                        
                        hload_min(i) = app.H_par(1)*(T_h_hload_min(i)-(T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1))));
                        
                        u_sim_2 = [T_e_sim(j-1)+(i-1)/60*(T_e_sim(j)-T_e_sim(j-1)),P_re_sim(j-1)+(i-1)/60*(P_re_sim(j)-P_re_sim(j-1)),T_gr_sim(j-1)+(i-1)/60*(T_gr_sim(j)-T_gr_sim(j-1)),P_sol_win_sim(j-1)+(i-1)/60*(P_sol_win_sim(j)-P_sol_win_sim(j-1)),hload_min(i-1);T_e_sim(j-1)+i/60*(T_e_sim(j)-T_e_sim(j-1)),P_re_sim(j-1)+i/60*(P_re_sim(j)-P_re_sim(j-1)),T_gr_sim(j-1)+i/60*(T_gr_sim(j)-T_gr_sim(j-1)),P_sol_win_sim(j-1)+i/60*(P_sol_win_sim(j)-P_sol_win_sim(j-1)),hload_min(i)];
                        [~,~,x_hload_2] = lsim(sys_hload_2,u_sim_2,[0;60],x0_sim,'foh');
                        x0_sim = [x_hload_2(2,1);x_hload_2(2,2);x_hload_2(2,3);T_i_sim(j-1)+i/60*(T_i_sim(j)-T_i_sim(j-1))];
                        T_i_hload_min(i) = x_hload_2(2,4);
                    end
                    app.sim_P_h_hour(j,rm) = sum(P_h_sim_min)/60;
                    hload_min(1) = hload_min(60);
                    P_h_sim_min(1) = P_h_sim_min(60);
                    T_h_hload_min(1) = T_h_hload_min(60);
                end
                
                % Daily heating demand [kWh]
                Q_h_day = zeros(365,1);
                for j=1:365
                    Q_h_day(j) = sum(app.sim_P_h_hour((j-1)*24+1:j*24,rm))/1000;
                end
                
                % Yearly heating demand [kWh]
                app.sim_Q_h_year(rm) = sum(Q_h_day);
                
                %% Simulation of (min.) hourly supply temperature
                for i=1:8760
                    if app.sim_P_h_hour(i,rm)==0
                        app.sim_T_supply_hour(i,rm) = 0;
                    elseif app.sim_P_h_hour(i,rm)<200
                        app.sim_T_supply_hour(i,rm) = 30;
                    else
                        app.sim_T_supply_hour(i,rm) = (exp(app.delta_T/(app.sim_P_h_hour(i,rm)/app.K_rad)^(1/app.n))*(app.delta_T+T_i_sim(i))-T_i_sim(i))/(exp(app.delta_T/(app.sim_P_h_hour(i,rm)/app.K_rad)^(1/app.n))-1);
                    end
                end
                
                % Average supply temperature
                [~,~,sim_T_supply_hour_nonzero] = find(app.sim_T_supply_hour(:,rm));
                app.sim_T_supply_average(rm) = mean(sim_T_supply_hour_nonzero);
            waitbar(rm/16,progress,'Simulating');
            end
            saved_sim_H_tot = app.sim_H_tot;
            save('sim_H_tot.mat','saved_sim_H_tot')
            saved_sim_hload_max = app.sim_hload_max;
            save('sim_hload_max.mat','saved_sim_hload_max')
            saved_sim_Q_h_year = app.sim_Q_h_year;
            save('sim_Q_h_year.mat','saved_sim_Q_h_year')
            saved_sim_P_h_hour = app.sim_P_h_hour;
            save('sim_P_h_hour.mat','saved_sim_P_h_hour')
            saved_sim_T_supply_average = app.sim_T_supply_average;
            save('sim_T_supply_average.mat','saved_sim_T_supply_average')
            saved_sim_T_supply_hour = app.sim_T_supply_hour;
            save('sim_T_supply_hour.mat','saved_sim_T_supply_hour')
            
            toc
            app.CompleteLamp_4.Color = 'g';
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.lifetime_BE(1) = app.EditField_6.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.lifetime_BE(2) = app.EditField_7.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.lifetime_BE(3) = app.EditField_8.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            app.lifetime_BE(4) = app.EditField_9.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            app.invest_single_BE(1) = app.EditField_10.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            app.invest_single_BE(2) = app.EditField_11.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            app.invest_single_BE(3) = app.EditField_12.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            app.invest_single_BE(4) = app.EditField_13.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_28
        function EditField_28ValueChanged(app, event)
            app.lifetime_MM(1) = app.EditField_28.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_29
        function EditField_29ValueChanged(app, event)
            app.lifetime_MM(2) = app.EditField_29.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_30
        function EditField_30ValueChanged(app, event)
            app.lifetime_MM(3) = app.EditField_30.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_31
        function EditField_31ValueChanged(app, event)
            app.lifetime_MM(4) = app.EditField_31.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_32
        function EditField_32ValueChanged(app, event)
            app.invest_single_MM(1) = app.EditField_32.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_33
        function EditField_33ValueChanged(app, event)
            app.invest_single_MM(2) = app.EditField_33.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_34
        function EditField_34ValueChanged(app, event)
            app.invest_single_MM(3) = app.EditField_34.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_35
        function EditField_35ValueChanged(app, event)
            app.invest_single_MM(4) = app.EditField_35.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            app.lifetime_HS(1) = app.EditField_14.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            app.lifetime_HS(2) = app.EditField_15.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            app.lifetime_HS(3) = app.EditField_16.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_17
        function EditField_17ValueChanged(app, event)
            app.lifetime_HS(4) = app.EditField_17.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_27
        function EditField_27ValueChanged(app, event)
            app.lifetime_HS(5) = app.EditField_27.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFEditField
        function CHFEditFieldValueChanged(app, event)
            app.invest_HS(1,1) = app.CHFEditField.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFEditField_2
        function CHFEditField_2ValueChanged(app, event)
            app.invest_HS(2,1) = app.CHFEditField_2.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFEditField_3
        function CHFEditField_3ValueChanged(app, event)
            app.invest_HS(3,1) = app.CHFEditField_3.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFEditField_4
        function CHFEditField_4ValueChanged(app, event)
            app.invest_HS(4,1) = app.CHFEditField_4.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFEditField_5
        function CHFEditField_5ValueChanged(app, event)
            app.invest_HS(5,1) = app.CHFEditField_5.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFkWEditField
        function CHFkWEditFieldValueChanged(app, event)
            app.invest_HS(1,2) = app.CHFkWEditField.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFkWEditField_2
        function CHFkWEditField_2ValueChanged(app, event)
            app.invest_HS(2,2) = app.CHFkWEditField_2.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFkWEditField_3
        function CHFkWEditField_3ValueChanged(app, event)
            app.invest_HS(3,2) = app.CHFkWEditField_3.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFkWEditField_4
        function CHFkWEditField_4ValueChanged(app, event)
            app.invest_HS(4,2) = app.CHFkWEditField_4.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFkWEditField_5
        function CHFkWEditField_5ValueChanged(app, event)
            app.invest_HS(5,2) = app.CHFkWEditField_5.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFaEditField
        function CHFaEditFieldValueChanged(app, event)
            app.OM_HS(1,1) = app.CHFaEditField.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFaEditField_2
        function CHFaEditField_2ValueChanged(app, event)
            app.OM_HS(2,1) = app.CHFaEditField_2.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFaEditField_3
        function CHFaEditField_3ValueChanged(app, event)
            app.OM_HS(3,1) = app.CHFaEditField_3.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFaEditField_4
        function CHFaEditField_4ValueChanged(app, event)
            app.OM_HS(4,1) = app.CHFaEditField_4.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFaEditField_5
        function CHFaEditField_5ValueChanged(app, event)
            app.OM_HS(5,1) = app.CHFaEditField_5.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFakWEditField
        function CHFakWEditFieldValueChanged(app, event)
            app.OM_HS(1,2) = app.CHFakWEditField.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFakWEditField_2
        function CHFakWEditField_2ValueChanged(app, event)
            app.OM_HS(2,2) = app.CHFakWEditField_2.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFakWEditField_3
        function CHFakWEditField_3ValueChanged(app, event)
            app.OM_HS(3,2) = app.CHFakWEditField_3.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFakWEditField_4
        function CHFakWEditField_4ValueChanged(app, event)
            app.OM_HS(4,2) = app.CHFakWEditField_4.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: CHFakWEditField_5
        function CHFakWEditField_5ValueChanged(app, event)
            app.OM_HS(5,2) = app.CHFakWEditField_5.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_36
        function EditField_36ValueChanged(app, event)
            app.GHG_be(1) = app.EditField_36.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_37
        function EditField_37ValueChanged(app, event)
            app.GHG_be(2) = app.EditField_37.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_38
        function EditField_38ValueChanged(app, event)
            app.GHG_be(3) = app.EditField_38.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_39
        function EditField_39ValueChanged(app, event)
            app.GHG_be(4) = app.EditField_39.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_45
        function EditField_45ValueChanged(app, event)
            app.PENR_be(1) = app.EditField_45.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_46
        function EditField_46ValueChanged(app, event)
            app.PENR_be(2) = app.EditField_46.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_47
        function EditField_47ValueChanged(app, event)
            app.PENR_be(3) = app.EditField_47.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_48
        function EditField_48ValueChanged(app, event)
            app.PENR_be(4) = app.EditField_48.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_49
        function EditField_49ValueChanged(app, event)
            app.GHG_hs(1) = app.EditField_49.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_50
        function EditField_50ValueChanged(app, event)
            app.GHG_hs(2) = app.EditField_50.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_51
        function EditField_51ValueChanged(app, event)
            app.GHG_hs(3) = app.EditField_51.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_52
        function EditField_52ValueChanged(app, event)
            app.GHG_hs(4) = app.EditField_52.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_57
        function EditField_57ValueChanged(app, event)
            app.GHG_hs(5) = app.EditField_57.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_53
        function EditField_53ValueChanged(app, event)
            app.PENR_hs(1) = app.EditField_53.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_54
        function EditField_54ValueChanged(app, event)
            app.PENR_hs(2) = app.EditField_54.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_55
        function EditField_55ValueChanged(app, event)
            app.PENR_hs(3) = app.EditField_55.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_56
        function EditField_56ValueChanged(app, event)
            app.PENR_hs(4) = app.EditField_56.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_58
        function EditField_58ValueChanged(app, event)
            app.PENR_hs(5) = app.EditField_58.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_60
        function EditField_60ValueChanged(app, event)
            app.GHG_probe = app.EditField_60.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: EditField_64
        function EditField_64ValueChanged(app, event)
            app.PENR_probe = app.EditField_64.Value;
            app.CompleteLamp_5.Color = 'r';
        end

        % Value changed function: TemperatureofDHWCEditField
        function TemperatureofDHWCEditFieldValueChanged(app, event)
            app.T_dhw = app.TemperatureofDHWCEditField.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: DHWstorageEditField
        function DHWstorageEditFieldValueChanged(app, event)
            app.eff_dhw = app.DHWstorageEditField.Value;
            app.CompleteLamp_6.Color = 'r';
        end

        % Value changed function: OilboilerEditField
        function OilboilerEditFieldValueChanged(app, event)
            app.eff_oil = app.OilboilerEditField.Value;
            app.CompleteLamp_6.Color = 'r';
        end

        % Value changed function: 
        % DistrictheatingheattransferstationEditField
        function DistrictheatingheattransferstationEditFieldValueChanged(app, event)
            app.eff_dh = app.DistrictheatingheattransferstationEditField.Value;
            app.CompleteLamp_6.Color = 'r';
        end

        % Value changed function: PelletboilerEditField
        function PelletboilerEditFieldValueChanged(app, event)
            app.eff_pellet = app.PelletboilerEditField.Value;
            app.CompleteLamp_6.Color = 'r';
        end

        % Button pushed function: StartsimulationButton_2
        function StartsimulationButton_2Pushed(app, event)
            esim_H_tot = load('sim_H_tot.mat');
            esim_H_tot = esim_H_tot.saved_sim_H_tot;
            esim_hload_max = load('sim_hload_max.mat');
            esim_hload_max = esim_hload_max.saved_sim_hload_max;
            esim_P_h_hour = load('sim_P_h_hour.mat');
            esim_P_h_hour = esim_P_h_hour.saved_sim_P_h_hour;
            esim_Q_h_year = load('sim_Q_h_year.mat');
            esim_Q_h_year = esim_Q_h_year.saved_sim_Q_h_year;
            esim_T_supply_average = load('sim_T_supply_average.mat');
            esim_T_supply_average = esim_T_supply_average.saved_sim_T_supply_average;
            esim_T_supply_hour = load('sim_T_supply_hour.mat');
            esim_T_supply_hour = esim_T_supply_hour.saved_sim_T_supply_hour;
            
            E_oil = zeros(16,1);
            E_hp_geo = zeros(16,1);
            E_hp_air = zeros(16,1);
            E_dh = zeros(16,1);
            E_pellet = zeros(16,1);
                        
            for i=1:16
                E_oil(i) = (esim_Q_h_year(i)+app.Q_dhw/app.eff_dhw)/app.eff_oil;
                E_dh(i) = (esim_Q_h_year(i)+app.Q_dhw/app.eff_dhw)/app.eff_dh;
                E_pellet(i) = (esim_Q_h_year(i)+app.Q_dhw/app.eff_dhw)/app.eff_pellet;
                [E_hp_air(i),~,~] = HP_air_sim(esim_T_supply_hour(:,i),esim_P_h_hour(:,i),app.Q_dhw,app.T_dhw);
                if max(esim_T_supply_hour(:,i))>app.T_supply_limit_air
                    app.feasibility_air(i) = 0;
                else
                    app.feasibility_air(i) = 1;
                end
                [E_hp_geo(i),~,~,app.length_probe(i)] = HP_geothermal_sim(esim_hload_max(i),esim_T_supply_average(i),esim_T_supply_hour(:,i),esim_P_h_hour(:,i),esim_Q_h_year(i),app.Q_dhw,app.T_dhw);
                if max(esim_T_supply_hour(:,i))>app.T_supply_limit_geo
                    app.feasibility_geo(i) = 0;
                else
                    app.feasibility_geo(i) = 1;
                end
            end
            
            app.E_final = [E_oil,E_hp_geo,E_hp_air,E_dh,E_pellet];
            
            % Save variables for second app
            final_E = app.E_final;
            save('E_final.mat','final_E')
            air_feasibility = app.feasibility_air;
            save('feasibility_air.mat','air_feasibility')
            geo_feasibility = app.feasibility_geo;
            save('feasibility_geo.mat','geo_feasibility')
            probe_length = app.length_probe;
            save('length_probe.mat','probe_length')
            
            app.CompleteLamp_6.Color = 'g';
        end

        % Value changed function: 
        % MaxsupplytemperaturewithgeothermalheatpumpCEditField
        function MaxsupplytemperaturewithgeothermalheatpumpCEditFieldValueChanged(app, event)
            app.T_supply_limit_geo = app.MaxsupplytemperaturewithgeothermalheatpumpCEditField.Value;
            app.CompleteLamp_6.Color = 'r';
        end

        % Value changed function: 
        % MaxsupplytemperaturewithairtowaterheatpumpCEditField
        function MaxsupplytemperaturewithairtowaterheatpumpCEditFieldValueChanged(app, event)
            app.T_supply_limit_air = app.MaxsupplytemperaturewithairtowaterheatpumpCEditField.Value;
            app.CompleteLamp_6.Color = 'r';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.RetrofitApp1Label = uilabel(app.Tab);
            app.RetrofitApp1Label.Text = 'Retrofit App 1';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = {'- Light-grey-box model (calibration & validation)'; '- Building energy simulations'; '- Heating system simulations'; '- Catalogue of retrofit measures'};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.TabGroup2 = uitabgroup(app.SettingsTab);
            app.BuildingTab = uitab(app.TabGroup2);
            app.BuildingTab.Title = 'Building';
            app.GeneralinformationPanel = uipanel(app.BuildingTab);
            app.GeneralinformationPanel.Title = 'General information';
            app.TimezoneUTCEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.TimezoneUTCEditFieldLabel.Text = 'Time zone (UTC):';
            app.TimezoneUTCEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.TimezoneUTCEditField.ValueChangedFcn = createCallbackFcn(app, @TimezoneUTCEditFieldValueChanged, true);
            app.TimezoneUTCEditField.Value = 1;
            app.LongitudeEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.LongitudeEditFieldLabel.Text = 'Longitude [°]:';
            app.LongitudeEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.LongitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LongitudeEditFieldValueChanged, true);
            app.LongitudeEditField.Value = 9.4;
            app.LatitudeEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude [°]:';
            app.LatitudeEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.LatitudeEditField.ValueChangedFcn = createCallbackFcn(app, @LatitudeEditFieldValueChanged, true);
            app.LatitudeEditField.Value = 47.43;
            app.NumberofoccupantsEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.NumberofoccupantsEditFieldLabel.Text = 'Number of occupants:';
            app.NumberofoccupantsEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.NumberofoccupantsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofoccupantsEditFieldValueChanged, true);
            app.NumberofoccupantsEditField.Value = 4;
            app.NumberofheatedfloorsEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.NumberofheatedfloorsEditFieldLabel.Text = 'Number of heated floors:';
            app.NumberofheatedfloorsEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.NumberofheatedfloorsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofheatedfloorsEditFieldValueChanged, true);
            app.NumberofheatedfloorsEditField.Value = 3;
            app.FloorheightmEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.FloorheightmEditFieldLabel.Text = 'Floor height [m]:';
            app.FloorheightmEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.FloorheightmEditField.ValueChangedFcn = createCallbackFcn(app, @FloorheightmEditFieldValueChanged, true);
            app.FloorheightmEditField.Value = 2.48;
            app.UndergrounddepthofcellarfloormEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.UndergrounddepthofcellarfloormEditFieldLabel.Text = 'Underground depth of cellar floor [m]:';
            app.UndergrounddepthofcellarfloormEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.UndergrounddepthofcellarfloormEditField.ValueChangedFcn = createCallbackFcn(app, @UndergrounddepthofcellarfloormEditFieldValueChanged, true);
            app.UndergrounddepthofcellarfloormEditField.Value = 1.1;
            app.OvergroundheightofcellarfloormEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.OvergroundheightofcellarfloormEditFieldLabel.Text = 'Overground height of cellar floor [m]:';
            app.OvergroundheightofcellarfloormEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.OvergroundheightofcellarfloormEditField.ValueChangedFcn = createCallbackFcn(app, @OvergroundheightofcellarfloormEditFieldValueChanged, true);
            app.OvergroundheightofcellarfloormEditField.Value = 1.6;
            app.EnergyreferenceareaERAm2EditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.EnergyreferenceareaERAm2EditFieldLabel.Text = 'Energy reference area (ERA) [m2]:';
            app.EnergyreferenceareaERAm2EditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.EnergyreferenceareaERAm2EditField.ValueChangedFcn = createCallbackFcn(app, @EnergyreferenceareaERAm2EditFieldValueChanged, true);
            app.EnergyreferenceareaERAm2EditField.Value = 242.3;
            app.BuildingperimetermEditFieldLabel = uilabel(app.GeneralinformationPanel);
            app.BuildingperimetermEditFieldLabel.Text = 'Building perimeter [m]:';
            app.BuildingperimetermEditField = uieditfield(app.GeneralinformationPanel, 'numeric');
            app.BuildingperimetermEditField.ValueChangedFcn = createCallbackFcn(app, @BuildingperimetermEditFieldValueChanged, true);
            app.BuildingperimetermEditField.Value = 30;
            app.BuildingclassButtonGroup = uibuttongroup(app.BuildingTab);
            app.BuildingclassButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BuildingclassButtonGroupSelectionChanged, true);
            app.BuildingclassButtonGroup.Title = 'Building class';
            app.VerylightButton = uiradiobutton(app.BuildingclassButtonGroup);
            app.VerylightButton.Text = 'Very light';
            app.LightButton = uiradiobutton(app.BuildingclassButtonGroup);
            app.LightButton.Text = 'Light';
            app.MediumButton = uiradiobutton(app.BuildingclassButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.MediumButton.Value = true;
            app.HeavyButton = uiradiobutton(app.BuildingclassButtonGroup);
            app.HeavyButton.Text = 'Heavy';
            app.VeryheavyButton = uiradiobutton(app.BuildingclassButtonGroup);
            app.VeryheavyButton.Text = 'Very heavy';
            app.Buildingenvelopesurfacesm2Panel = uipanel(app.BuildingTab);
            app.Buildingenvelopesurfacesm2Panel.Title = 'Building envelope surfaces [m2]';
            app.RoofEditField_2Label = uilabel(app.Buildingenvelopesurfacesm2Panel);
            app.RoofEditField_2Label.Text = 'Roof:';
            app.RoofEditField_2 = uieditfield(app.Buildingenvelopesurfacesm2Panel, 'numeric');
            app.RoofEditField_2.ValueChangedFcn = createCallbackFcn(app, @RoofEditField_2ValueChanged, true);
            app.RoofEditField_2.Value = 148.5;
            app.ExternalwallsEditField_2Label = uilabel(app.Buildingenvelopesurfacesm2Panel);
            app.ExternalwallsEditField_2Label.Text = 'External walls:';
            app.ExternalwallsEditField_2 = uieditfield(app.Buildingenvelopesurfacesm2Panel, 'numeric');
            app.ExternalwallsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ExternalwallsEditField_2ValueChanged, true);
            app.ExternalwallsEditField_2.Value = 234.73;
            app.WindowsEditField_2Label = uilabel(app.Buildingenvelopesurfacesm2Panel);
            app.WindowsEditField_2Label.Text = 'Windows:';
            app.WindowsEditField_2 = uieditfield(app.Buildingenvelopesurfacesm2Panel, 'numeric');
            app.WindowsEditField_2.ValueChangedFcn = createCallbackFcn(app, @WindowsEditField_2ValueChanged, true);
            app.WindowsEditField_2.Value = 71.7;
            app.GroundfloorEditField_2Label = uilabel(app.Buildingenvelopesurfacesm2Panel);
            app.GroundfloorEditField_2Label.Text = 'Ground floor:';
            app.GroundfloorEditField_2 = uieditfield(app.Buildingenvelopesurfacesm2Panel, 'numeric');
            app.GroundfloorEditField_2.ValueChangedFcn = createCallbackFcn(app, @GroundfloorEditField_2ValueChanged, true);
            app.GroundfloorEditField_2.Value = 126;
            app.UValuesWm2KPanel = uipanel(app.BuildingTab);
            app.UValuesWm2KPanel.Title = 'U-Values [W/(m2*K)]';
            app.RoofEditFieldLabel = uilabel(app.UValuesWm2KPanel);
            app.RoofEditFieldLabel.Text = 'Roof:';
            app.RoofEditField = uieditfield(app.UValuesWm2KPanel, 'numeric');
            app.RoofEditField.ValueChangedFcn = createCallbackFcn(app, @RoofEditFieldValueChanged, true);
            app.RoofEditField.Value = 0.4941;
            app.ExternalwallsEditFieldLabel = uilabel(app.UValuesWm2KPanel);
            app.ExternalwallsEditFieldLabel.Text = 'External walls:';
            app.ExternalwallsEditField = uieditfield(app.UValuesWm2KPanel, 'numeric');
            app.ExternalwallsEditField.ValueChangedFcn = createCallbackFcn(app, @ExternalwallsEditFieldValueChanged, true);
            app.ExternalwallsEditField.Value = 0.4623;
            app.WindowsEditFieldLabel = uilabel(app.UValuesWm2KPanel);
            app.WindowsEditFieldLabel.Text = 'Windows:';
            app.WindowsEditField = uieditfield(app.UValuesWm2KPanel, 'numeric');
            app.WindowsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsEditFieldValueChanged, true);
            app.WindowsEditField.Value = 2.73;
            app.GroundfloorEditFieldLabel = uilabel(app.UValuesWm2KPanel);
            app.GroundfloorEditFieldLabel.Text = 'Ground floor:';
            app.GroundfloorEditField = uieditfield(app.UValuesWm2KPanel, 'numeric');
            app.GroundfloorEditField.ValueChangedFcn = createCallbackFcn(app, @GroundfloorEditFieldValueChanged, true);
            app.GroundfloorEditField.Value = 0.9028;
            app.SurfaceresistancesincludedCheckBox = uicheckbox(app.UValuesWm2KPanel);
            app.SurfaceresistancesincludedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SurfaceresistancesincludedCheckBoxValueChanged, true);
            app.SurfaceresistancesincludedCheckBox.Text = 'Surface resistances included';
            app.SurfaceresistancesincludedCheckBox.Value = true;
            app.TotalenergytransmittanceofwindowsPanel = uipanel(app.BuildingTab);
            app.TotalenergytransmittanceofwindowsPanel.Title = 'Total energy transmittance of windows';
            app.GValueEditFieldLabel = uilabel(app.TotalenergytransmittanceofwindowsPanel);
            app.GValueEditFieldLabel.Text = 'G-Value [-]:';
            app.GValueEditField = uieditfield(app.TotalenergytransmittanceofwindowsPanel, 'numeric');
            app.GValueEditField.ValueChangedFcn = createCallbackFcn(app, @GValueEditFieldValueChanged, true);
            app.GValueEditField.Value = 0.73;
            app.OrientationofexternalwallsandwindowsPanel = uipanel(app.BuildingTab);
            app.OrientationofexternalwallsandwindowsPanel.Title = 'Orientation of external walls and windows';
            app.SouthEditFieldLabel = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.SouthEditFieldLabel.Text = 'South';
            app.SouthEditField = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.SouthEditField.ValueChangedFcn = createCallbackFcn(app, @SouthEditFieldValueChanged, true);
            app.EastEditFieldLabel = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.EastEditFieldLabel.Text = 'East';
            app.EastEditField = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.EastEditField.ValueChangedFcn = createCallbackFcn(app, @EastEditFieldValueChanged, true);
            app.EastEditField.Value = 90;
            app.NorthEditFieldLabel = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.NorthEditFieldLabel.Text = 'North';
            app.NorthEditField = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.NorthEditField.ValueChangedFcn = createCallbackFcn(app, @NorthEditFieldValueChanged, true);
            app.NorthEditField.Value = 180;
            app.WestEditFieldLabel = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.WestEditFieldLabel.Text = 'West';
            app.WestEditField = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.WestEditField.ValueChangedFcn = createCallbackFcn(app, @WestEditFieldValueChanged, true);
            app.WestEditField.Value = -90;
            app.AzimuthangleLabel = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.AzimuthangleLabel.Text = 'Azimuth angle [°]';
            app.SouthEditField_2 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.SouthEditField_2.ValueChangedFcn = createCallbackFcn(app, @SouthEditField_2ValueChanged, true);
            app.SouthEditField_2.Value = 74.33;
            app.EastEditField_2 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.EastEditField_2.ValueChangedFcn = createCallbackFcn(app, @EastEditField_2ValueChanged, true);
            app.EastEditField_2.Value = 33.84;
            app.NorthEditField_2 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.NorthEditField_2.ValueChangedFcn = createCallbackFcn(app, @NorthEditField_2ValueChanged, true);
            app.NorthEditField_2.Value = 79.56;
            app.WestEditField_2 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.WestEditField_2.ValueChangedFcn = createCallbackFcn(app, @WestEditField_2ValueChanged, true);
            app.WestEditField_2.Value = 47;
            app.Externalwallsm2Label = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.Externalwallsm2Label.Text = 'External walls [m2]';
            app.SouthEditField_3 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.SouthEditField_3.ValueChangedFcn = createCallbackFcn(app, @SouthEditField_3ValueChanged, true);
            app.SouthEditField_3.Value = 25.82;
            app.EastEditField_3 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.EastEditField_3.ValueChangedFcn = createCallbackFcn(app, @EastEditField_3ValueChanged, true);
            app.EastEditField_3.Value = 18.1;
            app.NorthEditField_3 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.NorthEditField_3.ValueChangedFcn = createCallbackFcn(app, @NorthEditField_3ValueChanged, true);
            app.NorthEditField_3.Value = 20.08;
            app.WestEditField_3 = uieditfield(app.OrientationofexternalwallsandwindowsPanel, 'numeric');
            app.WestEditField_3.ValueChangedFcn = createCallbackFcn(app, @WestEditField_3ValueChanged, true);
            app.WestEditField_3.Value = 7.7;
            app.Windowsm2Label = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.Windowsm2Label.Text = 'Windows [m2]';
            app.AnnotationtoazimuthangleTextAreaLabel = uilabel(app.OrientationofexternalwallsandwindowsPanel);
            app.AnnotationtoazimuthangleTextAreaLabel.Text = 'Annotation to azimuth angle';
            app.AnnotationtoazimuthangleTextArea = uitextarea(app.OrientationofexternalwallsandwindowsPanel);
            app.AnnotationtoazimuthangleTextArea.Value = {'South = 0°'; 'East = 90°'; 'North = 180° / -180°'; 'West = -90°'};
            app.CompleteLampLabel = uilabel(app.BuildingTab);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.BuildingTab);
            app.ImportbuildingdataButton = uibutton(app.BuildingTab, 'push');
            app.ImportbuildingdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportbuildingdataButtonPushed, true);
            app.ImportbuildingdataButton.Text = 'Import building data';
            app.HeatingsystemTab = uitab(app.TabGroup2);
            app.HeatingsystemTab.Title = 'Heating system';
            app.RoomsetpointtemperatureCEditFieldLabel = uilabel(app.HeatingsystemTab);
            app.RoomsetpointtemperatureCEditFieldLabel.Text = 'Room setpoint temperature [°C]:';
            app.RoomsetpointtemperatureCEditField = uieditfield(app.HeatingsystemTab, 'numeric');
            app.RoomsetpointtemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @RoomsetpointtemperatureCEditFieldValueChanged, true);
            app.RoomsetpointtemperatureCEditField.Value = 20;
            app.OutdoordesigntemperatureCEditFieldLabel = uilabel(app.HeatingsystemTab);
            app.OutdoordesigntemperatureCEditFieldLabel.Text = 'Outdoor design temperature [°C]:';
            app.OutdoordesigntemperatureCEditField = uieditfield(app.HeatingsystemTab, 'numeric');
            app.OutdoordesigntemperatureCEditField.ValueChangedFcn = createCallbackFcn(app, @OutdoordesigntemperatureCEditFieldValueChanged, true);
            app.OutdoordesigntemperatureCEditField.Value = -9;
            app.HeatinglimitCEditFieldLabel = uilabel(app.HeatingsystemTab);
            app.HeatinglimitCEditFieldLabel.Text = 'Heating limit [°C]:';
            app.HeatinglimitCEditField = uieditfield(app.HeatingsystemTab, 'numeric');
            app.HeatinglimitCEditField.ValueChangedFcn = createCallbackFcn(app, @HeatinglimitCEditFieldValueChanged, true);
            app.HeatinglimitCEditField.Value = 15;
            app.correspondstotheoutsidetemperatureonthedesigndayLabel = uilabel(app.HeatingsystemTab);
            app.correspondstotheoutsidetemperatureonthedesigndayLabel.Text = '(corresponds to the outdoor temperature on the design day, i.e. the coldest day the heating system must be dimensioned for)';
            app.Label = uilabel(app.HeatingsystemTab);
            app.Label.Text = '(the heating system is only switched on when the outdoor temperature falls below the heating limit)';
            app.TypeofheatdissipationDropDownLabel = uilabel(app.HeatingsystemTab);
            app.TypeofheatdissipationDropDownLabel.Text = 'Type of heat dissipation:';
            app.TypeofheatdissipationDropDown = uidropdown(app.HeatingsystemTab);
            app.TypeofheatdissipationDropDown.Items = {'Floor heating', 'Floor heating and radiators', 'Radiators', 'Convectors'};
            app.TypeofheatdissipationDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeofheatdissipationDropDownValueChanged, true);
            app.TypeofheatdissipationDropDown.Value = 'Radiators';
            app.TemperaturespreadCEditFieldLabel = uilabel(app.HeatingsystemTab);
            app.TemperaturespreadCEditFieldLabel.Text = 'Temperature spread [°C]:';
            app.TemperaturespreadCEditField = uieditfield(app.HeatingsystemTab, 'numeric');
            app.TemperaturespreadCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperaturespreadCEditFieldValueChanged, true);
            app.TemperaturespreadCEditField.Value = 8;
            app.betweensupplyandreturntemperatureoffutureheatingsystemLabel = uilabel(app.HeatingsystemTab);
            app.betweensupplyandreturntemperatureoffutureheatingsystemLabel.Text = '(between supply and return temperature of future heating system)';
            app.TemperatureofDHWCEditFieldLabel = uilabel(app.HeatingsystemTab);
            app.TemperatureofDHWCEditFieldLabel.Text = 'Temperature of DHW [°C]:';
            app.TemperatureofDHWCEditField = uieditfield(app.HeatingsystemTab, 'numeric');
            app.TemperatureofDHWCEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureofDHWCEditFieldValueChanged, true);
            app.TemperatureofDHWCEditField.Value = 50;
            app.DatainputTab = uitab(app.TabGroup);
            app.DatainputTab.Title = 'Data input';
            app.TrainingperiodPanel = uipanel(app.DatainputTab);
            app.TrainingperiodPanel.Title = 'Training period';
            app.StartdateLabel = uilabel(app.TrainingperiodPanel);
            app.StartdateLabel.Text = 'Start date:';
            app.StartdateDatePicker = uidatepicker(app.TrainingperiodPanel);
            app.StartdateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePickerValueChanged, true);
            app.StartdateDatePicker.Value = datetime([2019 2 2]);
            app.EnddateDatePickerLabel = uilabel(app.TrainingperiodPanel);
            app.EnddateDatePickerLabel.Text = 'End date:';
            app.EnddateDatePicker = uidatepicker(app.TrainingperiodPanel);
            app.EnddateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePickerValueChanged, true);
            app.EnddateDatePicker.Value = datetime([2019 2 22]);
            app.TestperiodPanel = uipanel(app.DatainputTab);
            app.TestperiodPanel.Title = 'Test period';
            app.StartdateDatePicker_2Label = uilabel(app.TestperiodPanel);
            app.StartdateDatePicker_2Label.Text = 'Start date:';
            app.StartdateDatePicker_2 = uidatepicker(app.TestperiodPanel);
            app.StartdateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @StartdateDatePicker_2ValueChanged, true);
            app.StartdateDatePicker_2.Value = datetime([2019 2 23]);
            app.EnddateDatePicker_2Label = uilabel(app.TestperiodPanel);
            app.EnddateDatePicker_2Label.Text = 'End date:';
            app.EnddateDatePicker_2 = uidatepicker(app.TestperiodPanel);
            app.EnddateDatePicker_2.ValueChangedFcn = createCallbackFcn(app, @EnddateDatePicker_2ValueChanged, true);
            app.EnddateDatePicker_2.Value = datetime([2019 3 15]);
            app.InputfilesxlsxPanel = uipanel(app.DatainputTab);
            app.InputfilesxlsxPanel.Title = 'Input files [.xlsx]';
            app.SolarirradianceEditFieldLabel = uilabel(app.InputfilesxlsxPanel);
            app.SolarirradianceEditFieldLabel.Text = 'Solar irradiance:';
            app.SolarirradianceEditField = uieditfield(app.InputfilesxlsxPanel, 'text');
            app.SolarirradianceEditField.ValueChangedFcn = createCallbackFcn(app, @SolarirradianceEditFieldValueChanged, true);
            app.SolarirradianceEditField.Value = 'solar_irradiance_STG.xlsx';
            app.WSNmeasurementdataEditFieldLabel = uilabel(app.InputfilesxlsxPanel);
            app.WSNmeasurementdataEditFieldLabel.Text = 'WSN measurement data:';
            app.WSNmeasurementdataEditField = uieditfield(app.InputfilesxlsxPanel, 'text');
            app.WSNmeasurementdataEditField.ValueChangedFcn = createCallbackFcn(app, @WSNmeasurementdataEditFieldValueChanged, true);
            app.WSNmeasurementdataEditField.Value = 'input_data.xlsx';
            app.UITable = uitable(app.InputfilesxlsxPanel);
            app.UITable.ColumnName = {'Day'; 'Hour'; 'T_i'; 'T_e'; 'Flow'; 'T_return'; 'T_supply'};
            app.UITable.RowName = {};
            app.FormatLabel = uilabel(app.InputfilesxlsxPanel);
            app.FormatLabel.Text = 'Format:';
            app.VariablesTextAreaLabel = uilabel(app.InputfilesxlsxPanel);
            app.VariablesTextAreaLabel.Text = 'Variables:';
            app.VariablesTextArea = uitextarea(app.InputfilesxlsxPanel);
            app.VariablesTextArea.Value = {'- Day: Day of the year [1;...;365]'; '- Hour: Hour of the day [1;...;24]'; '- T_i: Hourly indoor temperature [°C]'; '- T_e: Hourly outdoor temperature [°C]'; '- Flow: Hourly heating water flow right after heating system [l/s]'; '- T_return: Hourly return temperature of heating water [°C|'; '- T_supply: Hourly supply temperature of heating water [°C]'};
            app.UITable_2 = uitable(app.InputfilesxlsxPanel);
            app.UITable_2.ColumnName = {'Day'; 'Hour'; 'BNI'; 'DHI'};
            app.UITable_2.RowName = {};
            app.FormatLabel_2 = uilabel(app.InputfilesxlsxPanel);
            app.FormatLabel_2.Text = 'Format:';
            app.VariablesTextArea_2Label = uilabel(app.InputfilesxlsxPanel);
            app.VariablesTextArea_2Label.Text = 'Variables:';
            app.VariablesTextArea_2 = uitextarea(app.InputfilesxlsxPanel);
            app.VariablesTextArea_2.Value = {'- Day: Day of the year [1;...;365]'; '- Hour: Hour of the day [1;...;24]'; '- BNI: Hourly beam normal irradiation (=DNI) [Wh/m2]'; '- DHI: Hourly diffuse horizontal irradiation [Wh/m2] '};
            app.CalibratelightgreyboxmodelButton = uibutton(app.DatainputTab, 'push');
            app.CalibratelightgreyboxmodelButton.ButtonPushedFcn = createCallbackFcn(app, @CalibratelightgreyboxmodelButtonPushed, true);
            app.CalibratelightgreyboxmodelButton.Text = 'Calibrate light-grey-box model';
            app.CompleteLamp_2Label = uilabel(app.DatainputTab);
            app.CompleteLamp_2Label.Text = 'Complete';
            app.CompleteLamp_2 = uilamp(app.DatainputTab);
            app.RCmodelTab = uitab(app.TabGroup);
            app.RCmodelTab.Title = 'RC model';
            app.UIAxes4 = uiaxes(app.RCmodelTab);
            app.UIAxes4.XTick = [];
            app.UIAxes4.XTickLabel = '';
            app.UIAxes4.YTick = [];
            app.ModelvalidationTab = uitab(app.TabGroup);
            app.ModelvalidationTab.Title = 'Model validation';
            app.TabGroup3 = uitabgroup(app.ModelvalidationTab);
            app.OverviewTab = uitab(app.TabGroup3);
            app.OverviewTab.Title = 'Overview';
            app.BuildingcharacteristicsLabel = uilabel(app.OverviewTab);
            app.BuildingcharacteristicsLabel.Text = 'Building characteristics:';
            app.UITable2 = uitable(app.OverviewTab);
            app.UITable2.ColumnName = {''};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.OverviewTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.TrainingperiodLabel = uilabel(app.OverviewTab);
            app.TrainingperiodLabel.Text = 'Training period:';
            app.UITable3_2 = uitable(app.OverviewTab);
            app.UITable3_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3_2.RowName = {};
            app.TestperiodLabel = uilabel(app.OverviewTab);
            app.TestperiodLabel.Text = 'Test period:';
            app.Label_2 = uilabel(app.OverviewTab);
            app.Label_2.Text = {'(the radiator constant characterizes the heat distribution system and depends'; 'on the heat transfer area and the coefficient of thermal transmittance)'};
            app.IndoortemperatureTab = uitab(app.TabGroup3);
            app.IndoortemperatureTab.Title = 'Indoor temperature';
            app.UIAxes = uiaxes(app.IndoortemperatureTab);
            title(app.UIAxes, 'Training period')
            ylabel(app.UIAxes, 'T_i [°C]')
            app.UIAxes_2 = uiaxes(app.IndoortemperatureTab);
            title(app.UIAxes_2, 'Test period')
            ylabel(app.UIAxes_2, 'T_i [°C]')
            app.HeatingdemandTab = uitab(app.TabGroup3);
            app.HeatingdemandTab.Title = 'Heating demand';
            app.TrainingperiodPanel_2 = uipanel(app.HeatingdemandTab);
            app.TrainingperiodPanel_2.Title = 'Training period';
            app.UIAxes3 = uiaxes(app.TrainingperiodPanel_2);
            ylabel(app.UIAxes3, 'Heating demand [kWh/day]')
            app.TestperiodPanel_2 = uipanel(app.HeatingdemandTab);
            app.TestperiodPanel_2.Title = 'Test period';
            app.UIAxes2_3 = uiaxes(app.TestperiodPanel_2);
            ylabel(app.UIAxes2_3, 'Heating demand [kWh/day]')
            app.ModelparametersTab = uitab(app.TabGroup3);
            app.ModelparametersTab.Title = 'Model parameters';
            app.UITable4 = uitable(app.ModelparametersTab);
            app.UITable4.ColumnName = {'Parameter value'; 'Methodology'};
            app.UITable4.RowName = {};
            app.HeattransfercoefficientsWKLabel = uilabel(app.ModelparametersTab);
            app.HeattransfercoefficientsWKLabel.Text = 'Heat transfer coefficients [W/K]:';
            app.UITable4_2 = uitable(app.ModelparametersTab);
            app.UITable4_2.ColumnName = {'Parameter value'; 'Methodology'};
            app.UITable4_2.RowName = {};
            app.CapacitiesJLabel = uilabel(app.ModelparametersTab);
            app.CapacitiesJLabel.Text = 'Capacities [J]:';
            app.UITable4_3 = uitable(app.ModelparametersTab);
            app.UITable4_3.ColumnName = {'Parameter value'; 'Methodology'};
            app.UITable4_3.RowName = {};
            app.SurfaceheattransfercoefficientsWKLabel = uilabel(app.ModelparametersTab);
            app.SurfaceheattransfercoefficientsWKLabel.Text = 'Surface heat transfer coefficients [W/K]:';
            app.RetrofitmeasuresTab = uitab(app.TabGroup);
            app.RetrofitmeasuresTab.Title = 'Retrofit measures';
            app.TabGroup4 = uitabgroup(app.RetrofitmeasuresTab);
            app.FinancialparametersTab = uitab(app.TabGroup4);
            app.FinancialparametersTab.Title = 'Financial parameters';
            app.RetrofitmeasuresonbuildingenvelopePanel = uipanel(app.FinancialparametersTab);
            app.RetrofitmeasuresonbuildingenvelopePanel.Title = 'Retrofit measures on building envelope';
            app.RoofinsulationLabel = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.RoofinsulationLabel.Text = 'Roof insulation';
            app.ExternalwallinsulationLabel = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.ExternalwallinsulationLabel.Text = 'External wall insulation';
            app.WindowreplacementLabel = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.WindowreplacementLabel.Text = 'Window replacement';
            app.CellarceilinginsulationLabel = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.CellarceilinginsulationLabel.Text = 'Cellar ceiling insulation';
            app.EditField = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 0.18;
            app.EditField_2 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 0.1938;
            app.EditField_3 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 0.7602;
            app.EditField_4 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 0.1938;
            app.UValueWm2KLabel = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.UValueWm2KLabel.Text = {'U-Value'; '[W/(m2*K)]'};
            app.EditField_5 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 0.62;
            app.GValueLabel = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.GValueLabel.Text = {'G-Value'; '[-]'};
            app.EditField_6 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 30;
            app.EditField_7 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 40;
            app.EditField_8 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 25;
            app.EditField_9 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Value = 30;
            app.LifetimeaLabel = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.LifetimeaLabel.Text = {'Lifetime'; '[a]'};
            app.EditField_10 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_10.Value = 79.05;
            app.EditField_11 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 352.99;
            app.EditField_12 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_12.Value = 689.83;
            app.EditField_13 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_13.Value = 111.5;
            app.InvestmentcostCHFm2Label = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel);
            app.InvestmentcostCHFm2Label.Text = {'Investment cost'; '[CHF/m2]'};
            app.HeatingsystemsPanel = uipanel(app.FinancialparametersTab);
            app.HeatingsystemsPanel.Title = 'Heating systems';
            app.OilboilerLabel = uilabel(app.HeatingsystemsPanel);
            app.OilboilerLabel.Text = 'Oil boiler';
            app.GeothermalheatpumpLabel = uilabel(app.HeatingsystemsPanel);
            app.GeothermalheatpumpLabel.Text = 'Geothermal heat pump';
            app.AirtowaterheatpumpLabel = uilabel(app.HeatingsystemsPanel);
            app.AirtowaterheatpumpLabel.Text = 'Air-to-water heat pump';
            app.DistrictheatingLabel = uilabel(app.HeatingsystemsPanel);
            app.DistrictheatingLabel.Text = 'District heating';
            app.EditField_14 = uieditfield(app.HeatingsystemsPanel, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_14.Value = 20;
            app.EditField_15 = uieditfield(app.HeatingsystemsPanel, 'numeric');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_15.Value = 38;
            app.EditField_16 = uieditfield(app.HeatingsystemsPanel, 'numeric');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.EditField_16.Value = 21;
            app.EditField_17 = uieditfield(app.HeatingsystemsPanel, 'numeric');
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_17ValueChanged, true);
            app.EditField_17.Value = 20;
            app.LifetimeaLabel_3 = uilabel(app.HeatingsystemsPanel);
            app.LifetimeaLabel_3.Text = 'Lifetime [a]';
            app.PelletboilerLabel = uilabel(app.HeatingsystemsPanel);
            app.PelletboilerLabel.Text = 'Pellet boiler';
            app.EditField_27 = uieditfield(app.HeatingsystemsPanel, 'numeric');
            app.EditField_27.ValueChangedFcn = createCallbackFcn(app, @EditField_27ValueChanged, true);
            app.EditField_27.Value = 20;
            app.InvestmentcostPanel = uipanel(app.HeatingsystemsPanel);
            app.InvestmentcostPanel.Title = 'Investment cost';
            app.CHFLabel = uilabel(app.InvestmentcostPanel);
            app.CHFLabel.Text = 'CHF +';
            app.CHFEditField = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFEditField.ValueChangedFcn = createCallbackFcn(app, @CHFEditFieldValueChanged, true);
            app.CHFEditField.Value = 16310;
            app.CHFEditField_2Label = uilabel(app.InvestmentcostPanel);
            app.CHFEditField_2Label.Text = 'CHF +';
            app.CHFEditField_2 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFEditField_2.ValueChangedFcn = createCallbackFcn(app, @CHFEditField_2ValueChanged, true);
            app.CHFEditField_2.Value = 28500;
            app.CHFEditField_3Label = uilabel(app.InvestmentcostPanel);
            app.CHFEditField_3Label.Text = 'CHF +';
            app.CHFEditField_3 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFEditField_3.ValueChangedFcn = createCallbackFcn(app, @CHFEditField_3ValueChanged, true);
            app.CHFEditField_3.Value = 35360;
            app.CHFEditField_4Label = uilabel(app.InvestmentcostPanel);
            app.CHFEditField_4Label.Text = 'CHF +';
            app.CHFEditField_4 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFEditField_4.ValueChangedFcn = createCallbackFcn(app, @CHFEditField_4ValueChanged, true);
            app.CHFEditField_4.Value = 36920;
            app.CHFEditField_5Label = uilabel(app.InvestmentcostPanel);
            app.CHFEditField_5Label.Text = 'CHF +';
            app.CHFEditField_5 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFEditField_5.ValueChangedFcn = createCallbackFcn(app, @CHFEditField_5ValueChanged, true);
            app.CHFEditField_5.Value = 74380;
            app.CHFkWEditFieldLabel = uilabel(app.InvestmentcostPanel);
            app.CHFkWEditFieldLabel.Text = 'CHF/kW';
            app.CHFkWEditField = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFkWEditField.ValueChangedFcn = createCallbackFcn(app, @CHFkWEditFieldValueChanged, true);
            app.CHFkWEditField.Value = 133;
            app.CHFkWEditField_2Label = uilabel(app.InvestmentcostPanel);
            app.CHFkWEditField_2Label.Text = 'CHF/kW';
            app.CHFkWEditField_2 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFkWEditField_2.ValueChangedFcn = createCallbackFcn(app, @CHFkWEditField_2ValueChanged, true);
            app.CHFkWEditField_2.Value = 2122;
            app.CHFkWEditField_3Label = uilabel(app.InvestmentcostPanel);
            app.CHFkWEditField_3Label.Text = 'CHF/kW';
            app.CHFkWEditField_3 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFkWEditField_3.ValueChangedFcn = createCallbackFcn(app, @CHFkWEditField_3ValueChanged, true);
            app.CHFkWEditField_3.Value = 648;
            app.CHFkWEditField_4Label = uilabel(app.InvestmentcostPanel);
            app.CHFkWEditField_4Label.Text = 'CHF/kW';
            app.CHFkWEditField_4 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFkWEditField_4.ValueChangedFcn = createCallbackFcn(app, @CHFkWEditField_4ValueChanged, true);
            app.CHFkWEditField_4.Value = 128;
            app.CHFkWEditField_5Label = uilabel(app.InvestmentcostPanel);
            app.CHFkWEditField_5Label.Text = 'CHF/kW';
            app.CHFkWEditField_5 = uieditfield(app.InvestmentcostPanel, 'numeric');
            app.CHFkWEditField_5.ValueChangedFcn = createCallbackFcn(app, @CHFkWEditField_5ValueChanged, true);
            app.CHFkWEditField_5.Value = 97;
            app.OperationalandmaintenancecostPanel = uipanel(app.HeatingsystemsPanel);
            app.OperationalandmaintenancecostPanel.Title = 'Operational and maintenance cost';
            app.CHFaEditFieldLabel = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFaEditFieldLabel.Text = 'CHF/a +';
            app.CHFaEditField = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFaEditField.ValueChangedFcn = createCallbackFcn(app, @CHFaEditFieldValueChanged, true);
            app.CHFaEditField.Value = 650;
            app.CHFaEditField_2Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFaEditField_2Label.Text = 'CHF/a +';
            app.CHFaEditField_2 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFaEditField_2.ValueChangedFcn = createCallbackFcn(app, @CHFaEditField_2ValueChanged, true);
            app.CHFaEditField_2.Value = 330;
            app.CHFaEditField_3Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFaEditField_3Label.Text = 'CHF/a +';
            app.CHFaEditField_3 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFaEditField_3.ValueChangedFcn = createCallbackFcn(app, @CHFaEditField_3ValueChanged, true);
            app.CHFaEditField_3.Value = 330;
            app.CHFaEditField_4Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFaEditField_4Label.Text = 'CHF/a +';
            app.CHFaEditField_4 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFaEditField_4.ValueChangedFcn = createCallbackFcn(app, @CHFaEditField_4ValueChanged, true);
            app.CHFaEditField_4.Value = 310;
            app.CHFaEditField_5Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFaEditField_5Label.Text = 'CHF/a +';
            app.CHFaEditField_5 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFaEditField_5.ValueChangedFcn = createCallbackFcn(app, @CHFaEditField_5ValueChanged, true);
            app.CHFaEditField_5.Value = 450;
            app.CHFakWLabel = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFakWLabel.Text = 'CHF/(a*kW)';
            app.CHFakWEditField = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFakWEditField.ValueChangedFcn = createCallbackFcn(app, @CHFakWEditFieldValueChanged, true);
            app.CHFakWEditField_2Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFakWEditField_2Label.Text = 'CHF/(a*kW)';
            app.CHFakWEditField_2 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFakWEditField_2.ValueChangedFcn = createCallbackFcn(app, @CHFakWEditField_2ValueChanged, true);
            app.CHFakWEditField_3Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFakWEditField_3Label.Text = 'CHF/(a*kW)';
            app.CHFakWEditField_3 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFakWEditField_3.ValueChangedFcn = createCallbackFcn(app, @CHFakWEditField_3ValueChanged, true);
            app.CHFakWEditField_4Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFakWEditField_4Label.Text = 'CHF/(a*kW)';
            app.CHFakWEditField_4 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFakWEditField_4.ValueChangedFcn = createCallbackFcn(app, @CHFakWEditField_4ValueChanged, true);
            app.CHFakWEditField_4.Value = 72.2;
            app.CHFakWEditField_5Label = uilabel(app.OperationalandmaintenancecostPanel);
            app.CHFakWEditField_5Label.Text = 'CHF/(a*kW)';
            app.CHFakWEditField_5 = uieditfield(app.OperationalandmaintenancecostPanel, 'numeric');
            app.CHFakWEditField_5.ValueChangedFcn = createCallbackFcn(app, @CHFakWEditField_5ValueChanged, true);
            app.MaintenancemeasuresonbuildingenvelopePanel = uipanel(app.FinancialparametersTab);
            app.MaintenancemeasuresonbuildingenvelopePanel.Title = 'Maintenance measures on building envelope';
            app.EditField_28 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_28.ValueChangedFcn = createCallbackFcn(app, @EditField_28ValueChanged, true);
            app.EditField_28.Value = 25;
            app.EditField_29 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_29.ValueChangedFcn = createCallbackFcn(app, @EditField_29ValueChanged, true);
            app.EditField_29.Value = 25;
            app.EditField_30 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_30.ValueChangedFcn = createCallbackFcn(app, @EditField_30ValueChanged, true);
            app.EditField_30.Value = 25;
            app.EditField_31 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_31.ValueChangedFcn = createCallbackFcn(app, @EditField_31ValueChanged, true);
            app.EditField_31.Value = 25;
            app.LifetimeaLabel_4 = uilabel(app.MaintenancemeasuresonbuildingenvelopePanel);
            app.LifetimeaLabel_4.Text = {'Lifetime'; '[a]'};
            app.EditField_32 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_32.ValueChangedFcn = createCallbackFcn(app, @EditField_32ValueChanged, true);
            app.EditField_32.Value = 26.5;
            app.EditField_33 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_33.ValueChangedFcn = createCallbackFcn(app, @EditField_33ValueChanged, true);
            app.EditField_33.Value = 8.56;
            app.EditField_34 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_34.ValueChangedFcn = createCallbackFcn(app, @EditField_34ValueChanged, true);
            app.EditField_34.Value = 37.7;
            app.EditField_35 = uieditfield(app.MaintenancemeasuresonbuildingenvelopePanel, 'numeric');
            app.EditField_35.ValueChangedFcn = createCallbackFcn(app, @EditField_35ValueChanged, true);
            app.EditField_35.Value = 82.75;
            app.InvestmentcostCHFm2Label_2 = uilabel(app.MaintenancemeasuresonbuildingenvelopePanel);
            app.InvestmentcostCHFm2Label_2.Text = {'Investment cost'; '[CHF/m2]'};
            app.CleaningandrepaintingthefacadeLabel = uilabel(app.MaintenancemeasuresonbuildingenvelopePanel);
            app.CleaningandrepaintingthefacadeLabel.Text = 'Cleaning and repainting the facade';
            app.GrindingputtyingandrepaintingtheundersideoftheroofLabel = uilabel(app.MaintenancemeasuresonbuildingenvelopePanel);
            app.GrindingputtyingandrepaintingtheundersideoftheroofLabel.Text = {'Grinding, puttying and repainting the'; 'underside of the roof'};
            app.ScaffoldingLabel = uilabel(app.MaintenancemeasuresonbuildingenvelopePanel);
            app.ScaffoldingLabel.Text = 'Scaffolding';
            app.RepaintingthewindowsLabel = uilabel(app.MaintenancemeasuresonbuildingenvelopePanel);
            app.RepaintingthewindowsLabel.Text = 'Repainting the windows';
            app.EcologicalparametersTab = uitab(app.TabGroup4);
            app.EcologicalparametersTab.Title = 'Ecological parameters';
            app.CreateretrofitmatrixButton = uibutton(app.EcologicalparametersTab, 'push');
            app.CreateretrofitmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @CreateretrofitmatrixButtonPushed, true);
            app.CreateretrofitmatrixButton.Text = 'Create retrofit matrix';
            app.CompleteLamp_5Label = uilabel(app.EcologicalparametersTab);
            app.CompleteLamp_5Label.Text = 'Complete';
            app.CompleteLamp_5 = uilamp(app.EcologicalparametersTab);
            app.RetrofitmeasuresonbuildingenvelopePanel_3 = uipanel(app.EcologicalparametersTab);
            app.RetrofitmeasuresonbuildingenvelopePanel_3.Title = 'Retrofit measures on building envelope';
            app.RoofinsulationLabel_2 = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel_3);
            app.RoofinsulationLabel_2.Text = 'Roof insulation';
            app.ExternalwallinsulationLabel_2 = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel_3);
            app.ExternalwallinsulationLabel_2.Text = 'External wall insulation';
            app.WindowreplacementLabel_2 = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel_3);
            app.WindowreplacementLabel_2.Text = 'Window replacement';
            app.CellarceilinginsulationLabel_2 = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel_3);
            app.CellarceilinginsulationLabel_2.Text = 'Cellar ceiling insulation';
            app.EditField_36 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_36.ValueChangedFcn = createCallbackFcn(app, @EditField_36ValueChanged, true);
            app.EditField_36.Value = 22.6;
            app.EditField_37 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_37.ValueChangedFcn = createCallbackFcn(app, @EditField_37ValueChanged, true);
            app.EditField_37.Value = 72.44;
            app.EditField_38 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_38.ValueChangedFcn = createCallbackFcn(app, @EditField_38ValueChanged, true);
            app.EditField_38.Value = 153.75;
            app.EditField_39 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_39.ValueChangedFcn = createCallbackFcn(app, @EditField_39ValueChanged, true);
            app.EditField_39.Value = 18.08;
            app.GHGemissionskgCO2eqm2Label = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel_3);
            app.GHGemissionskgCO2eqm2Label.Text = {'GHG emissions'; '[kg CO2-eq/m2]'};
            app.EditField_45 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_45.ValueChangedFcn = createCallbackFcn(app, @EditField_45ValueChanged, true);
            app.EditField_45.Value = 155;
            app.EditField_46 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_46.ValueChangedFcn = createCallbackFcn(app, @EditField_46ValueChanged, true);
            app.EditField_46.Value = 261.35;
            app.EditField_47 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_47.ValueChangedFcn = createCallbackFcn(app, @EditField_47ValueChanged, true);
            app.EditField_47.Value = 743;
            app.EditField_48 = uieditfield(app.RetrofitmeasuresonbuildingenvelopePanel_3, 'numeric');
            app.EditField_48.ValueChangedFcn = createCallbackFcn(app, @EditField_48ValueChanged, true);
            app.EditField_48.Value = 124;
            app.PENRconsumptionkWhm2Label = uilabel(app.RetrofitmeasuresonbuildingenvelopePanel_3);
            app.PENRconsumptionkWhm2Label.Text = {'PENR consumption'; '[kWh/m2]'};
            app.HeatingsystemsPanel_2 = uipanel(app.EcologicalparametersTab);
            app.HeatingsystemsPanel_2.Title = 'Heating systems';
            app.OilboilerLabel_2 = uilabel(app.HeatingsystemsPanel_2);
            app.OilboilerLabel_2.Text = 'Oil boiler';
            app.GeothermalheatpumpLabel_2 = uilabel(app.HeatingsystemsPanel_2);
            app.GeothermalheatpumpLabel_2.Text = 'Geothermal heat pump';
            app.AirtowaterheatpumpLabel_2 = uilabel(app.HeatingsystemsPanel_2);
            app.AirtowaterheatpumpLabel_2.Text = 'Air-to-water heat pump';
            app.DistrictheatingLabel_2 = uilabel(app.HeatingsystemsPanel_2);
            app.DistrictheatingLabel_2.Text = 'District heating';
            app.EditField_49 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_49.ValueChangedFcn = createCallbackFcn(app, @EditField_49ValueChanged, true);
            app.EditField_49.Value = 380.62;
            app.EditField_50 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_50.ValueChangedFcn = createCallbackFcn(app, @EditField_50ValueChanged, true);
            app.EditField_50.Value = 2180;
            app.EditField_51 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_51.ValueChangedFcn = createCallbackFcn(app, @EditField_51ValueChanged, true);
            app.EditField_51.Value = 2910;
            app.EditField_52 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_52.ValueChangedFcn = createCallbackFcn(app, @EditField_52ValueChanged, true);
            app.EditField_52.Value = 190.31;
            app.GHGemissionskgCO2eqcomponentLabel = uilabel(app.HeatingsystemsPanel_2);
            app.GHGemissionskgCO2eqcomponentLabel.Text = {'GHG emissions'; '[kg CO2-eq/component]'};
            app.EditField_53 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @EditField_53ValueChanged, true);
            app.EditField_53.Value = 1760;
            app.EditField_54 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_54.ValueChangedFcn = createCallbackFcn(app, @EditField_54ValueChanged, true);
            app.EditField_54.Value = 5520;
            app.EditField_55 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_55.ValueChangedFcn = createCallbackFcn(app, @EditField_55ValueChanged, true);
            app.EditField_55.Value = 6460;
            app.EditField_56 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_56.ValueChangedFcn = createCallbackFcn(app, @EditField_56ValueChanged, true);
            app.EditField_56.Value = 880;
            app.PENRconsumptionkWhcomponentLabel = uilabel(app.HeatingsystemsPanel_2);
            app.PENRconsumptionkWhcomponentLabel.Text = {'PENR consumption'; '[kWh/component]'};
            app.PelletboilerLabel_2 = uilabel(app.HeatingsystemsPanel_2);
            app.PelletboilerLabel_2.Text = 'Pellet boiler';
            app.EditField_57 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_57.ValueChangedFcn = createCallbackFcn(app, @EditField_57ValueChanged, true);
            app.EditField_57.Value = 2926.3;
            app.EditField_58 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_58.ValueChangedFcn = createCallbackFcn(app, @EditField_58ValueChanged, true);
            app.EditField_58.Value = 9075.2;
            app.EditField_60 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_60.ValueChangedFcn = createCallbackFcn(app, @EditField_60ValueChanged, true);
            app.EditField_60.Value = 28.1;
            app.GHGemissionskgCO2eqmprobelengthLabel = uilabel(app.HeatingsystemsPanel_2);
            app.GHGemissionskgCO2eqmprobelengthLabel.Text = {'GHG emissions'; '[kg CO2-eq/m probe length]'};
            app.EditField_64 = uieditfield(app.HeatingsystemsPanel_2, 'numeric');
            app.EditField_64.ValueChangedFcn = createCallbackFcn(app, @EditField_64ValueChanged, true);
            app.EditField_64.Value = 130;
            app.PENRconsumptionkWhmprobelengthLabel = uilabel(app.HeatingsystemsPanel_2);
            app.PENRconsumptionkWhmprobelengthLabel.Text = {'PENR consumption'; '[kWh/m probe length]'};
            app.SimulationsTab = uitab(app.TabGroup);
            app.SimulationsTab.Title = 'Simulations';
            app.BuildingenergysimulationsPanel = uipanel(app.SimulationsTab);
            app.BuildingenergysimulationsPanel.Title = 'Building energy simulations';
            app.InputfilesxlsxwithhourlydataforawholeyearPanel = uipanel(app.BuildingenergysimulationsPanel);
            app.InputfilesxlsxwithhourlydataforawholeyearPanel.Title = 'Input files [.xlsx] with hourly data for a whole year';
            app.SolarirradianceEditField_2Label = uilabel(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.SolarirradianceEditField_2Label.Text = 'Solar irradiance:';
            app.SolarirradianceEditField_2 = uieditfield(app.InputfilesxlsxwithhourlydataforawholeyearPanel, 'text');
            app.SolarirradianceEditField_2.ValueChangedFcn = createCallbackFcn(app, @SolarirradianceEditField_2ValueChanged, true);
            app.SolarirradianceEditField_2.Value = 'solar_irradiance_STG_year.xlsx';
            app.ExternaltemperatureEditFieldLabel = uilabel(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.ExternaltemperatureEditFieldLabel.Text = 'External temperature:';
            app.ExternaltemperatureEditField = uieditfield(app.InputfilesxlsxwithhourlydataforawholeyearPanel, 'text');
            app.ExternaltemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @ExternaltemperatureEditFieldValueChanged, true);
            app.ExternaltemperatureEditField.Value = 'input_ext_temp.xlsx';
            app.UITable_3 = uitable(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.UITable_3.ColumnName = {'Day'; 'Hour'; 'T_e'};
            app.UITable_3.RowName = {};
            app.FormatLabel_3 = uilabel(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.FormatLabel_3.Text = 'Format:';
            app.VariablesTextArea_3Label = uilabel(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.VariablesTextArea_3Label.Text = 'Variables:';
            app.VariablesTextArea_3 = uitextarea(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.VariablesTextArea_3.Value = {'- Day: Day of the year [1;...;365]'; '- Hour: Hour of the day [1;...;24]'; '- T_e: Hourly outdoor temperature [°C]'};
            app.UITable_4 = uitable(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.UITable_4.ColumnName = {'Day'; 'Hour'; 'BNI'; 'DHI'};
            app.UITable_4.RowName = {};
            app.FormatLabel_4 = uilabel(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.FormatLabel_4.Text = 'Format:';
            app.VariablesTextArea_4Label = uilabel(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.VariablesTextArea_4Label.Text = 'Variables:';
            app.VariablesTextArea_4 = uitextarea(app.InputfilesxlsxwithhourlydataforawholeyearPanel);
            app.VariablesTextArea_4.Value = {'- Day: Day of the year [1;...;365]'; '- Hour: Hour of the day [1;...;24]'; '- BNI: Hourly beam normal irradiation (=DNI) [Wh/m2]'; '- DHI: Hourly diffuse horizontal irradiation [Wh/m2] '};
            app.StartsimulationButton = uibutton(app.BuildingenergysimulationsPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.CompleteLamp_4Label = uilabel(app.BuildingenergysimulationsPanel);
            app.CompleteLamp_4Label.Text = 'Complete';
            app.CompleteLamp_4 = uilamp(app.BuildingenergysimulationsPanel);
            app.HeatingsystemsimulationsPanel = uipanel(app.SimulationsTab);
            app.HeatingsystemsimulationsPanel.Title = 'Heating system simulations';
            app.EfficienciesPanel = uipanel(app.HeatingsystemsimulationsPanel);
            app.EfficienciesPanel.Title = 'Efficiencies';
            app.DHWstorageLabel = uilabel(app.EfficienciesPanel);
            app.DHWstorageLabel.Text = 'DHW storage:';
            app.DHWstorageEditField = uieditfield(app.EfficienciesPanel, 'numeric');
            app.DHWstorageEditField.ValueChangedFcn = createCallbackFcn(app, @DHWstorageEditFieldValueChanged, true);
            app.DHWstorageEditField.Value = 0.8;
            app.OilboilerEditFieldLabel = uilabel(app.EfficienciesPanel);
            app.OilboilerEditFieldLabel.Text = 'Oil boiler:';
            app.OilboilerEditField = uieditfield(app.EfficienciesPanel, 'numeric');
            app.OilboilerEditField.ValueChangedFcn = createCallbackFcn(app, @OilboilerEditFieldValueChanged, true);
            app.OilboilerEditField.Value = 0.95;
            app.DistrictheatingheattransferstationEditFieldLabel = uilabel(app.EfficienciesPanel);
            app.DistrictheatingheattransferstationEditFieldLabel.Text = 'District heating (heat transfer station):';
            app.DistrictheatingheattransferstationEditField = uieditfield(app.EfficienciesPanel, 'numeric');
            app.DistrictheatingheattransferstationEditField.ValueChangedFcn = createCallbackFcn(app, @DistrictheatingheattransferstationEditFieldValueChanged, true);
            app.DistrictheatingheattransferstationEditField.Value = 0.97;
            app.PelletboilerEditFieldLabel = uilabel(app.EfficienciesPanel);
            app.PelletboilerEditFieldLabel.Text = 'Pellet boiler:';
            app.PelletboilerEditField = uieditfield(app.EfficienciesPanel, 'numeric');
            app.PelletboilerEditField.ValueChangedFcn = createCallbackFcn(app, @PelletboilerEditFieldValueChanged, true);
            app.PelletboilerEditField.Value = 0.75;
            app.HeatpumpsTextAreaLabel = uilabel(app.EfficienciesPanel);
            app.HeatpumpsTextAreaLabel.Text = 'Heat pumps:';
            app.HeatpumpsTextArea = uitextarea(app.EfficienciesPanel);
            app.HeatpumpsTextArea.Value = {'The seasonal performance factor (SPF) is calculated with an hourly simulation with the respective heat source temperatures and supply temperatures.'};
            app.StartsimulationButton_2 = uibutton(app.HeatingsystemsimulationsPanel, 'push');
            app.StartsimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButton_2Pushed, true);
            app.StartsimulationButton_2.Text = 'Start simulation';
            app.CompleteLamp_6Label = uilabel(app.HeatingsystemsimulationsPanel);
            app.CompleteLamp_6Label.Text = 'Complete';
            app.CompleteLamp_6 = uilamp(app.HeatingsystemsimulationsPanel);
            app.TechnicalfeasibilityPanel = uipanel(app.HeatingsystemsimulationsPanel);
            app.TechnicalfeasibilityPanel.Title = 'Technical feasibility';
            app.MaxsupplytemperaturewithgeothermalheatpumpCEditFieldLabel = uilabel(app.TechnicalfeasibilityPanel);
            app.MaxsupplytemperaturewithgeothermalheatpumpCEditFieldLabel.Text = 'Max. supply temperature with geothermal heat pump [°C]:';
            app.MaxsupplytemperaturewithgeothermalheatpumpCEditField = uieditfield(app.TechnicalfeasibilityPanel, 'numeric');
            app.MaxsupplytemperaturewithgeothermalheatpumpCEditField.ValueChangedFcn = createCallbackFcn(app, @MaxsupplytemperaturewithgeothermalheatpumpCEditFieldValueChanged, true);
            app.MaxsupplytemperaturewithgeothermalheatpumpCEditField.Value = 60;
            app.MaxsupplytemperaturewithairtowaterheatpumpCEditFieldLabel = uilabel(app.TechnicalfeasibilityPanel);
            app.MaxsupplytemperaturewithairtowaterheatpumpCEditFieldLabel.Text = 'Max. supply temperature with air-to-water heat pump [°C]:';
            app.MaxsupplytemperaturewithairtowaterheatpumpCEditField = uieditfield(app.TechnicalfeasibilityPanel, 'numeric');
            app.MaxsupplytemperaturewithairtowaterheatpumpCEditField.ValueChangedFcn = createCallbackFcn(app, @MaxsupplytemperaturewithairtowaterheatpumpCEditFieldValueChanged, true);
            app.MaxsupplytemperaturewithairtowaterheatpumpCEditField.Value = 60;
            app.TextArea = uitextarea(app.TechnicalfeasibilityPanel);
            app.TextArea.Value = {'Maximum supply temperatures that can be generated by the heat pumps'};
            app.ImpressumTab = uitab(app.TabGroup);
            app.ImpressumTab.Title = 'Impressum';
            app.ProgrammerTextAreaLabel = uilabel(app.ImpressumTab);
            app.ProgrammerTextAreaLabel.Text = 'Programmer';
            app.ProgrammerTextArea = uitextarea(app.ImpressumTab);
            app.ProgrammerTextArea.Value = {'Diego Sigrist'};
            app.EmailTextAreaLabel = uilabel(app.ImpressumTab);
            app.EmailTextAreaLabel.Text = 'Email';
            app.EmailTextArea = uitextarea(app.ImpressumTab);
            app.EmailTextArea.Value = {'sigrisdi@student.ethz.ch'};
            app.TitleTextAreaLabel = uilabel(app.ImpressumTab);
            app.TitleTextAreaLabel.Text = 'Title';
            app.TitleTextArea = uitextarea(app.ImpressumTab);
            app.TitleTextArea.Value = {'Retrofit App 1'};
            app.VersionTextAreaLabel = uilabel(app.ImpressumTab);
            app.VersionTextAreaLabel.Text = 'Version';
            app.VersionTextArea = uitextarea(app.ImpressumTab);
            app.VersionTextArea.Value = {'1.0'};
            app.DateTextAreaLabel = uilabel(app.ImpressumTab);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.ImpressumTab);
            app.DateTextArea.Value = {'22.08.2019'};
            app.DescriptionTextAreaLabel = uilabel(app.ImpressumTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.ImpressumTab);
            app.DescriptionTextArea.Value = {'This programme was developed as part of the Master''s thesis "Automation of Cost-Optimal Retrofit Analysis for Residential Buildings Using a Light-Grey-Box Model", in which detailed documentation can also be found.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Retrofit_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end