classdef Retrofit_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        RetrofitApp2Label               matlab.ui.control.Label
        LifecyclecostingLifecycleassessmentLabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        FinancialparametersPanel        matlab.ui.container.Panel
        EnergypricesCHFkWhPanel         matlab.ui.container.Panel
        HeatingoilLabel                 matlab.ui.control.Label
        HeatingoilEditField             matlab.ui.control.NumericEditField
        DistrictheatLabel               matlab.ui.control.Label
        DistrictheatEditField           matlab.ui.control.NumericEditField
        ElectricityLabel                matlab.ui.control.Label
        ElectricityEditField            matlab.ui.control.NumericEditField
        PelletsLabel                    matlab.ui.control.Label
        PelletsEditField                matlab.ui.control.NumericEditField
        AnnualchangeinenergypricesPanel  matlab.ui.container.Panel
        HeatingoilLabel_2               matlab.ui.control.Label
        HeatingoilSlider                matlab.ui.control.Slider
        PelletsLabel_2                  matlab.ui.control.Label
        PelletsSlider                   matlab.ui.control.Slider
        ElectricityLabel_2              matlab.ui.control.Label
        ElectricitySlider               matlab.ui.control.Slider
        DistrictheatLabel_2             matlab.ui.control.Label
        DistrictheatSlider              matlab.ui.control.Slider
        RealinterestratePanel           matlab.ui.container.Panel
        RealinterestrateSlider          matlab.ui.control.Slider
        CalculationtimeperiodaPanel     matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        EcologicalparametersPanel       matlab.ui.container.Panel
        GHGefactorskgCO2eqkWhPanel      matlab.ui.container.Panel
        HeatingoilLabel_3               matlab.ui.control.Label
        HeatingoilEditField_2           matlab.ui.control.NumericEditField
        DistrictheatLabel_3             matlab.ui.control.Label
        DistrictheatEditField_2         matlab.ui.control.NumericEditField
        ElectricityLabel_3              matlab.ui.control.Label
        ElectricityEditField_2          matlab.ui.control.NumericEditField
        PelletsLabel_3                  matlab.ui.control.Label
        PelletsEditField_2              matlab.ui.control.NumericEditField
        PENRfactorskWhkWhPanel          matlab.ui.container.Panel
        HeatingoilLabel_4               matlab.ui.control.Label
        HeatingoilEditField_3           matlab.ui.control.NumericEditField
        DistrictheatLabel_4             matlab.ui.control.Label
        DistrictheatEditField_3         matlab.ui.control.NumericEditField
        ElectricityLabel_4              matlab.ui.control.Label
        ElectricityEditField_3          matlab.ui.control.NumericEditField
        PelletsLabel_4                  matlab.ui.control.Label
        PelletsEditField_3              matlab.ui.control.NumericEditField
        CalculationButton_2             matlab.ui.control.Button
        CompleteLampLabel               matlab.ui.control.Label
        CompleteLamp                    matlab.ui.control.Lamp
        UIAxes6                         matlab.ui.control.UIAxes
        CostoverviewTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        InvestmentcostsTab              matlab.ui.container.Tab
        investment_costs                matlab.ui.control.Table
        MatrixwithinvestmentcostsofretrofitstrategiesCHFLabel  matlab.ui.control.Label
        AnnotationLabel_7               matlab.ui.control.Label
        TextArea_8                      matlab.ui.control.TextArea
        RunningcostsTab                 matlab.ui.container.Tab
        TextArea_7                      matlab.ui.control.TextArea
        AnnotationsLabel                matlab.ui.control.Label
        running_costs                   matlab.ui.control.Table
        MatrixwithrunningcostsofretrofitstrategiesCHFaLabel  matlab.ui.control.Label
        TextArea_9                      matlab.ui.control.TextArea
        ResultsTab                      matlab.ui.container.Tab
        MinglobalcostcostoptimalLabel   matlab.ui.control.Label
        MinPENRconsumptionLabel         matlab.ui.control.Label
        MinMACGHGemissionsLabel         matlab.ui.control.Label
        MinMACPENRconsumptionLabel      matlab.ui.control.Label
        cost_optimal                    matlab.ui.control.Table
        minimum_ghg                     matlab.ui.control.Table
        minimum_penr                    matlab.ui.control.Table
        cost_efficient_ghg              matlab.ui.control.Table
        cost_efficient_penr             matlab.ui.control.Table
        GlobalcostCHFEditField          matlab.ui.control.NumericEditField
        GlobalcostCHFEditField_2        matlab.ui.control.NumericEditField
        GlobalcostCHFEditField_3        matlab.ui.control.NumericEditField
        GlobalcostCHFEditField_4        matlab.ui.control.NumericEditField
        GlobalcostCHFEditField_5        matlab.ui.control.NumericEditField
        GHGemissionskgCO2eqaGauge       matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_2     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_3     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_4     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_5     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaLabel       matlab.ui.control.Label
        PENRconsumptionkWhaLabel        matlab.ui.control.Label
        GlobalcostsCHFLabel             matlab.ui.control.Label
        GHGemissionskgCO2eqaGauge_6     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_7     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_8     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_9     matlab.ui.control.NinetyDegreeGauge
        GHGemissionskgCO2eqaGauge_10    matlab.ui.control.NinetyDegreeGauge
        MinGHGemissionsLabel            matlab.ui.control.Label
        OptimalretrofitstrategiesLabel  matlab.ui.control.Label
        PlotsTab                        matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        GlobalcostsvsGHGemissionsTab    matlab.ui.container.Tab
        UIAxes4                         matlab.ui.control.UIAxes
        GlobalcostsvsPENRconsumptionTab  matlab.ui.container.Tab
        UIAxes4_2                       matlab.ui.control.UIAxes
        DetailedresultsTab              matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        CosteffectivenessTab            matlab.ui.container.Tab
        global_costs                    matlab.ui.control.Table
        MatrixwithglobalcostsofretrofitstrategiesCHFLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        CostoptimalretrofitstrategyperheatingsystemLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        AnnotationLabel                 matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        GHGemissionsTab                 matlab.ui.container.Tab
        ghg_emissions                   matlab.ui.control.Table
        MatrixwithGHGemissionsofretrofitstrategieskgCO2eqaLabel  matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        RetrofitstrategywithminimumGHGemissionsperheatingsystemLabel  matlab.ui.control.Label
        UITable_2                       matlab.ui.control.Table
        AnnotationLabel_2               matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        PENRconsumptionTab              matlab.ui.container.Tab
        penr_consumption                matlab.ui.control.Table
        MatrixwithPENRconsumptionofretrofitstrategieskWhaLabel  matlab.ui.control.Label
        RetrofitstrategywithminimumPENRconsumptionperheatingsystemLabel  matlab.ui.control.Label
        UIAxes2_3                       matlab.ui.control.UIAxes
        UITable_3                       matlab.ui.control.Table
        AnnotationLabel_3               matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        MarginalabatementcostsGHGemissionsTab  matlab.ui.container.Tab
        mac_ghg                         matlab.ui.control.Table
        MatrixwithmarginalcostsofabatingGHGemissionsCHFkgCO2eqaLabel  matlab.ui.control.Label
        AnnotationLabel_4               matlab.ui.control.Label
        TextArea_4                      matlab.ui.control.TextArea
        RetrofitstrategywithminimumMACperheatingsystemLabel  matlab.ui.control.Label
        UITable_4                       matlab.ui.control.Table
        UIAxes2_4                       matlab.ui.control.UIAxes
        MarginalabatementcostsPENRconsumptionTab  matlab.ui.container.Tab
        mac_penr                        matlab.ui.control.Table
        MatrixwithmarginalcostsofabatingPENRconsumptionCHFkWhaLabel  matlab.ui.control.Label
        AnnotationLabel_5               matlab.ui.control.Label
        TextArea_5                      matlab.ui.control.TextArea
        RetrofitstrategywithminimumMACperheatingsystemLabel_2  matlab.ui.control.Label
        UITable_5                       matlab.ui.control.Table
        UIAxes2_5                       matlab.ui.control.UIAxes
        TimelineTab                     matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ImpressumTab                    matlab.ui.container.Tab
        ProgrammerTextAreaLabel         matlab.ui.control.Label
        ProgrammerTextArea              matlab.ui.control.TextArea
        EmailTextAreaLabel              matlab.ui.control.Label
        EmailTextArea                   matlab.ui.control.TextArea
        TitleTextAreaLabel              matlab.ui.control.Label
        TitleTextArea                   matlab.ui.control.TextArea
        VersionTextAreaLabel            matlab.ui.control.Label
        VersionTextArea                 matlab.ui.control.TextArea
        DateTextAreaLabel               matlab.ui.control.Label
        DateTextArea                    matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        E_final = load('E_final.mat'); % Final energy demand of each retrofit strategy
        invest_single_BE = load('invest_single_BE.mat'); % Investment costs of retrofit measures on building envelope
        invest_single_MM = load('invest_single_MM.mat'); % Investment costs of maintenance measures
        invest_HS = load('invest_HS.mat'); % Investment costs of heating systems
        OM_HS = load('OM_HS.mat'); % Operational and maintenance costs of heating systems
        lifetime_BE = load('lifetime_BE.mat'); % Lifetimes of retrofit measures on building envelope
        lifetime_MM = load('lifetime_MM.mat'); % Lifetimes of maintenance measures
        lifetime_HS = load('lifetime_HS.mat'); % Lifetimes of heating systems
        feasibility_air = load('feasibility_air.mat'); feasibility_geothermal = load('feasibility_geo.mat');
        Roof; Walls; Windows; Cellar_ceiling;
        RS = cell([16 5]);
        hload_max = load('sim_hload_max.mat');
        length_probe = load('length_probe.mat');
        p_oil; p_dh; p_el; p_wood; r_oil; r_dh; r_el; r_wood;
        Price_oil; Price_dh; Price_el; Price_wood;
        r; t_period;
        ghg_oil; ghg_wood; ghg_dh; ghg_el;
        penr_oil; penr_wood; penr_dh; penr_el;
        GHG_be = load('GHG_be.mat'); GHG_hs = load('GHG_hs.mat'); GHG_probe = load('GHG_probe.mat');
        PENR_be = load('PENR_be.mat'); PENR_hs = load('PENR_hs.mat'); PENR_probe = load('PENR_probe.mat');
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.p_oil = app.HeatingoilEditField.Value;
            app.p_dh = app.DistrictheatEditField.Value;
            app.p_el = app.ElectricityEditField.Value;
            app.p_wood = app.PelletsEditField.Value;
            app.r_oil = app.HeatingoilSlider.Value;
            app.r_dh = app.DistrictheatSlider.Value;
            app.r_el = app.ElectricitySlider.Value;
            app.r_wood = app.PelletsSlider.Value;
            app.r = app.RealinterestrateSlider.Value;
            app.t_period = str2double(app.DropDown.Value);
            app.ghg_oil = app.HeatingoilEditField_2.Value;
            app.ghg_dh = app.DistrictheatEditField_2.Value;
            app.ghg_el = app.ElectricityEditField_2.Value;
            app.ghg_wood = app.PelletsEditField_2.Value;
            app.penr_oil = app.HeatingoilEditField_3.Value;
            app.penr_dh = app.DistrictheatEditField_3.Value;
            app.penr_el = app.ElectricityEditField_3.Value;
            app.penr_wood = app.PelletsEditField_3.Value;
            
            app.Roof = [false;true;false;false;false;true;true;true;false;false;false;true;true;true;false;true];
            app.Walls = [false;false;true;false;false;true;false;false;true;true;false;true;true;false;true;true];
            app.Cellar_ceiling = [false;false;false;false;true;false;false;true;false;true;true;false;true;true;true;true];
            app.Windows = [false;false;false;true;false;false;true;false;true;false;true;true;false;true;true;true];
            
            rs = cell([16 1]);
            rs{1,1} = [0,0,0,0,0;1,1,1,1,0];
            rs{2,1} = [1,0,0,0,0;1,1,1,1,0];
            rs{3,1} = [0,1,0,0,0;0,1,0,1,0];
            rs{4,1} = [0,0,1,0,0;1,1,1,0,0];
            rs{5,1} = [0,0,0,1,0;1,1,1,1,0];
            rs{6,1} = [1,1,0,0,0;0,1,0,1,0];
            rs{7,1} = [1,0,1,0,0;1,1,1,0,0];
            rs{8,1} = [1,0,0,1,0;1,1,1,1,0];
            rs{9,1} = [0,1,1,0,0;0,1,0,0,0];
            rs{10,1} = [0,1,0,1,0;0,1,0,1,0];
            rs{11,1} = [0,0,1,1,0;1,1,1,0,0];
            rs{12,1} = [1,1,1,0,0;0,1,0,0,0];
            rs{13,1} = [1,1,0,1,0;0,1,0,1,0];
            rs{14,1} = [1,0,1,1,0;1,1,1,0,0];
            rs{15,1} = [0,1,1,1,0;0,1,0,0,0];
            rs{16,1} = [1,1,1,1,0;0,1,0,0,0];
            
            rs_HS = zeros(1,5);
            for i=1:5
                rs_HS(i) = 1;
                for j=1:16
                    app.RS{j,i} = [rs{j,1};rs_HS];
                end
                rs_HS(i) = 0;
            end
            
            app.E_final = app.E_final.final_E;
            app.invest_single_BE = app.invest_single_BE.BE_invest;
            app.invest_single_MM = app.invest_single_MM.MM_invest;
            app.invest_HS = app.invest_HS.HS_invest;
            app.OM_HS = app.OM_HS.HS_om;
            app.lifetime_BE = app.lifetime_BE.BE_lifetime;
            app.lifetime_MM = app.lifetime_MM.MM_lifetime;
            app.lifetime_HS = app.lifetime_HS.HS_lifetime;
            app.feasibility_air = app.feasibility_air.air_feasibility; app.feasibility_geothermal = app.feasibility_geothermal.geo_feasibility;
            app.hload_max = app.hload_max.saved_sim_hload_max;
            app.length_probe = app.length_probe.probe_length;
            app.GHG_be = app.GHG_be.BE_ghg; app.GHG_hs = app.GHG_hs.HS_ghg; app.GHG_probe = app.GHG_probe.PROBE_ghg;
            app.PENR_be = app.PENR_be.BE_penr; app.PENR_hs = app.PENR_hs.HS_penr; app.PENR_probe = app.PENR_probe.PROBE_penr;
            
            % Development of energy prices
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: HeatingoilEditField
        function HeatingoilEditFieldValueChanged(app, event)
            app.p_oil = app.HeatingoilEditField.Value;
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: DistrictheatEditField
        function DistrictheatEditFieldValueChanged(app, event)
            app.p_dh = app.DistrictheatEditField.Value;
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: ElectricityEditField
        function ElectricityEditFieldValueChanged(app, event)
            app.p_el = app.ElectricityEditField.Value;
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: PelletsEditField
        function PelletsEditFieldValueChanged(app, event)
            app.p_wood = app.PelletsEditField.Value;
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: HeatingoilSlider
        function HeatingoilSliderValueChanged(app, event)
           app.r_oil = app.HeatingoilSlider.Value;
           app.CompleteLamp.Color = 'r';
           
           app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: PelletsSlider
        function PelletsSliderValueChanged(app, event)
            app.r_wood = app.PelletsSlider.Value;
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: DistrictheatSlider
        function DistrictheatSliderValueChanged(app, event)
            app.r_dh = app.DistrictheatSlider.Value;
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: ElectricitySlider
        function ElectricitySliderValueChanged(app, event)
            app.r_el = app.ElectricitySlider.Value;
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end

        % Value changed function: RealinterestrateSlider
        function RealinterestrateSliderValueChanged(app, event)
            app.r = app.RealinterestrateSlider.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: HeatingoilEditField_2
        function HeatingoilEditField_2ValueChanged(app, event)
            app.ghg_oil = app.HeatingoilEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: DistrictheatEditField_2
        function DistrictheatEditField_2ValueChanged(app, event)
            app.ghg_dh = app.DistrictheatEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: ElectricityEditField_2
        function ElectricityEditField_2ValueChanged(app, event)
            app.ghg_el = app.ElectricityEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: PelletsEditField_2
        function PelletsEditField_2ValueChanged(app, event)
            app.ghg_wood = app.PelletsEditField_2.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: HeatingoilEditField_3
        function HeatingoilEditField_3ValueChanged(app, event)
            app.penr_oil = app.HeatingoilEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: DistrictheatEditField_3
        function DistrictheatEditField_3ValueChanged(app, event)
            app.penr_dh = app.DistrictheatEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: ElectricityEditField_3
        function ElectricityEditField_3ValueChanged(app, event)
            app.penr_el = app.ElectricityEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Value changed function: PelletsEditField_3
        function PelletsEditField_3ValueChanged(app, event)
            app.penr_wood = app.PelletsEditField_3.Value;
            app.CompleteLamp.Color = 'r';
        end

        % Button pushed function: CalculationButton_2
        function CalculationButton_2Pushed(app, event)
            P_e = [app.p_oil,app.p_el,app.p_el,app.p_dh,app.p_wood];                            % Allocation of energy prices
            R_e = [app.r_oil,app.r_el,app.r_el,app.r_dh,app.r_wood]/100;                        % Allocation of energy price changes
            
            % Investment costs [CHF]
            RS_invest = zeros(16,5);
            for i=1:5
                for j=1:16
                    RS_now = app.RS{j,i};
                    for k=1:5
                        RS_invest(j,i) = RS_invest(j,i)+RS_now(1,k)*app.invest_single_BE(k);
                        RS_invest(j,i) = RS_invest(j,i)+RS_now(2,k)*app.invest_single_MM(k);
                        RS_invest(j,i) = RS_invest(j,i)+RS_now(3,k)*app.invest_HS(k,1)+RS_now(3,k)*app.hload_max(j)/1000*app.invest_HS(k,2);
                    end
                end
            end
            RS_invest_table = RS_invest;
            
            % Operational and maintenance (O&M) costs [CHF/a]
            RS_om = cell([16 5]); RS_om_average = zeros(16,5);
            for i=1:5
                for j=1:16
                    RS_now = app.RS{j,i};
                    om_costs = 0;
                    for k=1:5
                        om_costs = om_costs+RS_now(3,k)*app.OM_HS(k,1)+RS_now(3,k)*app.hload_max(j)/1000*app.OM_HS(k,2);
                    end
                    RS_om{j,i} = ones(app.t_period,1)*om_costs;
                    RS_om_average(j,i) = om_costs;
                end
            end
            
            % Energy costs (for each year in t_period) [CHF/a]
            RS_energy = cell([16 5]); RS_running = zeros(16,5);
            for i=1:5
                for j=1:16
                    e_costs = zeros(app.t_period,1);
                    for k=1:app.t_period
                        e_costs(k) = app.E_final(j,i)*P_e(i)*(1+R_e(i))^k;
                    end
                    RS_energy{j,i} = e_costs;
                    if i==2 && app.feasibility_geothermal(j)==0
                        RS_running(j,i) = NaN;
                        RS_invest_table(j,i) = NaN;
                    elseif i==3 && app.feasibility_air(j)==0
                        RS_running(j,i) = NaN;
                        RS_invest_table(j,i) = NaN;
                    else
                        RS_running(j,i) = RS_om_average(j,i)+mean(e_costs);
                    end
                end
            end
            
            % UI: Cost overview, Running costs, table
            app.running_costs.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Oil boiler';'Geothermal heat pump';'Air-to-water heat pump';'District heating';'Pellet boiler'};
            app.running_costs.ColumnFormat = {'logical','logical','logical','logical','numeric','numeric','numeric','numeric','numeric'};
            app.running_costs.Data = [app.Roof,app.Walls,app.Cellar_ceiling,app.Windows,RS_running(:,1),RS_running(:,2),RS_running(:,3),RS_running(:,4),RS_running(:,5)];
            
            
            % Replacement costs (for each year in t_period) [CHF/a]
            RS_replace = cell([16 5]);
            for i=1:5
                for j=1:16
                    RS_now = app.RS{j,i};
                    replace_costs = zeros(app.t_period,1);
                    for k=1:app.t_period
                        for l=1:5
                            if mod(k,RS_now(1,l)*app.lifetime_BE(l))==0
                                replace_costs(k) = replace_costs(k)+app.invest_single_BE(l);
                            end
                            if mod(k,RS_now(2,l)*app.lifetime_MM(l))==0
                                replace_costs(k) = replace_costs(k)+app.invest_single_MM(l);
                            end
                            if mod(k,RS_now(3,l)*app.lifetime_HS(l))==0
                                replace_costs(k) = replace_costs(k)+app.invest_HS(l,1)+app.hload_max(j)/1000*app.invest_HS(l,2);
                            end
                        end
                    end
                    RS_replace{j,i} = replace_costs;
                end
            end
            
            % Residual values (after t_period) [CHF]
            RS_res = zeros(16,5);
            for i=1:5
                for j=1:16
                    RS_now = app.RS{j,i};
                    for k=1:5
                        if k==5
                            n3 = floor(app.t_period/app.lifetime_HS(k));
                            RS_res(j,i) = RS_res(j,i)+(RS_now(3,k)*app.invest_HS(k,1)+RS_now(3,k)*app.hload_max(j)/1000*app.invest_HS(k,2))*(1-(app.t_period-n3*app.lifetime_HS(k))/app.lifetime_HS(k));
                        else
                            n1 = floor(app.t_period/app.lifetime_BE(k));
                            RS_res(j,i) = RS_res(j,i)+RS_now(1,k)*app.invest_single_BE(k)*(1-(app.t_period-n1*app.lifetime_BE(k))/app.lifetime_BE(k));
                            n2 = floor(app.t_period/app.lifetime_MM(k));
                            RS_res(j,i) = RS_res(j,i)+RS_now(2,k)*app.invest_single_MM(k)*(1-(app.t_period-n2*app.lifetime_MM(k))/app.lifetime_MM(k));
                            n3 = floor(app.t_period/app.lifetime_HS(k));
                            RS_res(j,i) = RS_res(j,i)+(RS_now(3,k)*app.invest_HS(k,1)+RS_now(3,k)*app.hload_max(j)/1000*app.invest_HS(k,2))*(1-(app.t_period-n3*app.lifetime_HS(k))/app.lifetime_HS(k));
                        end
                    end
                end
            end
            
            % Global costs (= net present costs) [CHF]
            RS_C_global = zeros(16,5);
            for i=1:5
                for j=1:16
                    RS_C_global(j,i) = RS_invest(j,i)+pvvar([0;RS_energy{j,i}],app.r/100)+pvvar([0;RS_om{j,i}],app.r/100)+pvvar([0;RS_replace{j,i}],app.r/100)-RS_res(j,i)/(1+app.r/100)^app.t_period;
                end
            end
            
            Oil_boiler = RS_C_global(:,1);
            Geothermal_heat_pump = NaN(16,1);
            Air_to_water_heat_pump = NaN(16,1);
            for i=1:16                                                      % Check whether heat pumps are feasible (whether T_supply_max can be achieved)
                if app.feasibility_geothermal(i)==1
                    Geothermal_heat_pump(i) = RS_C_global(i,2);
                else
                    RS_C_global(i,2) = NaN;
                end
                if app.feasibility_air(i)==1
                    Air_to_water_heat_pump(i) = RS_C_global(i,3);
                else
                    RS_C_global(i,3) = NaN;
                end
            end
            District_heating = RS_C_global(:,4);
            Pellet_boiler = RS_C_global(:,5);
            
            % UI: Cost overview, Investment costs, table
            app.investment_costs.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Oil boiler';'Geothermal heat pump';'Air-to-water heat pump';'District heating';'Pellet boiler'};
            app.investment_costs.ColumnFormat = {'logical','logical','logical','logical','numeric','numeric','numeric','numeric','numeric'};
            app.investment_costs.Data = [app.Roof,app.Walls,app.Cellar_ceiling,app.Windows,RS_invest_table(:,1),RS_invest_table(:,2),RS_invest_table(:,3),RS_invest_table(:,4),RS_invest_table(:,5)];
            
            % UI: Detailed results, Cost-effectivness, large table
            app.global_costs.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Oil boiler';'Geothermal heat pump';'Air-to-water heat pump';'District heating';'Pellet boiler'};
            app.global_costs.ColumnFormat = {'logical','logical','logical','logical','numeric','numeric','numeric','numeric','numeric'};
            app.global_costs.Data = [app.Roof,app.Walls,app.Cellar_ceiling,app.Windows,Oil_boiler,Geothermal_heat_pump,Air_to_water_heat_pump,District_heating,Pellet_boiler];
            
            index_min = zeros(5,1); investment = zeros(5,1); energy = zeros(5,1); o_and_m = zeros(5,1); global_cost = zeros(5,1);
            for i=1:5
                [~,index_min(i)] = min(RS_C_global(:,i));
                investment(i) = RS_invest(index_min(i),i)+pvvar([0;RS_replace{index_min(i),i}],app.r/100)-RS_res(index_min(i),i)/(1+app.r/100)^app.t_period;
                energy(i) = pvvar([0;RS_energy{index_min(i),i}],app.r/100);
                o_and_m(i) = pvvar([0;RS_om{index_min(i),i}],app.r/100);
                global_cost(i) = RS_C_global(index_min(i),i);
            end
            
            % UI: Detailed results, Cost-effectivness, small table
            app.UITable.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Global cost'};
            app.UITable.RowName = {'Oil';'Geo. HP';'Air. HP';'DH';'Pellet'};
            app.UITable.ColumnFormat = {'logical','logical','logical','logical','numeric'};
            app.UITable.Data = [app.Roof(index_min(:)),app.Walls(index_min(:)),app.Cellar_ceiling(index_min(:)),app.Windows(index_min(:)),global_cost];
            
            % UI: Detailed results, Cost-effectivness, bar chart
            bar(app.UIAxes2,1:5,[investment,energy,o_and_m],'stacked')
            app.UIAxes2.XTickLabel = {'Oil','Geo. HP','Air. HP','DH','Pellet'};
            legend(app.UIAxes2,'Investment costs','Energy costs','O&M costs');
            app.UIAxes2.Legend.Location = 'northwest';
            
            % Cumulative costs [CHF]
            RS_C_cum = cell([16 5]);
            for i=1:5
                for j=1:16
                    cum_costs = zeros(app.t_period,1);
                    cum_costs(1) = RS_invest(j,i)+RS_energy{j,i}(1)+RS_om{j,i}(1)+RS_replace{j,i}(1);
                    for k=2:app.t_period
                        cum_costs(k) = cum_costs(k-1)+RS_energy{j,i}(k)+RS_om{j,i}(k)+RS_replace{j,i}(k);
                    end
                    RS_C_cum{j,i} = cum_costs;
                end
            end
            
            %% Lifecycle analysis (LCA)
            % Input data
            ghg = [app.ghg_oil,app.ghg_el,app.ghg_el,app.ghg_dh,app.ghg_wood];                                  % Allocation of emission factors
            penr = [app.penr_oil,app.penr_el,app.penr_el,app.penr_dh,app.penr_wood];                            % Allocation of energy factors
            
            % Grey EI [kg CO2-eq/a or kWh/a]
            RS_grey_ghg = zeros(16,5); RS_grey_penr = zeros(16,5);
            for i=1:5
                for j=1:16
                    RS_now = app.RS{j,i};
                    for k=1:5
                        if k==5
                            RS_grey_ghg(j,i) = RS_grey_ghg(j,i)+RS_now(3,k)*app.GHG_hs(k)/app.lifetime_HS(k);
                            RS_grey_penr(j,i) = RS_grey_penr(j,i)+RS_now(3,k)*app.PENR_hs(k)/app.lifetime_HS(k);
                        else
                            RS_grey_ghg(j,i) = RS_grey_ghg(j,i)+RS_now(1,k)*app.GHG_be(k)/app.lifetime_BE(k);
                            RS_grey_penr(j,i) = RS_grey_penr(j,i)+RS_now(1,k)*app.PENR_be(k)/app.lifetime_BE(k);
                            RS_grey_ghg(j,i) = RS_grey_ghg(j,i)+RS_now(3,k)*app.GHG_hs(k)/app.lifetime_HS(k);
                            RS_grey_penr(j,i) = RS_grey_penr(j,i)+RS_now(3,k)*app.PENR_hs(k)/app.lifetime_HS(k);
                        end
                    end
                    if RS_now(3,2)==1
                        RS_grey_ghg(j,i) = RS_grey_ghg(j,i)+app.length_probe(j)*app.GHG_probe/app.lifetime_HS(2);
                        RS_grey_penr(j,i) = RS_grey_penr(j,i)+app.length_probe(j)*app.PENR_probe/app.lifetime_HS(2);
                    end
                end
            end
            
            % EI during operation [kg CO2-eq/a or kWh/a]
            RS_op_ghg = zeros(16,5); RS_op_penr = zeros(16,5);
            for i=1:5
                for j=1:16
                    RS_op_ghg(j,i) = app.E_final(j,i)*ghg(i);
                    RS_op_penr(j,i) = app.E_final(j,i)*penr(i);
                end
            end
            
            % Total EI [kg CO2-eq/a or kWh/a]
            RS_ghg = RS_grey_ghg+RS_op_ghg;
            RS_penr = RS_grey_penr+RS_op_penr;
            
            Oil_boiler = RS_ghg(:,1);
            Geothermal_heat_pump = NaN(16,1);
            Air_to_water_heat_pump = NaN(16,1);
            for i=1:16                                                      % Check whether heat pumps are feasible (whether T_supply_max can be achieved)
                if app.feasibility_geothermal(i)==1
                    Geothermal_heat_pump(i) = RS_ghg(i,2);
                else
                    RS_ghg(i,2) = NaN;
                end
                if app.feasibility_air(i)==1
                    Air_to_water_heat_pump(i) = RS_ghg(i,3);
                else
                    RS_ghg(i,3) = NaN;
                end
            end
            District_heating = RS_ghg(:,4);
            Pellet_boiler = RS_ghg(:,5);
            
            % UI: Detailed results, GHG emissions, large table
            app.ghg_emissions.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Oil boiler';'Geothermal heat pump';'Air-to-water heat pump';'District heating';'Pellet boiler'};
            app.ghg_emissions.ColumnFormat = {'logical','logical','logical','logical','numeric','numeric','numeric','numeric','numeric'};
            app.ghg_emissions.Data = [app.Roof,app.Walls,app.Cellar_ceiling,app.Windows,Oil_boiler,Geothermal_heat_pump,Air_to_water_heat_pump,District_heating,Pellet_boiler];
            
            index_min = zeros(5,1); production = zeros(5,1); operation = zeros(5,1); ghg_tot = zeros(5,1);
            for i=1:5
                [~,index_min(i)] = min(RS_ghg(:,i));
                production(i) = RS_grey_ghg(index_min(i),i);
                operation(i) = RS_op_ghg(index_min(i),i);
                ghg_tot(i) = RS_ghg(index_min(i),i);
            end
            
            % UI: Detailed results, GHG emissions, small table
            app.UITable_2.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'GHG'};
            app.UITable_2.RowName = {'Oil';'Geo. HP';'Air. HP';'DH';'Pellet'};
            app.UITable_2.ColumnFormat = {'logical','logical','logical','logical','numeric'};
            app.UITable_2.Data = [app.Roof(index_min(:)),app.Walls(index_min(:)),app.Cellar_ceiling(index_min(:)),app.Windows(index_min(:)),ghg_tot];
            
            % UI: Detailed results, GHG emissions, bar chart
            bar(app.UIAxes2_2,1:5,[production,operation],'stacked')
            app.UIAxes2_2.XTickLabel = {'Oil','Geo. HP','Air. HP','DH','Pellet'};
            legend(app.UIAxes2_2,'Production & disposal','Operation');            
            
            Oil_boiler = RS_penr(:,1);
            Geothermal_heat_pump = NaN(16,1);
            Air_to_water_heat_pump = NaN(16,1);
            for i=1:16                                                      % Check whether heat pumps are feasible (whether T_supply_max can be achieved)
                if app.feasibility_geothermal(i)==1
                    Geothermal_heat_pump(i) = RS_penr(i,2);
                else
                    RS_penr(i,2) = NaN;
                end
                if app.feasibility_air(i)==1
                    Air_to_water_heat_pump(i) = RS_penr(i,3);
                else
                    RS_penr(i,3) = NaN;
                end
            end
            District_heating = RS_penr(:,4);
            Pellet_boiler = RS_penr(:,5);
            
            % UI: Detailed results, PENR consumption, large table
            app.penr_consumption.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Oil boiler';'Geothermal heat pump';'Air-to-water heat pump';'District heating';'Pellet boiler'};
            app.penr_consumption.ColumnFormat = {'logical','logical','logical','logical','numeric','numeric','numeric','numeric','numeric'};
            app.penr_consumption.Data = [app.Roof,app.Walls,app.Cellar_ceiling,app.Windows,Oil_boiler,Geothermal_heat_pump,Air_to_water_heat_pump,District_heating,Pellet_boiler];
            
            index_min = zeros(5,1); production = zeros(5,1); operation = zeros(5,1); penr_tot = zeros(5,1);
            for i=1:5
                [~,index_min(i)] = min(RS_penr(:,i));
                production(i) = RS_grey_penr(index_min(i),i);
                operation(i) = RS_op_penr(index_min(i),i);
                penr_tot(i) = RS_penr(index_min(i),i);
            end
            
            % UI: Detailed results, PENR consumption, small table
            app.UITable_3.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'PENR'};
            app.UITable_3.RowName = {'Oil';'Geo. HP';'Air. HP';'DH';'Pellet'};
            app.UITable_3.ColumnFormat = {'logical','logical','logical','logical','numeric'};
            app.UITable_3.Data = [app.Roof(index_min(:)),app.Walls(index_min(:)),app.Cellar_ceiling(index_min(:)),app.Windows(index_min(:)),penr_tot];
            
            % UI: Detailed results, PENR consumption, bar chart
            bar(app.UIAxes2_3,1:5,[production,operation],'stacked')
            app.UIAxes2_3.XTickLabel = {'Oil','Geo. HP','Air. HP','DH','Pellet'};
            legend(app.UIAxes2_3,'Production & disposal','Operation'); 
            
            %% LCC and LCA combined: marginal abatement cost
            % Additional costs compared to the reference case divided by the
            % environmental impacts saved [CHF/kg CO2-eq_saved or CHF/kWh_saved]
            
            RS_MAC_ghg = zeros(16,5); RS_MAC_penr = zeros(16,5);
            for i=1:5
                for j=1:16
                    if i==1 && j==1
                        RS_MAC_ghg(j,i) = Inf;
                        RS_MAC_penr(j,i) = Inf;
                    elseif isnan(RS_C_global(j,i))==false
                        RS_MAC_ghg(j,i) = (RS_C_global(j,i)-RS_C_global(1,1))/(RS_ghg(1,1)-RS_ghg(j,i));
                        RS_MAC_penr(j,i) = (RS_C_global(j,i)-RS_C_global(1,1))/(RS_penr(1,1)-RS_penr(j,i));
                    else
                        RS_MAC_ghg(j,i) = NaN;
                        RS_MAC_penr(j,i) = NaN;
                    end
                end
            end
            
            Oil_boiler = RS_MAC_ghg(:,1);
            Geothermal_heat_pump = NaN(16,1);
            Air_to_water_heat_pump = NaN(16,1);
            for i=1:16                                                      % Check whether heat pumps are feasible (whether T_supply_max can be achieved)
                if app.feasibility_geothermal(i)==1
                    Geothermal_heat_pump(i) = RS_MAC_ghg(i,2);
                end
                if app.feasibility_air(i)==1
                    Air_to_water_heat_pump(i) = RS_MAC_ghg(i,3);
                end
            end
            District_heating = RS_MAC_ghg(:,4);
            Pellet_boiler = RS_MAC_ghg(:,5);
            
            % UI: Detailed results, Marginal abatement costs of abating GHG emissions, large table
            app.mac_ghg.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Oil boiler';'Geothermal heat pump';'Air-to-water heat pump';'District heating';'Pellet boiler'};
            app.mac_ghg.ColumnFormat = {'logical','logical','logical','logical','numeric','numeric','numeric','numeric','numeric'};
            app.mac_ghg.Data = [app.Roof,app.Walls,app.Cellar_ceiling,app.Windows,Oil_boiler,Geothermal_heat_pump,Air_to_water_heat_pump,District_heating,Pellet_boiler];
            
            index_min = zeros(5,1); min_mac_ghg = zeros(5,1);
            for i=1:5
                [~,index_min(i)] = min(RS_MAC_ghg(:,i));
                min_mac_ghg(i) = RS_MAC_ghg(index_min(i),i);
            end
            
            % UI: Detailed results, MAC ghg emissions, small table
            app.UITable_4.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'MAC'};
            app.UITable_4.RowName = {'Oil';'Geo. HP';'Air. HP';'DH';'Pellet'};
            app.UITable_4.ColumnFormat = {'logical','logical','logical','logical','numeric'};
            app.UITable_4.Data = [app.Roof(index_min(:)),app.Walls(index_min(:)),app.Cellar_ceiling(index_min(:)),app.Windows(index_min(:)),min_mac_ghg];
            
            % UI: Detailed results, MAC ghg emissions, bar chart
            bar(app.UIAxes2_4,1:5,min_mac_ghg)
            app.UIAxes2_4.XTickLabel = {'Oil','Geo. HP','Air. HP','DH','Pellet'};            
            
            Oil_boiler = RS_MAC_penr(:,1);
            Geothermal_heat_pump = NaN(16,1);
            Air_to_water_heat_pump = NaN(16,1);
            for i=1:16                                                      % Check whether heat pumps are feasible (whether T_supply_max can be achieved)
                if app.feasibility_geothermal(i)==1
                    Geothermal_heat_pump(i) = RS_MAC_penr(i,2);
                end
                if app.feasibility_air(i)==1
                    Air_to_water_heat_pump(i) = RS_MAC_penr(i,3);
                end
            end
            District_heating = RS_MAC_penr(:,4);
            Pellet_boiler = RS_MAC_penr(:,5);
            
            % UI: Detailed results, Marginal abatement costs of abating PENR consumption, large table
            app.mac_penr.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'Oil boiler';'Geothermal heat pump';'Air-to-water heat pump';'District heating';'Pellet boiler'};
            app.mac_penr.ColumnFormat = {'logical','logical','logical','logical','numeric','numeric','numeric','numeric','numeric'};
            app.mac_penr.Data = [app.Roof,app.Walls,app.Cellar_ceiling,app.Windows,Oil_boiler,Geothermal_heat_pump,Air_to_water_heat_pump,District_heating,Pellet_boiler];
            
            index_min = zeros(5,1); min_mac_penr = zeros(5,1);
            for i=1:5
                [~,index_min(i)] = min(RS_MAC_penr(:,i));
                min_mac_penr(i) = RS_MAC_penr(index_min(i),i);
            end
            
            % UI: Detailed results, MAC penr, small table
            app.UITable_5.ColumnName = {'Roof';'Walls';'Cellar ceiling';'Windows';'MAC'};
            app.UITable_5.RowName = {'Oil';'Geo. HP';'Air. HP';'DH';'Pellet'};
            app.UITable_5.ColumnFormat = {'logical','logical','logical','logical','numeric'};
            app.UITable_5.Data = [app.Roof(index_min(:)),app.Walls(index_min(:)),app.Cellar_ceiling(index_min(:)),app.Windows(index_min(:)),min_mac_penr];
            
            % UI: Detailed results, MAC penr, bar chart
            bar(app.UIAxes2_5,1:5,min_mac_penr)
            app.UIAxes2_5.XTickLabel = {'Oil','Geo. HP','Air. HP','DH','Pellet'};
            
            %% Summary
            % Cost-optimality
            [co_row,co_col] = find(RS_C_global==min(min(RS_C_global)));
            if co_col==1
                hs_co = 'Oil boiler';
            elseif co_col==2
                hs_co = 'Geothermal heat pump';
            elseif co_col==3
                hs_co = 'Air-to-water heat pump';
            elseif co_col==4
                hs_co = 'District heating';
            elseif co_col==5
                hs_co = 'Pellet boiler';
            end
            
            % UI: Results, Cost-optimal
            app.cost_optimal.Data = {hs_co,app.Roof(co_row),app.Walls(co_row),app.Cellar_ceiling(co_row),app.Windows(co_row)};
            app.GlobalcostCHFEditField.Value = RS_C_global(co_row,co_col);
            app.GHGemissionskgCO2eqaGauge.Value = RS_ghg(co_row,co_col);
            app.GHGemissionskgCO2eqaGauge_6.Value = RS_penr(co_row,co_col);
            
            % Environmental impact: GHG emissions
            [ghg_row,ghg_col] = find(RS_ghg==min(min(RS_ghg)));
            if ghg_col==1
                hs_ghg = 'Oil boiler';
            elseif ghg_col==2
                hs_ghg = 'Geothermal heat pump';
            elseif ghg_col==3
                hs_ghg = 'Air-to-water heat pump';
            elseif ghg_col==4
                hs_ghg = 'District heating';
            elseif ghg_col==5
                hs_ghg = 'Pellet boiler';
            end
            
            % UI: Results, Min. GHG emissions
            app.minimum_ghg.Data = {hs_ghg,app.Roof(ghg_row),app.Walls(ghg_row),app.Cellar_ceiling(ghg_row),app.Windows(ghg_row)};
            app.GlobalcostCHFEditField_2.Value = RS_C_global(ghg_row,ghg_col);
            app.GHGemissionskgCO2eqaGauge_2.Value = RS_ghg(ghg_row,ghg_col);
            app.GHGemissionskgCO2eqaGauge_7.Value = RS_penr(ghg_row,ghg_col);
            
            % Environmental impact: PENR
            [penr_row,penr_col] = find(RS_penr==min(min(RS_penr)));
            if penr_col==1
                hs_penr = 'Oil boiler';
            elseif penr_col==2
                hs_penr = 'Geothermal heat pump';
            elseif penr_col==3
                hs_penr = 'Air-to-water heat pump';
            elseif penr_col==4
                hs_penr = 'District heating';
            elseif penr_col==5
                hs_penr = 'Pellet boiler';
            end
            
            % UI: Results, Min. PENR consumption
            app.minimum_penr.Data = {hs_penr,app.Roof(penr_row),app.Walls(penr_row),app.Cellar_ceiling(penr_row),app.Windows(penr_row)};
            app.GlobalcostCHFEditField_3.Value = RS_C_global(penr_row,penr_col);
            app.GHGemissionskgCO2eqaGauge_3.Value = RS_ghg(penr_row,penr_col);
            app.GHGemissionskgCO2eqaGauge_8.Value = RS_penr(penr_row,penr_col);
            
            % Cost-efficiency: Abatement of GHG emissiosn
            [mac_ghg_row,mac_ghg_col] = find(RS_MAC_ghg==min(min(RS_MAC_ghg)));
            if mac_ghg_col==1
                hs_mac_ghg = 'Oil boiler';
            elseif mac_ghg_col==2
                hs_mac_ghg = 'Geothermal heat pump';
            elseif mac_ghg_col==3
                hs_mac_ghg = 'Air-to-water heat pump';
            elseif mac_ghg_col==4
                hs_mac_ghg = 'District heating';
            elseif mac_ghg_col==5
                hs_mac_ghg = 'Pellet boiler';
            end
            
            % UI: Results, MAC GHG emissions
            app.cost_efficient_ghg.Data = {hs_mac_ghg,app.Roof(mac_ghg_row),app.Walls(mac_ghg_row),app.Cellar_ceiling(mac_ghg_row),app.Windows(mac_ghg_row)};
            app.GlobalcostCHFEditField_4.Value = RS_C_global(mac_ghg_row,mac_ghg_col);
            app.GHGemissionskgCO2eqaGauge_4.Value = RS_ghg(mac_ghg_row,mac_ghg_col);
            app.GHGemissionskgCO2eqaGauge_9.Value = RS_penr(mac_ghg_row,mac_ghg_col);
            
            % Cost-efficiency: Abatement of PENR
            [mac_penr_row,mac_penr_col] = find(RS_MAC_penr==min(min(RS_MAC_penr)));
            if mac_penr_col==1
                hs_mac_penr = 'Oil boiler';
            elseif mac_penr_col==2
                hs_mac_penr = 'Geothermal heat pump';
            elseif mac_penr_col==3
                hs_mac_penr = 'Air-to-water heat pump';
            elseif mac_penr_col==4
                hs_mac_penr = 'District heating';
            elseif mac_penr_col==5
                hs_mac_penr = 'Pellet boiler';
            end
            
            % UI: Results, MAC PENR consumption
            app.cost_efficient_penr.Data = {hs_mac_penr,app.Roof(mac_penr_row),app.Walls(mac_penr_row),app.Cellar_ceiling(mac_penr_row),app.Windows(mac_penr_row)};
            app.GlobalcostCHFEditField_5.Value = RS_C_global(mac_penr_row,mac_penr_col);
            app.GHGemissionskgCO2eqaGauge_5.Value = RS_ghg(mac_penr_row,mac_penr_col);
            app.GHGemissionskgCO2eqaGauge_10.Value = RS_penr(mac_penr_row,mac_penr_col);
            
            % UI: Timeline
            plot(app.UIAxes,0:app.t_period,[RS_invest(co_row,co_col);RS_C_cum{co_row,co_col}],0:app.t_period,[RS_invest(ghg_row,ghg_col);RS_C_cum{ghg_row,ghg_col}],'-o',0:app.t_period,[RS_invest(penr_row,penr_col);RS_C_cum{penr_row,penr_col}],'-+',0:app.t_period,[RS_invest(mac_ghg_row,mac_ghg_col);RS_C_cum{mac_ghg_row,mac_ghg_col}],'-*',0:app.t_period,[RS_invest(mac_penr_row,mac_penr_col);RS_C_cum{mac_penr_row,mac_penr_col}],'-x','LineWidth',2);
            legend(app.UIAxes,'Cost-optimal','Minimum GHG emissions','Minimum PENR consumption','Most cost-efficient GHG emissions reduction','Most cost-efficient PENR consumption reduction')
            app.UIAxes.Legend.Location = 'northwest';
            
            % UI: Plots, Global costs vs. GHG emissions
            plot(app.UIAxes4,RS_ghg(:,1),RS_C_global(:,1),'.',RS_ghg(:,2),RS_C_global(:,2),'.',RS_ghg(:,3),RS_C_global(:,3),'.',RS_ghg(:,4),RS_C_global(:,4),'.',RS_ghg(:,5),RS_C_global(:,5),'.','MarkerSize',30)
            legend(app.UIAxes4,'Oil boiler','Geothermal heat pump','Air-to-water heat pump','District heating','Pellet boiler')
            
            % UI: Plots, Global costs vs. PENR consumption
            plot(app.UIAxes4_2,RS_penr(:,1),RS_C_global(:,1),'.',RS_penr(:,2),RS_C_global(:,2),'.',RS_penr(:,3),RS_C_global(:,3),'.',RS_penr(:,4),RS_C_global(:,4),'.',RS_penr(:,5),RS_C_global(:,5),'.','MarkerSize',30)
            legend(app.UIAxes4_2,'Oil boiler','Geothermal heat pump','Air-to-water heat pump','District heating','Pellet boiler')
            
            app.CompleteLamp.Color = 'g';            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.t_period = str2double(app.DropDown.Value);
            app.CompleteLamp.Color = 'r';
            
            app.Price_oil = zeros(app.t_period,1); app.Price_dh = zeros(app.t_period,1); app.Price_el = zeros(app.t_period,1); app.Price_wood = zeros(app.t_period,1);
            for i=1:app.t_period
                app.Price_oil(i) = app.p_oil*(1+app.r_oil/100)^i;
                app.Price_dh(i) = app.p_dh*(1+app.r_dh/100)^i;
                app.Price_el(i) = app.p_el*(1+app.r_el/100)^i;
                app.Price_wood(i) = app.p_wood*(1+app.r_wood/100)^i;
            end
            app.Price_oil = [app.p_oil;app.Price_oil]; app.Price_dh = [app.p_dh;app.Price_dh]; app.Price_el = [app.p_el;app.Price_el]; app.Price_wood = [app.p_wood;app.Price_wood];
            plot(app.UIAxes6,0:app.t_period,app.Price_oil,0:app.t_period,app.Price_dh,0:app.t_period,app.Price_el,0:app.t_period,app.Price_wood,'LineWidth',2)
            legend(app.UIAxes6,'Oil','District heat','Electricity','Pellets')
            ylim(app.UIAxes6,[0 0.5])
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.RetrofitApp2Label = uilabel(app.Tab);
            app.RetrofitApp2Label.Text = 'Retrofit App 2';
            app.LifecyclecostingLifecycleassessmentLabel = uilabel(app.Tab);
            app.LifecyclecostingLifecycleassessmentLabel.Text = {'- Life cycle costing'; '- Life cycle assessment'};
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.FinancialparametersPanel = uipanel(app.SettingsTab);
            app.FinancialparametersPanel.Title = 'Financial parameters';
            app.EnergypricesCHFkWhPanel = uipanel(app.FinancialparametersPanel);
            app.EnergypricesCHFkWhPanel.Title = 'Energy prices [CHF/kWh]';
            app.HeatingoilLabel = uilabel(app.EnergypricesCHFkWhPanel);
            app.HeatingoilLabel.Text = 'Heating oil:';
            app.HeatingoilEditField = uieditfield(app.EnergypricesCHFkWhPanel, 'numeric');
            app.HeatingoilEditField.Limits = [0 0.5];
            app.HeatingoilEditField.ValueChangedFcn = createCallbackFcn(app, @HeatingoilEditFieldValueChanged, true);
            app.HeatingoilEditField.Value = 0.1;
            app.DistrictheatLabel = uilabel(app.EnergypricesCHFkWhPanel);
            app.DistrictheatLabel.Text = 'District heat:';
            app.DistrictheatEditField = uieditfield(app.EnergypricesCHFkWhPanel, 'numeric');
            app.DistrictheatEditField.Limits = [0 0.5];
            app.DistrictheatEditField.ValueChangedFcn = createCallbackFcn(app, @DistrictheatEditFieldValueChanged, true);
            app.DistrictheatEditField.Value = 0.09;
            app.ElectricityLabel = uilabel(app.EnergypricesCHFkWhPanel);
            app.ElectricityLabel.Text = 'Electricity:';
            app.ElectricityEditField = uieditfield(app.EnergypricesCHFkWhPanel, 'numeric');
            app.ElectricityEditField.Limits = [0 0.5];
            app.ElectricityEditField.ValueChangedFcn = createCallbackFcn(app, @ElectricityEditFieldValueChanged, true);
            app.ElectricityEditField.Value = 0.21;
            app.PelletsLabel = uilabel(app.EnergypricesCHFkWhPanel);
            app.PelletsLabel.Text = 'Pellets:';
            app.PelletsEditField = uieditfield(app.EnergypricesCHFkWhPanel, 'numeric');
            app.PelletsEditField.Limits = [0 0.5];
            app.PelletsEditField.ValueChangedFcn = createCallbackFcn(app, @PelletsEditFieldValueChanged, true);
            app.PelletsEditField.Value = 0.08;
            app.AnnualchangeinenergypricesPanel = uipanel(app.FinancialparametersPanel);
            app.AnnualchangeinenergypricesPanel.Title = 'Annual change in energy prices [%]';
            app.HeatingoilLabel_2 = uilabel(app.AnnualchangeinenergypricesPanel);
            app.HeatingoilLabel_2.Text = 'Heating oil:';
            app.HeatingoilSlider = uislider(app.AnnualchangeinenergypricesPanel);
            app.HeatingoilSlider.Limits = [-3 3];
            app.HeatingoilSlider.ValueChangedFcn = createCallbackFcn(app, @HeatingoilSliderValueChanged, true);
            app.HeatingoilSlider.MinorTicks = [-3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.PelletsLabel_2 = uilabel(app.AnnualchangeinenergypricesPanel);
            app.PelletsLabel_2.Text = 'Pellets:';
            app.PelletsSlider = uislider(app.AnnualchangeinenergypricesPanel);
            app.PelletsSlider.Limits = [-3 3];
            app.PelletsSlider.ValueChangedFcn = createCallbackFcn(app, @PelletsSliderValueChanged, true);
            app.PelletsSlider.MinorTicks = [-3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.ElectricityLabel_2 = uilabel(app.AnnualchangeinenergypricesPanel);
            app.ElectricityLabel_2.Text = 'Electricity:';
            app.ElectricitySlider = uislider(app.AnnualchangeinenergypricesPanel);
            app.ElectricitySlider.Limits = [-3 3];
            app.ElectricitySlider.ValueChangedFcn = createCallbackFcn(app, @ElectricitySliderValueChanged, true);
            app.ElectricitySlider.MinorTicks = [-3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.DistrictheatLabel_2 = uilabel(app.AnnualchangeinenergypricesPanel);
            app.DistrictheatLabel_2.Text = 'District heat:';
            app.DistrictheatSlider = uislider(app.AnnualchangeinenergypricesPanel);
            app.DistrictheatSlider.Limits = [-3 3];
            app.DistrictheatSlider.ValueChangedFcn = createCallbackFcn(app, @DistrictheatSliderValueChanged, true);
            app.DistrictheatSlider.MinorTicks = [-3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3];
            app.RealinterestratePanel = uipanel(app.FinancialparametersPanel);
            app.RealinterestratePanel.Title = 'Real interest rate [%]';
            app.RealinterestrateSlider = uislider(app.RealinterestratePanel);
            app.RealinterestrateSlider.Limits = [0 6];
            app.RealinterestrateSlider.ValueChangedFcn = createCallbackFcn(app, @RealinterestrateSliderValueChanged, true);
            app.RealinterestrateSlider.MinorTicks = [0 0.25 0.5 0.75 1 1.25 1.5 1.75 2 2.25 2.5 2.75 3 3.25 3.5 3.75 4 4.25 4.5 4.75 5 5.25 5.5 5.75 6];
            app.CalculationtimeperiodaPanel = uipanel(app.FinancialparametersPanel);
            app.CalculationtimeperiodaPanel.Title = 'Calculation time period [a]';
            app.DropDown = uidropdown(app.CalculationtimeperiodaPanel);
            app.DropDown.Items = {'20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '30';
            app.EcologicalparametersPanel = uipanel(app.SettingsTab);
            app.EcologicalparametersPanel.Title = 'Ecological parameters';
            app.GHGefactorskgCO2eqkWhPanel = uipanel(app.EcologicalparametersPanel);
            app.GHGefactorskgCO2eqkWhPanel.Title = 'GHG e. factors [kg CO2-eq/kWh]';
            app.HeatingoilLabel_3 = uilabel(app.GHGefactorskgCO2eqkWhPanel);
            app.HeatingoilLabel_3.Text = 'Heating oil:';
            app.HeatingoilEditField_2 = uieditfield(app.GHGefactorskgCO2eqkWhPanel, 'numeric');
            app.HeatingoilEditField_2.Limits = [0 2];
            app.HeatingoilEditField_2.ValueChangedFcn = createCallbackFcn(app, @HeatingoilEditField_2ValueChanged, true);
            app.HeatingoilEditField_2.Value = 0.301;
            app.DistrictheatLabel_3 = uilabel(app.GHGefactorskgCO2eqkWhPanel);
            app.DistrictheatLabel_3.Text = 'District heat:';
            app.DistrictheatEditField_2 = uieditfield(app.GHGefactorskgCO2eqkWhPanel, 'numeric');
            app.DistrictheatEditField_2.Limits = [0 2];
            app.DistrictheatEditField_2.ValueChangedFcn = createCallbackFcn(app, @DistrictheatEditField_2ValueChanged, true);
            app.DistrictheatEditField_2.Value = 0.108;
            app.ElectricityLabel_3 = uilabel(app.GHGefactorskgCO2eqkWhPanel);
            app.ElectricityLabel_3.Text = 'Electricity:';
            app.ElectricityEditField_2 = uieditfield(app.GHGefactorskgCO2eqkWhPanel, 'numeric');
            app.ElectricityEditField_2.Limits = [0 2];
            app.ElectricityEditField_2.ValueChangedFcn = createCallbackFcn(app, @ElectricityEditField_2ValueChanged, true);
            app.ElectricityEditField_2.Value = 0.102;
            app.PelletsLabel_3 = uilabel(app.GHGefactorskgCO2eqkWhPanel);
            app.PelletsLabel_3.Text = 'Pellets:';
            app.PelletsEditField_2 = uieditfield(app.GHGefactorskgCO2eqkWhPanel, 'numeric');
            app.PelletsEditField_2.Limits = [0 2];
            app.PelletsEditField_2.ValueChangedFcn = createCallbackFcn(app, @PelletsEditField_2ValueChanged, true);
            app.PelletsEditField_2.Value = 0.027;
            app.PENRfactorskWhkWhPanel = uipanel(app.EcologicalparametersPanel);
            app.PENRfactorskWhkWhPanel.Title = 'PENR factors [kWh/kWh]';
            app.HeatingoilLabel_4 = uilabel(app.PENRfactorskWhkWhPanel);
            app.HeatingoilLabel_4.Text = 'Heating oil:';
            app.HeatingoilEditField_3 = uieditfield(app.PENRfactorskWhkWhPanel, 'numeric');
            app.HeatingoilEditField_3.Limits = [0 5];
            app.HeatingoilEditField_3.ValueChangedFcn = createCallbackFcn(app, @HeatingoilEditField_3ValueChanged, true);
            app.HeatingoilEditField_3.Value = 1.23;
            app.DistrictheatLabel_4 = uilabel(app.PENRfactorskWhkWhPanel);
            app.DistrictheatLabel_4.Text = 'District heat:';
            app.DistrictheatEditField_3 = uieditfield(app.PENRfactorskWhkWhPanel, 'numeric');
            app.DistrictheatEditField_3.Limits = [0 5];
            app.DistrictheatEditField_3.ValueChangedFcn = createCallbackFcn(app, @DistrictheatEditField_3ValueChanged, true);
            app.DistrictheatEditField_3.Value = 0.549;
            app.ElectricityLabel_4 = uilabel(app.PENRfactorskWhkWhPanel);
            app.ElectricityLabel_4.Text = 'Electricity:';
            app.ElectricityEditField_3 = uieditfield(app.PENRfactorskWhkWhPanel, 'numeric');
            app.ElectricityEditField_3.Limits = [0 5];
            app.ElectricityEditField_3.ValueChangedFcn = createCallbackFcn(app, @ElectricityEditField_3ValueChanged, true);
            app.ElectricityEditField_3.Value = 2.52;
            app.PelletsLabel_4 = uilabel(app.PENRfactorskWhkWhPanel);
            app.PelletsLabel_4.Text = 'Pellets:';
            app.PelletsEditField_3 = uieditfield(app.PENRfactorskWhkWhPanel, 'numeric');
            app.PelletsEditField_3.Limits = [0 5];
            app.PelletsEditField_3.ValueChangedFcn = createCallbackFcn(app, @PelletsEditField_3ValueChanged, true);
            app.PelletsEditField_3.Value = 0.157;
            app.CalculationButton_2 = uibutton(app.SettingsTab, 'push');
            app.CalculationButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculationButton_2Pushed, true);
            app.CalculationButton_2.Text = 'Calculation';
            app.CompleteLampLabel = uilabel(app.SettingsTab);
            app.CompleteLampLabel.Text = 'Complete';
            app.CompleteLamp = uilamp(app.SettingsTab);
            app.UIAxes6 = uiaxes(app.SettingsTab);
            title(app.UIAxes6, 'Development of energy prices')
            xlabel(app.UIAxes6, 'Years')
            ylabel(app.UIAxes6, 'Price [CHF/kWh]')
            app.CostoverviewTab = uitab(app.TabGroup);
            app.CostoverviewTab.Title = 'Cost overview';
            app.TabGroup3 = uitabgroup(app.CostoverviewTab);
            app.InvestmentcostsTab = uitab(app.TabGroup3);
            app.InvestmentcostsTab.Title = 'Investment costs';
            app.investment_costs = uitable(app.InvestmentcostsTab);
            app.investment_costs.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.investment_costs.RowName = {};
            app.MatrixwithinvestmentcostsofretrofitstrategiesCHFLabel = uilabel(app.InvestmentcostsTab);
            app.MatrixwithinvestmentcostsofretrofitstrategiesCHFLabel.Text = 'Matrix with investment costs of retrofit strategies [CHF]';
            app.AnnotationLabel_7 = uilabel(app.InvestmentcostsTab);
            app.AnnotationLabel_7.Text = 'Annotation';
            app.TextArea_8 = uitextarea(app.InvestmentcostsTab);
            app.TextArea_8.Value = {'NaN = heat pump is not feasible because max. supply temperature cannot be achieved'};
            app.RunningcostsTab = uitab(app.TabGroup3);
            app.RunningcostsTab.Title = 'Running costs';
            app.TextArea_7 = uitextarea(app.RunningcostsTab);
            app.TextArea_7.Value = {'NaN = heat pump is not feasible because max. supply temperature cannot be achieved'};
            app.AnnotationsLabel = uilabel(app.RunningcostsTab);
            app.AnnotationsLabel.Text = 'Annotations';
            app.running_costs = uitable(app.RunningcostsTab);
            app.running_costs.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.running_costs.RowName = {};
            app.MatrixwithrunningcostsofretrofitstrategiesCHFaLabel = uilabel(app.RunningcostsTab);
            app.MatrixwithrunningcostsofretrofitstrategiesCHFaLabel.Text = 'Matrix with running costs of retrofit strategies [CHF/a]';
            app.TextArea_9 = uitextarea(app.RunningcostsTab);
            app.TextArea_9.Value = {'Running costs = energy costs + O&M costs'};
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.MinglobalcostcostoptimalLabel = uilabel(app.ResultsTab);
            app.MinglobalcostcostoptimalLabel.Text = 'Min. global cost (cost-optimal)';
            app.MinPENRconsumptionLabel = uilabel(app.ResultsTab);
            app.MinPENRconsumptionLabel.Text = 'Min. PENR consumption';
            app.MinMACGHGemissionsLabel = uilabel(app.ResultsTab);
            app.MinMACGHGemissionsLabel.Text = 'Min. MAC (GHG emissions)';
            app.MinMACPENRconsumptionLabel = uilabel(app.ResultsTab);
            app.MinMACPENRconsumptionLabel.Text = 'Min. MAC PENR consumption';
            app.cost_optimal = uitable(app.ResultsTab);
            app.cost_optimal.ColumnName = {'Heating system'; 'Roof'; 'Walls'; 'Cellar ceiling'; 'Windows'};
            app.cost_optimal.RowName = {};
            app.minimum_ghg = uitable(app.ResultsTab);
            app.minimum_ghg.ColumnName = {'Heating system'; 'Roof'; 'Walls'; 'Cellar ceiling'; 'Windows'};
            app.minimum_ghg.RowName = {};
            app.minimum_penr = uitable(app.ResultsTab);
            app.minimum_penr.ColumnName = {'Heating system'; 'Roof'; 'Walls'; 'Cellar ceiling'; 'Windows'};
            app.minimum_penr.RowName = {};
            app.cost_efficient_ghg = uitable(app.ResultsTab);
            app.cost_efficient_ghg.ColumnName = {'Heating system'; 'Roof'; 'Walls'; 'Cellar ceiling'; 'Windows'};
            app.cost_efficient_ghg.RowName = {};
            app.cost_efficient_penr = uitable(app.ResultsTab);
            app.cost_efficient_penr.ColumnName = {'Heating system'; 'Roof'; 'Walls'; 'Cellar ceiling'; 'Windows'};
            app.cost_efficient_penr.RowName = {};
            app.GlobalcostCHFEditField = uieditfield(app.ResultsTab, 'numeric');
            app.GlobalcostCHFEditField_2 = uieditfield(app.ResultsTab, 'numeric');
            app.GlobalcostCHFEditField_3 = uieditfield(app.ResultsTab, 'numeric');
            app.GlobalcostCHFEditField_4 = uieditfield(app.ResultsTab, 'numeric');
            app.GlobalcostCHFEditField_5 = uieditfield(app.ResultsTab, 'numeric');
            app.GHGemissionskgCO2eqaGauge = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge.Limits = [0 10500];
            app.GHGemissionskgCO2eqaGauge.MajorTicks = [0 5250 10500];
            app.GHGemissionskgCO2eqaGauge.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge.MinorTicks = [0 1750 3500 5250 7000 8750 10500];
            app.GHGemissionskgCO2eqaGauge_2 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_2.Limits = [0 10500];
            app.GHGemissionskgCO2eqaGauge_2.MajorTicks = [0 5250 10500];
            app.GHGemissionskgCO2eqaGauge_2.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_2.MinorTicks = [0 1750 3500 5250 7000 8750 10500];
            app.GHGemissionskgCO2eqaGauge_3 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_3.Limits = [0 10500];
            app.GHGemissionskgCO2eqaGauge_3.MajorTicks = [0 5250 10500];
            app.GHGemissionskgCO2eqaGauge_3.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_3.MinorTicks = [0 1750 3500 5250 7000 8750 10500];
            app.GHGemissionskgCO2eqaGauge_4 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_4.Limits = [0 10500];
            app.GHGemissionskgCO2eqaGauge_4.MajorTicks = [0 5250 10500];
            app.GHGemissionskgCO2eqaGauge_4.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_4.MinorTicks = [0 1750 3500 5250 7000 8750 10500];
            app.GHGemissionskgCO2eqaGauge_5 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_5.Limits = [0 10500];
            app.GHGemissionskgCO2eqaGauge_5.MajorTicks = [0 5250 10500];
            app.GHGemissionskgCO2eqaGauge_5.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_5.MinorTicks = [0 1750 3500 5250 7000 8750 10500];
            app.GHGemissionskgCO2eqaLabel = uilabel(app.ResultsTab);
            app.GHGemissionskgCO2eqaLabel.Text = {'GHG emissions'; '[kg CO2-eq/a]'};
            app.PENRconsumptionkWhaLabel = uilabel(app.ResultsTab);
            app.PENRconsumptionkWhaLabel.Text = {'PENR consumption'; '[kWh/a]'};
            app.GlobalcostsCHFLabel = uilabel(app.ResultsTab);
            app.GlobalcostsCHFLabel.Text = {'Global costs'; '[CHF]'};
            app.GHGemissionskgCO2eqaGauge_6 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_6.Limits = [0 43000];
            app.GHGemissionskgCO2eqaGauge_6.MajorTicks = [0 21500 43000];
            app.GHGemissionskgCO2eqaGauge_6.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_6.MinorTicks = [0 7166.7 14333.3 21500 28666.7 35833.3 43000];
            app.GHGemissionskgCO2eqaGauge_7 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_7.Limits = [0 43000];
            app.GHGemissionskgCO2eqaGauge_7.MajorTicks = [0 21500 43000];
            app.GHGemissionskgCO2eqaGauge_7.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_7.MinorTicks = [0 7166.7 14333.3 21500 28666.7 35833.3 43000];
            app.GHGemissionskgCO2eqaGauge_8 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_8.Limits = [0 43000];
            app.GHGemissionskgCO2eqaGauge_8.MajorTicks = [0 21500 43000];
            app.GHGemissionskgCO2eqaGauge_8.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_8.MinorTicks = [0 7166.7 14333.3 21500 28666.7 35833.3 43000];
            app.GHGemissionskgCO2eqaGauge_9 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_9.Limits = [0 43000];
            app.GHGemissionskgCO2eqaGauge_9.MajorTicks = [0 21500 43000];
            app.GHGemissionskgCO2eqaGauge_9.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_9.MinorTicks = [0 7166.7 14333.3 21500 28666.7 35833.3 43000];
            app.GHGemissionskgCO2eqaGauge_10 = uigauge(app.ResultsTab, 'ninetydegree');
            app.GHGemissionskgCO2eqaGauge_10.Limits = [0 43000];
            app.GHGemissionskgCO2eqaGauge_10.MajorTicks = [0 21500 43000];
            app.GHGemissionskgCO2eqaGauge_10.MajorTickLabels = {'low', 'medium', 'high'};
            app.GHGemissionskgCO2eqaGauge_10.MinorTicks = [0 7166.7 14333.3 21500 28666.7 35833.3 43000];
            app.MinGHGemissionsLabel = uilabel(app.ResultsTab);
            app.MinGHGemissionsLabel.Text = 'Min. GHG emissions';
            app.OptimalretrofitstrategiesLabel = uilabel(app.ResultsTab);
            app.OptimalretrofitstrategiesLabel.Text = 'Optimal retrofit strategies:';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.TabGroup4 = uitabgroup(app.PlotsTab);
            app.GlobalcostsvsGHGemissionsTab = uitab(app.TabGroup4);
            app.GlobalcostsvsGHGemissionsTab.Title = 'Global costs vs. GHG emissions';
            app.UIAxes4 = uiaxes(app.GlobalcostsvsGHGemissionsTab);
            xlabel(app.UIAxes4, 'GHG emissions [kg CO2-eq/a]')
            ylabel(app.UIAxes4, 'Global costs [CHF]')
            app.GlobalcostsvsPENRconsumptionTab = uitab(app.TabGroup4);
            app.GlobalcostsvsPENRconsumptionTab.Title = 'Global costs vs. PENR consumption';
            app.UIAxes4_2 = uiaxes(app.GlobalcostsvsPENRconsumptionTab);
            xlabel(app.UIAxes4_2, 'PENR consumption [kWh/a]')
            ylabel(app.UIAxes4_2, 'Global costs [CHF]')
            app.DetailedresultsTab = uitab(app.TabGroup);
            app.DetailedresultsTab.Title = 'Detailed results';
            app.TabGroup2 = uitabgroup(app.DetailedresultsTab);
            app.CosteffectivenessTab = uitab(app.TabGroup2);
            app.CosteffectivenessTab.Title = 'Cost-effectiveness';
            app.global_costs = uitable(app.CosteffectivenessTab);
            app.global_costs.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.global_costs.RowName = {};
            app.MatrixwithglobalcostsofretrofitstrategiesCHFLabel = uilabel(app.CosteffectivenessTab);
            app.MatrixwithglobalcostsofretrofitstrategiesCHFLabel.Text = 'Matrix with global costs of retrofit strategies [CHF]';
            app.UIAxes2 = uiaxes(app.CosteffectivenessTab);
            ylabel(app.UIAxes2, 'Global cost [CHF]')
            app.CostoptimalretrofitstrategyperheatingsystemLabel = uilabel(app.CosteffectivenessTab);
            app.CostoptimalretrofitstrategyperheatingsystemLabel.Text = 'Cost-optimal retrofit strategy per heating system';
            app.UITable = uitable(app.CosteffectivenessTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.AnnotationLabel = uilabel(app.CosteffectivenessTab);
            app.AnnotationLabel.Text = 'Annotation';
            app.TextArea = uitextarea(app.CosteffectivenessTab);
            app.TextArea.Value = {'NaN = heat pump is not feasible because max. supply temperature cannot be achieved'};
            app.GHGemissionsTab = uitab(app.TabGroup2);
            app.GHGemissionsTab.Title = 'GHG emissions';
            app.ghg_emissions = uitable(app.GHGemissionsTab);
            app.ghg_emissions.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ghg_emissions.RowName = {};
            app.MatrixwithGHGemissionsofretrofitstrategieskgCO2eqaLabel = uilabel(app.GHGemissionsTab);
            app.MatrixwithGHGemissionsofretrofitstrategieskgCO2eqaLabel.Text = 'Matrix with GHG emissions of retrofit strategies [kg CO2-eq/a]';
            app.UIAxes2_2 = uiaxes(app.GHGemissionsTab);
            ylabel(app.UIAxes2_2, 'GHG emissions [kg CO2-eq/a]')
            app.RetrofitstrategywithminimumGHGemissionsperheatingsystemLabel = uilabel(app.GHGemissionsTab);
            app.RetrofitstrategywithminimumGHGemissionsperheatingsystemLabel.Text = 'Retrofit strategy with minimum GHG emissions per heating system';
            app.UITable_2 = uitable(app.GHGemissionsTab);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.AnnotationLabel_2 = uilabel(app.GHGemissionsTab);
            app.AnnotationLabel_2.Text = 'Annotation';
            app.TextArea_2 = uitextarea(app.GHGemissionsTab);
            app.TextArea_2.Value = {'NaN = heat pump is not feasible because max. supply temperature cannot be achieved'};
            app.PENRconsumptionTab = uitab(app.TabGroup2);
            app.PENRconsumptionTab.Title = 'PENR consumption';
            app.penr_consumption = uitable(app.PENRconsumptionTab);
            app.penr_consumption.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.penr_consumption.RowName = {};
            app.MatrixwithPENRconsumptionofretrofitstrategieskWhaLabel = uilabel(app.PENRconsumptionTab);
            app.MatrixwithPENRconsumptionofretrofitstrategieskWhaLabel.Text = 'Matrix with PENR consumption of retrofit strategies [kWh/a]';
            app.RetrofitstrategywithminimumPENRconsumptionperheatingsystemLabel = uilabel(app.PENRconsumptionTab);
            app.RetrofitstrategywithminimumPENRconsumptionperheatingsystemLabel.Text = 'Retrofit strategy with minimum PENR consumption per heating system';
            app.UIAxes2_3 = uiaxes(app.PENRconsumptionTab);
            ylabel(app.UIAxes2_3, 'PENR [kWh/a]')
            app.UITable_3 = uitable(app.PENRconsumptionTab);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.AnnotationLabel_3 = uilabel(app.PENRconsumptionTab);
            app.AnnotationLabel_3.Text = 'Annotation';
            app.TextArea_3 = uitextarea(app.PENRconsumptionTab);
            app.TextArea_3.Value = {'NaN = heat pump is not feasible because max. supply temperature cannot be achieved'};
            app.MarginalabatementcostsGHGemissionsTab = uitab(app.TabGroup2);
            app.MarginalabatementcostsGHGemissionsTab.Title = 'Marginal abatement costs (GHG emissions)';
            app.mac_ghg = uitable(app.MarginalabatementcostsGHGemissionsTab);
            app.mac_ghg.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.mac_ghg.RowName = {};
            app.MatrixwithmarginalcostsofabatingGHGemissionsCHFkgCO2eqaLabel = uilabel(app.MarginalabatementcostsGHGemissionsTab);
            app.MatrixwithmarginalcostsofabatingGHGemissionsCHFkgCO2eqaLabel.Text = 'Matrix with marginal costs of abating GHG emissions [CHF/(kg CO2-eq/a)]';
            app.AnnotationLabel_4 = uilabel(app.MarginalabatementcostsGHGemissionsTab);
            app.AnnotationLabel_4.Text = 'Annotation';
            app.TextArea_4 = uitextarea(app.MarginalabatementcostsGHGemissionsTab);
            app.TextArea_4.Value = {'NaN = heat pump is not feasible because max. supply temperature cannot be achieved'};
            app.RetrofitstrategywithminimumMACperheatingsystemLabel = uilabel(app.MarginalabatementcostsGHGemissionsTab);
            app.RetrofitstrategywithminimumMACperheatingsystemLabel.Text = 'Retrofit strategy with minimum MAC per heating system';
            app.UITable_4 = uitable(app.MarginalabatementcostsGHGemissionsTab);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_4.RowName = {};
            app.UIAxes2_4 = uiaxes(app.MarginalabatementcostsGHGemissionsTab);
            ylabel(app.UIAxes2_4, 'MAC [CHF/(kg CO2-eq/a)]')
            app.MarginalabatementcostsPENRconsumptionTab = uitab(app.TabGroup2);
            app.MarginalabatementcostsPENRconsumptionTab.Title = 'Marginal abatement costs (PENR consumption)';
            app.mac_penr = uitable(app.MarginalabatementcostsPENRconsumptionTab);
            app.mac_penr.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.mac_penr.RowName = {};
            app.MatrixwithmarginalcostsofabatingPENRconsumptionCHFkWhaLabel = uilabel(app.MarginalabatementcostsPENRconsumptionTab);
            app.MatrixwithmarginalcostsofabatingPENRconsumptionCHFkWhaLabel.Text = 'Matrix with marginal costs of abating PENR consumption [CHF/(kWh/a)]';
            app.AnnotationLabel_5 = uilabel(app.MarginalabatementcostsPENRconsumptionTab);
            app.AnnotationLabel_5.Text = 'Annotation';
            app.TextArea_5 = uitextarea(app.MarginalabatementcostsPENRconsumptionTab);
            app.TextArea_5.Value = {'NaN = heat pump is not feasible because max. supply temperature cannot be achieved'};
            app.RetrofitstrategywithminimumMACperheatingsystemLabel_2 = uilabel(app.MarginalabatementcostsPENRconsumptionTab);
            app.RetrofitstrategywithminimumMACperheatingsystemLabel_2.Text = 'Retrofit strategy with minimum MAC per heating system';
            app.UITable_5 = uitable(app.MarginalabatementcostsPENRconsumptionTab);
            app.UITable_5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_5.RowName = {};
            app.UIAxes2_5 = uiaxes(app.MarginalabatementcostsPENRconsumptionTab);
            ylabel(app.UIAxes2_5, 'MAC [CHF/(kWh/a)]')
            app.TimelineTab = uitab(app.TabGroup);
            app.TimelineTab.Title = 'Timeline';
            app.UIAxes = uiaxes(app.TimelineTab);
            xlabel(app.UIAxes, 'Years')
            ylabel(app.UIAxes, 'Cumulative costs [CHF]')
            app.ImpressumTab = uitab(app.TabGroup);
            app.ImpressumTab.Title = 'Impressum';
            app.ProgrammerTextAreaLabel = uilabel(app.ImpressumTab);
            app.ProgrammerTextAreaLabel.Text = 'Programmer';
            app.ProgrammerTextArea = uitextarea(app.ImpressumTab);
            app.ProgrammerTextArea.Value = {'Diego Sigrist'};
            app.EmailTextAreaLabel = uilabel(app.ImpressumTab);
            app.EmailTextAreaLabel.Text = 'Email';
            app.EmailTextArea = uitextarea(app.ImpressumTab);
            app.EmailTextArea.Value = {'sigrisdi@student.ethz.ch'};
            app.TitleTextAreaLabel = uilabel(app.ImpressumTab);
            app.TitleTextAreaLabel.Text = 'Title';
            app.TitleTextArea = uitextarea(app.ImpressumTab);
            app.TitleTextArea.Value = {'Retrofit App 2'};
            app.VersionTextAreaLabel = uilabel(app.ImpressumTab);
            app.VersionTextAreaLabel.Text = 'Version';
            app.VersionTextArea = uitextarea(app.ImpressumTab);
            app.VersionTextArea.Value = {'1.0'};
            app.DateTextAreaLabel = uilabel(app.ImpressumTab);
            app.DateTextAreaLabel.Text = 'Date';
            app.DateTextArea = uitextarea(app.ImpressumTab);
            app.DateTextArea.Value = {'22.08.2019'};
            app.DescriptionTextAreaLabel = uilabel(app.ImpressumTab);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.ImpressumTab);
            app.DescriptionTextArea.Value = {'This programme was developed as part of the Master''s thesis "Automation of Cost-Optimal Retrofit Analysis for Residential Buildings Using a Light-Grey-Box Model", in which detailed documentation can also be found.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Retrofit_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end